classdef DataImportWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        ImportedDataT  matlab.ui.control.Table
        FileSelectL    matlab.ui.control.Label
        FileImportB    matlab.ui.control.Button
        FileSelectB    matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app Object
        filePath = "";
        importedData = {};
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, path, importedData)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.filePath = path;
            app.ImportedDataT.Data = importedData;
        end

        % Button pushed function: FileImportB
        function FileImportBButtonPushed(app, event)
            % Call main app's public function
            try
                app.importedData = readtable(app.filePath);
                app.importedData
                app.ImportedDataT.Data = app.importedData;
            catch err
                throw(err);
            end

            updateImportedData(app.CallingApp,app.filePath,app.importedData);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app

            % Delete the dialog box 
            delete(app);
        end

        % Button pushed function: FileSelectB
        function FileSelectBButtonPushed(app, event)
            [name,path] = uigetfile('*.xlsx');
            app.filePath = string(path)+string(name);
            app.FileSelectL.Text = "File: "+string(path)+string(name);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FileSelectB = uibutton(app.GridLayout, 'push');
            app.FileSelectB.ButtonPushedFcn = createCallbackFcn(app, @FileSelectBButtonPushed, true);
            app.FileSelectB.Text = 'Select File';
            app.FileImportB = uibutton(app.GridLayout, 'push');
            app.FileImportB.ButtonPushedFcn = createCallbackFcn(app, @FileImportBButtonPushed, true);
            app.FileImportB.Text = 'Import';
            app.FileSelectL = uilabel(app.GridLayout);
            app.FileSelectL.Text = 'File: ';
            app.ImportedDataT = uitable(app.GridLayout);
            app.ImportedDataT.ColumnName = '';
            app.ImportedDataT.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataImportWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end