classdef MAGMA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MAGMAUIFigure                   matlab.ui.Figure
        GridLayout46                    matlab.ui.container.GridLayout
        StopCalculationButton           matlab.ui.control.Button
        ClearConsoleButton              matlab.ui.control.Button
        ConsoleB                        matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        HomeTabGL                       matlab.ui.container.GridLayout
        ModelBioChemT                   matlab.ui.control.Table
        ModelSavedL                     matlab.ui.control.Label
        ModelSaveB                      matlab.ui.control.Button
        ModelSearchByDD                 matlab.ui.control.DropDown
        SearchByLabel                   matlab.ui.control.Label
        ModelDBLoadB                    matlab.ui.control.Button
        ModelSearchB                    matlab.ui.control.Button
        ModelSearchVF                   matlab.ui.control.EditField
        ModelSearchTree                 matlab.ui.container.Tree
        ModelFileLoadB                  matlab.ui.control.Button
        CreateNewModelB                 matlab.ui.control.Button
        ModelSave2DBB                   matlab.ui.control.Button
        ModelSave2FileB                 matlab.ui.control.Button
        ModelNameTA                     matlab.ui.control.TextArea
        ModelNameLabel                  matlab.ui.control.Label
        ModelDescHTA                    matlab.ui.control.TextArea
        ModelDescriptionLabel           matlab.ui.control.Label
        RefHTA                          matlab.ui.control.TextArea
        ReferencesLabel                 matlab.ui.control.Label
        SpecTab                         matlab.ui.container.Tab
        SpecEnvironGrid                 matlab.ui.container.GridLayout
        DensityEditField                matlab.ui.control.NumericEditField
        DensityEditFieldLabel           matlab.ui.control.Label
        Button_13                       matlab.ui.control.Button
        DensityDropDown                 matlab.ui.control.DropDown
        Button_9                        matlab.ui.control.Button
        MWVF                            matlab.ui.control.NumericEditField
        MolecularWeightEditFieldLabel   matlab.ui.control.Label
        ChemTypeDD                      matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        SorpEqML                        matlab.ui.control.Label
        CompSolutesL                    matlab.ui.control.Label
        HConstL                         matlab.ui.control.Label
        SpecInitConcF                   matlab.ui.control.NumericEditField
        ChemInitConcF                   matlab.ui.control.NumericEditField
        Button_8                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Param3L                         matlab.ui.control.Label
        Param2L                         matlab.ui.control.Label
        Param1L                         matlab.ui.control.Label
        HConst2HB                       matlab.ui.control.Button
        HConstHB                        matlab.ui.control.Button
        HConst2L                        matlab.ui.control.Label
        HConst2VF                       matlab.ui.control.NumericEditField
        HConst2UDD                      matlab.ui.control.DropDown
        SorpParam3VF                    matlab.ui.control.NumericEditField
        SorpParam2VF                    matlab.ui.control.NumericEditField
        SorpParam1VF                    matlab.ui.control.NumericEditField
        SorptionModelDD                 matlab.ui.control.DropDown
        SorptionModelRB                 matlab.ui.control.Button
        SorptionModelUB                 matlab.ui.control.Button
        SorptionT                       matlab.ui.control.Table
        CompSoluteDD                    matlab.ui.control.DropDown
        HConstUDD                       matlab.ui.control.DropDown
        HConstVF                        matlab.ui.control.NumericEditField
        VolCB                           matlab.ui.control.CheckBox
        ChemRB                          matlab.ui.control.Button
        ChemT                           matlab.ui.control.Table
        ChemInitConcUDD                 matlab.ui.control.DropDown
        ChemInitConcL                   matlab.ui.control.Label
        ChemNameDD                      matlab.ui.control.DropDown
        ChemicalNameLabel               matlab.ui.control.Label
        ChemAB                          matlab.ui.control.Button
        SpecInitConcUnitDD              matlab.ui.control.DropDown
        InitialConcentrationEditField_2Label  matlab.ui.control.Label
        SpecRB                          matlab.ui.control.Button
        SpecT                           matlab.ui.control.Table
        SpecAB                          matlab.ui.control.Button
        SpecNameDD                      matlab.ui.control.DropDown
        SpeciesNameLabel                matlab.ui.control.Label
        EnvironmentTab                  matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        EnvironmentDesignPanel          matlab.ui.container.Panel
        GridLayout36                    matlab.ui.container.GridLayout
        EnvReactProp5L                  matlab.ui.control.Label
        LgtMdlCB3                       matlab.ui.control.CheckBox
        LgtMdlCB4                       matlab.ui.control.CheckBox
        LgtMdlCB2                       matlab.ui.control.CheckBox
        LgtMdlCB1                       matlab.ui.control.CheckBox
        Button_3                        matlab.ui.control.Button
        IncidentLightFuncL              matlab.ui.control.Label
        IncidentLightFuncVF             matlab.ui.control.EditField
        EditField_9                     matlab.ui.control.NumericEditField
        DropDown_7                      matlab.ui.control.DropDown
        EnvGeomUB                       matlab.ui.control.Button
        EnvReactProp1L                  matlab.ui.control.Label
        HelpBut9                        matlab.ui.control.Button
        HelpBut8                        matlab.ui.control.Button
        HelpBut7                        matlab.ui.control.Button
        HelpBut6                        matlab.ui.control.Button
        HelpBut5                        matlab.ui.control.Button
        HelpBut4                        matlab.ui.control.Button
        HelpBut3                        matlab.ui.control.Button
        HelpBut2                        matlab.ui.control.Button
        ShadingEffectLabel              matlab.ui.control.Label
        LgtAttnMdlL                     matlab.ui.control.Label
        EnvReactProp2L                  matlab.ui.control.Label
        EnvReactProp3L                  matlab.ui.control.Label
        EnvReactProp4L                  matlab.ui.control.Label
        HelpBut1                        matlab.ui.control.Button
        ReactParamUnit4DD               matlab.ui.control.DropDown
        ReactParam4VF                   matlab.ui.control.NumericEditField
        ReactParam3VF                   matlab.ui.control.NumericEditField
        ReactParamUnit3DD               matlab.ui.control.DropDown
        ReactParamUnit2DD               matlab.ui.control.DropDown
        ReactParam2VF                   matlab.ui.control.NumericEditField
        LgtAttnMdlDD                    matlab.ui.control.DropDown
        LightAttenuationModelLabel      matlab.ui.control.Label
        ReactParamUnit1DD               matlab.ui.control.DropDown
        ReactParam1VF                   matlab.ui.control.NumericEditField
        ReactorTypeDD                   matlab.ui.control.DropDown
        ReactorTypeDropDownLabel        matlab.ui.control.Label
        ReactorImg                      matlab.ui.control.Image
        EnvironParamFuncL               matlab.ui.control.Label
        GenParamDD                      matlab.ui.control.DropDown
        genparamddLabel                 matlab.ui.control.Label
        GenParamAB                      matlab.ui.control.Button
        GenParamUnitDD                  matlab.ui.control.DropDown
        GenParamF                       matlab.ui.control.EditField
        EnvironT                        matlab.ui.control.Table
        EnvironParamFuncPlot            matlab.ui.control.UIAxes
        MassTransferTab                 matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        GasSpargingPhysicsSpecificationPanel  matlab.ui.container.Panel
        GridLayout40                    matlab.ui.container.GridLayout
        MTP                             matlab.ui.container.Panel
        GridLayout37                    matlab.ui.container.GridLayout
        MTModelUB                       matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
        MTFuncDD                        matlab.ui.control.DropDown
        MTPhaseBDD                      matlab.ui.control.DropDown
        MTPhaseBL                       matlab.ui.control.Label
        MTPhaseADD                      matlab.ui.control.DropDown
        GoverningEquationofLabel        matlab.ui.control.Label
        MTCompDD                        matlab.ui.control.DropDown
        ComponentDropDownLabel_2        matlab.ui.control.Label
        MTSaveL                         matlab.ui.control.Label
        MTGovFuncL                      matlab.ui.control.Label
        InputandOutputStreamsPanel      matlab.ui.container.Panel
        GridLayout34                    matlab.ui.container.GridLayout
        SolventLabel                    matlab.ui.control.Label
        MTSolvNDD                       matlab.ui.control.DropDown
        FlowrateL                       matlab.ui.control.Label
        MTStrmFlowUDD                   matlab.ui.control.DropDown
        MTStrmFlowVF                    matlab.ui.control.NumericEditField
        MTStrmCompBasisUDD              matlab.ui.control.DropDown
        MTStrmCompBasisDD               matlab.ui.control.DropDown
        MTStrmCompT                     matlab.ui.control.Table
        StrmAB                          matlab.ui.control.Button
        StrmTypeL                       matlab.ui.control.Label
        StrmRB                          matlab.ui.control.Button
        StrmNDD                         matlab.ui.control.DropDown
        StreamNameDropDownLabel         matlab.ui.control.Label
        StrmTypeDD                      matlab.ui.control.DropDown
        StrmT                           matlab.ui.control.Table
        ReactionsTab                    matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        HelperFuncP                     matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        HelperSaveL                     matlab.ui.control.Label
        HelperFuncL                     matlab.ui.control.Label
        HelperAB                        matlab.ui.control.Button
        Label_16                        matlab.ui.control.Label
        HelperNameF                     matlab.ui.control.EditField
        HelperSymF                      matlab.ui.control.EditField
        HelperValF                      matlab.ui.control.EditField
        Label_15                        matlab.ui.control.Label
        HelperRB                        matlab.ui.control.Button
        HelperUB                        matlab.ui.control.Button
        HelperDD                        matlab.ui.control.DropDown
        PiecewiseLimsP                  matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        UpLimOpDD                       matlab.ui.control.DropDown
        LowLimOpDD                      matlab.ui.control.DropDown
        UpLimVF                         matlab.ui.control.NumericEditField
        LowLimVF                        matlab.ui.control.NumericEditField
        ElseValDD                       matlab.ui.control.DropDown
        ElseDropDownLabel               matlab.ui.control.Label
        PiecewiseDD                     matlab.ui.control.DropDown
        ExpressionDropDownLabel         matlab.ui.control.Label
        PiecewiseRB                     matlab.ui.control.Button
        PiecewiseAB                     matlab.ui.control.Button
        GovFuncPiecewiseT               matlab.ui.control.Table
        PiecewiseVarDD                  matlab.ui.control.DropDown
        IfLabel_2                       matlab.ui.control.Label
        SubFuncPanel                    matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        OpenExpressionsReferenceButton  matlab.ui.control.Button
        GovFuncVF                       matlab.ui.control.EditField
        ModelUB                         matlab.ui.control.Button
        FuncNewAB                       matlab.ui.control.Button
        FuncTypeDD                      matlab.ui.control.DropDown
        SysVarT                         matlab.ui.control.Table
        GovFuncPanel                    matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        SaveL                           matlab.ui.control.Label
        GovFuncL                        matlab.ui.control.Label
        InteractCompDD                  matlab.ui.control.DropDown
        ComponentDropDownLabel_3        matlab.ui.control.Label
        SolverTab                       matlab.ui.container.Tab
        GridLayout41                    matlab.ui.container.GridLayout
        SolverEventsPanel               matlab.ui.container.Panel
        GridLayout43                    matlab.ui.container.GridLayout
        ActionVariableValueEditField    matlab.ui.control.EditField
        ActionVariableValueEditFieldLabel  matlab.ui.control.Label
        ActionVariableDropDown          matlab.ui.control.DropDown
        ActionVariableDropDownLabel     matlab.ui.control.Label
        RemoveButton_2                  matlab.ui.control.Button
        AddButton_2                     matlab.ui.control.Button
        EventDropDown                   matlab.ui.control.DropDown
        EventDropDownLabel              matlab.ui.control.Label
        StopSolverCheckBox              matlab.ui.control.CheckBox
        DirectionDropDown               matlab.ui.control.DropDown
        DirectionDropDownLabel          matlab.ui.control.Label
        ValueEditField                  matlab.ui.control.EditField
        ValueLabel                      matlab.ui.control.Label
        SolverSysVarT                   matlab.ui.control.Table
        SolverEvtSysVarDD               matlab.ui.control.DropDown
        SystemVariableLabel             matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        SolverSpecificSettingsPanel     matlab.ui.container.Panel
        GridLayout44                    matlab.ui.container.GridLayout
        MaximumRuntimeEditField         matlab.ui.control.NumericEditField
        MaximumRuntimeEditFieldLabel    matlab.ui.control.Label
        DropDown_8                      matlab.ui.control.DropDown
        MaxOrderDD                      matlab.ui.control.DropDown
        MaximumOrderDropDownLabel       matlab.ui.control.Label
        BDFCB                           matlab.ui.control.CheckBox
        VectorizationCB                 matlab.ui.control.CheckBox
        NormCtrlCB                      matlab.ui.control.CheckBox
        MaxStepSizeVF                   matlab.ui.control.NumericEditField
        MaximumStepSizeEditFieldLabel   matlab.ui.control.Label
        InitStepSizeVF                  matlab.ui.control.NumericEditField
        InitialStepSizeEditFieldLabel   matlab.ui.control.Label
        RelTolVF                        matlab.ui.control.NumericEditField
        RelativeToleranceLabel          matlab.ui.control.Label
        TolXVF                          matlab.ui.control.NumericEditField
        AbsoluteToleranceEditFieldLabel  matlab.ui.control.Label
        SolverL                         matlab.ui.control.Label
        SolverDD                        matlab.ui.control.DropDown
        SelectSolverDropDownLabel       matlab.ui.control.Label
        NonNegativeSystemVariablesPanel  matlab.ui.container.Panel
        GridLayout42                    matlab.ui.container.GridLayout
        NonNegRB                        matlab.ui.control.Button
        NonNegAB                        matlab.ui.control.Button
        NonNegSysVarLB                  matlab.ui.control.ListBox
        NonNegSysVarAvailLB             matlab.ui.control.ListBox
        ModelReviewTab                  matlab.ui.container.Tab
        GridLayout39                    matlab.ui.container.GridLayout
        ModelFunctionsPanel             matlab.ui.container.Panel
        SummaryL                        matlab.ui.control.Label
        ParametersTab                   matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        ParamSymDD                      matlab.ui.control.DropDown
        ParameterSymbolDropDownLabel    matlab.ui.control.Label
        ParamNF                         matlab.ui.control.EditField
        ParameterNameEditFieldLabel     matlab.ui.control.Label
        CompParamDD                     matlab.ui.control.DropDown
        SpeciesChemicalLabel            matlab.ui.control.Label
        ParamUB                         matlab.ui.control.Button
        ParamUnitDD                     matlab.ui.control.DropDown
        ParamValF                       matlab.ui.control.NumericEditField
        ParamNumDD                      matlab.ui.control.DropDown
        ParameterNumberLabel            matlab.ui.control.Label
        ParamT                          matlab.ui.control.Table
        RegressionTab                   matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        RegAnalysisB                    matlab.ui.control.Button
        RegSpecsB                       matlab.ui.control.Button
        VarDataUnmatchB                 matlab.ui.control.Button
        RegCompParamDD                  matlab.ui.control.DropDown
        ComponentDropDown_2Label        matlab.ui.control.Label
        RegVarDataT                     matlab.ui.control.Table
        RegDVAB                         matlab.ui.control.Button
        RegDVDD                         matlab.ui.control.DropDown
        DependentVariableLabel          matlab.ui.control.Label
        ImportDataB                     matlab.ui.control.Button
        SaveAllRegParamB                matlab.ui.control.Button
        SaveRegParamDD                  matlab.ui.control.DropDown
        ParameterDropDownLabel_2        matlab.ui.control.Label
        RunRegB                         matlab.ui.control.Button
        RegParamT                       matlab.ui.control.Table
        VarDataMatchB                   matlab.ui.control.Button
        ImportedDataDD                  matlab.ui.control.DropDown
        ImportedDataDropDownLabel       matlab.ui.control.Label
        RegSysVarDD                     matlab.ui.control.DropDown
        SystemVariableDropDownLabel     matlab.ui.control.Label
        RegParamRB                      matlab.ui.control.Button
        RegParamAB                      matlab.ui.control.Button
        ParametersDropDownLabel         matlab.ui.control.Label
        SaveRegParamB                   matlab.ui.control.Button
        RegParamDD                      matlab.ui.control.DropDown
        RegPlot                         matlab.ui.control.UIAxes
        PlotCustomizationTab            matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        ModelResultsExportPanel         matlab.ui.container.Panel
        GridLayout38                    matlab.ui.container.GridLayout
        DataExportDIrL                  matlab.ui.control.Label
        DataExportDirB                  matlab.ui.control.Button
        DataExportB                     matlab.ui.control.Button
        SubplotRB                       matlab.ui.control.Button
        SubplotDD                       matlab.ui.control.DropDown
        SubplotDropDownLabel            matlab.ui.control.Label
        SubplotAB                       matlab.ui.control.Button
        PlotPosDD                       matlab.ui.control.DropDown
        PlotPositionDropDownLabel       matlab.ui.control.Label
        SubplotCVF                      matlab.ui.control.NumericEditField
        ofSubplotColumnsEditFieldLabel  matlab.ui.control.Label
        SubplotGroupDD                  matlab.ui.control.DropDown
        PlotGroupDropDownLabel          matlab.ui.control.Label
        SubplotUB                       matlab.ui.control.Button
        SubplotRVF                      matlab.ui.control.NumericEditField
        ofSubplotRowsEditFieldLabel     matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        PlotTitleF                      matlab.ui.control.EditField
        TitleEditFieldLabel_2           matlab.ui.control.Label
        PlotTitleDD                     matlab.ui.control.DropDown
        PlotAB                          matlab.ui.control.Button
        PlotDownloadP                   matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        FileTypeDD                      matlab.ui.control.DropDown
        FileTypeDropDownLabel           matlab.ui.control.Label
        PlotDownloadDirB                matlab.ui.control.Button
        PlotDirL                        matlab.ui.control.Label
        PlotGenerationP                 matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        PlotDispTree                    matlab.ui.container.CheckBoxTree
        SelectPlotsL                    matlab.ui.control.Label
        ClosePrevPlotsCB                matlab.ui.control.CheckBox
        OptionsL                        matlab.ui.control.Label
        GenPlotB                        matlab.ui.control.Button
        PlotInitCondP                   matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        AddSpecVF3                      matlab.ui.control.NumericEditField
        AddSpecVF2                      matlab.ui.control.NumericEditField
        AddSpecVF1                      matlab.ui.control.NumericEditField
        evalUnitsVF                     matlab.ui.control.EditField
        ICEvalVarDD                     matlab.ui.control.DropDown
        ICEvalAxisDD                    matlab.ui.control.DropDown
        AddSpecL3                       matlab.ui.control.Label
        AddSpecL2                       matlab.ui.control.Label
        AddSpecL1                       matlab.ui.control.Label
        UnitsLabel                      matlab.ui.control.Label
        VariableDropDownLabel_2         matlab.ui.control.Label
        AxisDropDownLabel               matlab.ui.control.Label
        PlotICEvalSpecUB                matlab.ui.control.Button
        SysVarInitCondT                 matlab.ui.control.Table
        AxisSpecificationsP             matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        PlotSelectedCompLB              matlab.ui.control.ListBox
        PlotAvailCompLB                 matlab.ui.control.ListBox
        AxisDD                          matlab.ui.control.DropDown
        AxisDropDownLabel_2             matlab.ui.control.Label
        Button_12                       matlab.ui.control.Button
        Label_18                        matlab.ui.control.Label
        AxUpLimVF                       matlab.ui.control.NumericEditField
        AxLoLimVF                       matlab.ui.control.NumericEditField
        AxVarRB                         matlab.ui.control.Button
        AxVarAB                         matlab.ui.control.Button
        AxDispLimUB                     matlab.ui.control.Button
        Div2                            matlab.ui.control.Label
        UpperDisplayLimitDropDown_3Label  matlab.ui.control.Label
        LowerDisplayLimitDropDown_3Label  matlab.ui.control.Label
        NbAxesDD                        matlab.ui.control.DropDown
        NumberofAxesDropDownLabel       matlab.ui.control.Label
        AxDefaultRCB                    matlab.ui.control.CheckBox
        AxTitleF                        matlab.ui.control.EditField
        XAxisTitleLabel                 matlab.ui.control.Label
        PlotRB                          matlab.ui.control.Button
        PlotsDropDownLabel              matlab.ui.control.Label
        Console                         matlab.ui.container.Panel
        ConsoleL                        matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
        DataImportWindow
        RegSpecsWindow
        RegAnalysisWindow
        ODESolverSpecsWindow

        blk_name = "";
        system = []; % Placeholder for ODESys

        currPhaseA = '';
        currPhaseB = '';

        plotDispTreeNodes = {};
        % structure
        % tree:
        %   name: plot_title
        %   main: uitree_node
        %       display: uitree_node
        %       download: uitree_node

        envParamNames = {'Model Run Time (t)';'Incident Light (I_0(t))';'Temperature (T(t))';'Pressure (P(t))'; ...
            'Initial Culture Volume (V_0)';'Initial pH (pH_0)';'PBR Height';'PBR Width';'PBR Depth';'Maximum Volume (V_m)'; ...
            'Light Model';'Biological Light Attenuation';'Chemical Light Attenuation'};
    end
    
    methods (Access = private)
        function updateSumL(app)
            % updates MSumL text
            % update the text display for the function value in MSumL
            app.SummaryL.Text = "$";

            comps = app.system.getAllComps();
            helperNames = app.system.getEnvAndHelperFuncNames();
            for k=1:1:length(comps)+length(helperNames)
                if k <= length(comps)-5
                    names = comps{k}.getAllPhaseNames();
                    for l=1:1:length(names)
                        funcSym = uni2latex(app.system.getCompSymByName(names(l)));
                        govFunc = app.system.getGovFuncByCompName(names(l));
                        if k == length(comps) + length(helperNames), end_term = "$"; else, end_term = " \\"; end
                        app.SummaryL.Text = app.SummaryL.Text + "\frac{d"+funcSym+"}{dt}="+govFunc+end_term;
                    end
                elseif k > length(comps)-5 && k <= length(comps)
                    funcSym = uni2latex(app.system.getCompSymByName(comps{k}.getName()));
                    govFunc = app.system.getGovFuncByCompName(comps{k}.getName());
                    if k == length(comps) + length(helperNames), end_term = "$"; else, end_term = " \\"; end
                    app.SummaryL.Text = app.SummaryL.Text + "\frac{d"+funcSym+"}{dt}="+govFunc+end_term;
                else
                    funcSym = uni2latex(app.system.getEnvAndHelperSymByName(helperNames(k-length(comps))));
                    govFunc = app.system.getEnvAndHelperFuncValByName(helperNames(k-length(comps)));
                    if k == length(comps) + length(helperNames), end_term = "$"; else, end_term = " \\"; end
                    if strcmp(helperNames(k-length(comps)),'Light Intensity')
                        app.SummaryL.Text = app.SummaryL.Text + string(govFunc)+end_term;
                    else
                        app.SummaryL.Text = app.SummaryL.Text + string(funcSym)+"="+string(govFunc)+end_term;
                    end
                end
            end
            % resize/reposition SummaryL
            app.SummaryL.Position = [0;0;app.SummaryL.Position(3);465./10.*(length(comps)+length(helperNames))];
            disp('resize SummaryL')
        end

        function updatePlotUI(app,plot)
            app.PlotTitleF.Value = plot{1};
            if plot{3} == 2
                app.NbAxesDD.Value = app.NbAxesDD.Items{1};
            elseif plot{3} == 3
                app.NbAxesDD.Value = app.NbAxesDD.Items{2};
            end
            app.PlotDirL.Text = "Directory: "+plot{4};
            if (any(convertCharsToStrings(app.FileTypeDD.Items) == plot{5}))
                app.FileTypeDD.Value = plot{5};
            else
                app.FileTypeDD.Items = [app.FileTypeDD.Items,plot{5}];
                app.FileTypeDD.Value = plot{5};
            end
            axesNames = cell(1,length(plot{2}));
            for k=1:1:length(axesNames)
                axesNames{k} = (plot{2}{k}.title);
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};
            app.ICEvalAxisDDValueChanged();
            
            % remember to update uitree for plot generation
            nodeExists = false;
            for k=1:1:length(app.plotDispTreeNodes)
                if app.plotDispTreeNodes{k}.name == plot{1}
                    app.plotDispTreeNodes{k}.main.Text = plot{1};
                    app.plotDispTreeNodes{k}.display.NodeData = plot{6};
                    app.plotDispTreeNodes{k}.download.NodeData = plot{7};
                    nodeExists = true;
                end
            end
            if ~nodeExists
                main = uitreenode(app.PlotDispTree,"Text",plot{1});
                display = uitreenode(main,"Text","Display","NodeData",plot{6});
                download = uitreenode(main,"Text","Download","NodeData",plot{7});
                app.plotDispTreeNodes{end+1} = struct('name',plot{1}, ...
                    'main',main,'display',display,'download',download);
            end
        end

        function updateAxesUI(app,axes)
            axis_name = app.AxisDD.Value;
            if strcmp(axis_name,'X-Axis')
                ax_num = 1;
            elseif strcmp(axis_name,'Y-Axis')
                ax_num = 2;
            elseif strcmp(axis_name,'Z-Axis')
                ax_num = 3;
            end
            app.AxTitleF.Value = axes{ax_num}{2};
            app.PlotAvailCompLB.Items = [axes{ax_num}{4};axes{ax_num}{15}];
            app.PlotSelectedCompLB.Items = axes{ax_num}{3};
            app.AxLoLimVF.Value = axes{ax_num}{5};
            app.AxUpLimVF.Value = axes{ax_num}{6};
            app.AxDefaultRCB.Value = axes{ax_num}{7};

            if ax_num == 3
                app.AxVarUBButtonPushed(struct('Source',app.ZAxVarAB));
            end
        end

        function removeRegParamsByComp(app,compName)
            params = app.system.removeRegParamByComp(compName);
            app.RegParamT.ColumnName = {'Parameter','Component','Function','Value','Regressed Value'};
            app.RegParamT.Data = params;
        end

        function reinstantiateODESysUI(app)
            % Home Tab
            app.ModelNameTA.Value = app.system.save_model_name;
            app.ModelDescHTA.Value = app.system.save_model_desc;
            if length(app.system.save_model_bio_names) > length(app.system.save_model_chem_names)
                app.system.save_model_chem_names(length(app.system.save_model_chem_names)+1: ...
                    length(app.system.save_model_chem_names)+(length(app.system.save_model_bio_names)- ...
                    length(app.system.save_model_chem_names)-1)) = {'-'};
            elseif length(app.system.save_model_chem_names) > length(app.system.save_model_bio_names)
                app.system.save_model_bio_names(length(app.system.save_model_bio_names)+1: ...
                    length(app.system.save_model_bio_names)+(length(app.system.save_model_chem_names)- ...
                    length(app.system.save_model_bio_names)-1)) = {'-'};
            end
            app.ModelBioChemT.Data = [app.system.save_model_bio_names,app.system.save_model_chem_names];
            app.RefHTA.Value = app.system.save_model_ref_str;

            % Environment Tab
            app.IncidentLightFuncVF.Value = app.system.getEnvAndHelperFuncValByName('Incident Light');
            [reactorType,params] = app.system.getReactorSpecificParams();
            app.ReactorTypeDD.Value = reactorType;
            app.ReactorTypeDDValueChanged();
            % app.
            app.GenParamDD.Value = app.GenParamDD.Items(1);
            [paramVals,~] = app.system.getCurrentEnvironParams();
            app.GenParamF.Value = paramVals{1};

            % Species Tab


            % Mass Transfer Tab


            % Governing Functions Tab


            % Parameters Tab


            % Regression Tab


            % Summary Tab


            % Plotting Tab
            

        end
    end

    methods (Access = public)
        function updateImportedData(app,filePath,importedData,colNames)
            app.system.setImportedData(filePath,importedData,colNames);
            
            app.ImportedDataDD.Items = string(colNames);
        end

        function updateRegSpecs(app,regSpecs)
            app.system.setRegSpecs(regSpecs);
        end

        function runNRMSE(app,normType)
            app.RegAnalysisWindow.dispNRMSE(app.system.calcNRMSE(normType));
        end

        function runParCI(app,calcType,alpha)
            app.RegAnalysisWindow.dispParCI(app.system.calcParCI(calcType,alpha));
        end

        function runPredCI(app,tval,calcType,alpha,simBnds,predIntType)
            app.RegAnalysisWindow.dispPredCI(app.system.calcPredCI(tval,calcType,alpha,simBnds,predIntType));
        end
        
        function updateConsole(app,new_text,num_lines)
            app.ConsoleL.Text = sprintf(app.ConsoleL.Text,'\n',new_text);
            app.ConsoleL.Position = [app.ConsoleL.Position(1),app.ConsoleL.Position(2),app.ConsoleL.Position(3),520+14*num_lines];
        end
    end

    methods (Static)
        function newItems = addItemToDD(items,newVal)
            if any(strcmp(items,newVal))
                newItems = items;
            else
                newItems = [items,newVal];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateConsole = @(a,b) app.updateConsole(a,b);
            app.system = ODESys(updateConsole);

            [paramVals,paramUnits] = app.system.getCurrentEnvironParams();
            app.EnvironT.Data = [app.envParamNames,paramVals,paramUnits];
            app.GenParamF.Value = app.EnvironT.Data{1,2};
        end

        % Button pushed function: SpecAB
        function SpecABPushed(app, event)
            % get species name input
            specName = app.SpecNameDD.Value;
            if strcmp(specName,""), return; end
            if ~any(strcmp(app.SpecNameDD.Items,specName))
                app.SpecNameDD.Items{end+1} = specName;
            end
            % get species initial concentration input
            specInitConc = app.SpecInitConcF.Value;
            % get species init conc unit input
            specInitConcUnit = app.SpecInitConcUnitDD.Value;

            if strcmp(specInitConcUnit,"g/L")
                specInitDispConc = specInitConc;
            elseif strcmp(specInitConcUnit,"mg/L")
                specInitDispConc = specInitConc;
                specInitConc = specInitConc./1000;
            else
                specInitDispConc = specInitConc;
            end

            % extracting data from table (can optimize)
            TNames = cell([height(app.SpecT.Data),1]);
            for k=1:1:length(TNames)
                TNames{k} = app.SpecT.Data{k,1};
            end

            % adds new Species to display table
            if any(strcmp(TNames,specName))
                updIdx = strcmp(TNames, specName);
                % updated Species table data
                app.SpecT.Data(updIdx,:) = {specName, app.SpecT.Data{updIdx,2}, specInitDispConc, specInitConcUnit};
                % update Species in ODESys
                app.system.updateSpecies(specName,specInitConc,specInitConcUnit);
            else
                % new Species table data
                app.SpecT.Data = [app.SpecT.Data; {specName, ['X_',char(string(length(TNames)+1))], specInitDispConc, specInitConcUnit}];
                % add new Species to ODESys
                app.system.addSpecies(length(TNames)+1,specName,specInitConc,specInitConcUnit);
            end

            % setting SpecNameDD.Items
            app.SpecNameDD.Items = app.SpecT.Data(:,1);

            % InteractCompDD.Items should only be biological species and
            % the liquid phase chemical species
            varNames1 = app.system.getInteractCompDDItems();
            app.InteractCompDD.Items = varNames1(:,1);

            % MTCompDD.Items and CompSoluteDDs.Items should only be chemical solute species
            app.MTCompDD.Items = app.system.getSoluteChems('name',true);
            app.CompSoluteDD.Items = app.system.getSoluteChems('name',false);

            % CompParamDD.Items, RegCompParamDD.Items, RegSysVarDD.items, PlotDVDD.Items, 
            % should be all biological/chemical
            % solute species in all phases, and environmental comps
            varNames2 = app.system.getCompParamDDItems();
            app.CompParamDD.Items = varNames2(:,1);

            % SysVarT.Data and MTSysVarT.Data should be all biological/chemical 
            % solute species in all phases, environmental comps, and helper
            % functions
            varNames3 = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames3;
            app.SolverSysVarT.Data = varNames3;
            varNames4 = app.system.getModelVarNames("plot");
            app.RegSysVarDD.Items = varNames4(:,1);
            app.SolverEvtSysVarDD.Items = varNames4(:,1);
            varNames5 = app.system.getRegFuncNames();
            app.RegCompParamDD.Items = varNames5(:,1);
            varNames6 = app.system.getStrmSolvNames();
            app.MTSolvNDD.Items = varNames6(:,1);
            varNames7 = app.system.getModelVarNames("nonNeg");
            app.NonNegSysVarAvailLB.Items = varNames7(:,1);

            app.InteractCompDDValueChanged();
            app.MTCompDDValueChanged();
            app.CompSoluteDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();

            app.system.compileModel();

            app.updateSumL();
        end

        % Button pushed function: SpecRB
        function SpecRBButtonPushed(app, event)
            specName = app.SpecNameDD.Value;
            if ~any(strcmp(app.SpecNameDD.Items,specName))
                return;
            end

            % extracting data from table (can optimize)
            TNames = cell([height(app.SpecT.Data),1]);
            for k=1:1:length(TNames)
                TNames{k} = app.SpecT.Data{k,1};
            end

            % removes species from display table
            if any(strcmp(TNames,specName))
                rmIdx = strcmp(TNames, specName);
                app.SpecT.Data(rmIdx,:) = [];
            else
                return;
            end

            % resets numbering of Speices in table
            for k=1:1:height(app.SpecT.Data)
                app.SpecT.Data{k,1} = k;
            end

            % removing Species from ODESys
            app.system.removeSpecies(specName);

            % updating SpecNameDD.Items
            try
                app.SpecNameDD.Items = app.SpecT.Data(:,1);
            catch err
                app.SpecNameDD.Items = {''};
                disp(err);
            end
            app.SpecNameDD.Value = app.SpecNameDD.Items(1); 

            % InteractCompDD.Items should only be biological species and
            % the liquid phase chemical species
            varNames1 = app.system.getInteractCompDDItems();
            app.InteractCompDD.Items = varNames1(:,1);

            % MTCompDD.Items and CompSoluteDDs.Items should only be chemical solute species
            app.MTCompDD.Items = app.system.getSoluteChems('name',true);
            app.CompSoluteDD.Items = app.system.getSoluteChems('name',false);

            % CompParamDD.Items, RegCompParamDD.Items, RegSysVarDD.items, PlotDVDD.Items, 
            % should be all biological/chemical
            % solute species in all phases, and environmental comps
            varNames2 = app.system.getCompParamDDItems();
            app.CompParamDD.Items = varNames2(:,1);

            % SysVarT.Data and MTSysVarT.Data should be all biological/chemical 
            % solute species in all phases, environmental comps, and helper
            % functions
            varNames3 = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames3;
            app.SolverSysVarT.Data = varNames3;
            varNames4 = app.system.getModelVarNames("plot");
            app.RegSysVarDD.Items = varNames4(:,1);
            app.SolverEvtSysVarDD.Items = varNames4(:,1);
            varNames5 = app.system.getRegFuncNames();
            app.RegCompParamDD.Items = varNames5(:,1);
            varNames6 = app.system.getStrmSolvNames();
            app.MTSolvNDD.Items = varNames6(:,1);
            varNames7 = app.system.getModelVarNames("nonNeg");
            app.NonNegSysVarAvailLB.Items = varNames7(:,1);

            app.InteractCompDDValueChanged();
            app.MTCompDDValueChanged();
            app.CompSoluteDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();

            app.system.compileModel();

            app.updateSumL();
        end

        % Value changed function: GenParamDD
        function GenParamDDValueChanged(app, event)
            value = app.GenParamDD.Value;
            switch value
                case "Model Runtime (t)"
                    app.GenParamUnitDD.Items = {'days','hr','min','sec'};
                    app.GenParamUnitDD.Value = 'hr';
                    app.EnvironParamFuncL.Text = "$t =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{1,2});
                    % [paramVals,paramUnits] = app.system.getCurrentEnvironParams();
                    % I_func = matlabFunction(str2sym(paramVals{2}));
                    % t_span = linspace(0,str2double(paramVals{1}),100);
                    % if contains(func2str(I_func),'t')
                    %     plot(app.EnvironParamFuncPlot,t_span,I_func(t_span),'b-');
                    % else
                    %     plot(app.EnvironParamFuncPlot,t_span,I_func().*ones(size(t_span)),'b-');
                    % end
                    % title(app.EnvironParamFuncPlot,"Incident Light Function vs Model Runtime");
                    % xlabel(app.EnvironParamFuncPlot,"Model Runtime ("+string(paramUnits{1})+")");
                    % ylabel(app.EnvironParamFuncPlot,"Incident Light ("+string(paramUnits{2})+")");
                case "Incident Light (I_0)"
                    app.GenParamUnitDD.Items = {'ppf'};
                    app.EnvironParamFuncL.Text = "$I(t) =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{2,2});
                case "Initial Temperature (T_0)"
                    app.GenParamUnitDD.Items = {'C','K','F','R'};
                    app.EnvironParamFuncL.Text = "$T_0 =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{3,2});
                case "Initial Pressure (P_0)"
                    app.GenParamUnitDD.Items = {'kPa','Pa','MPa'};
                    app.EnvironParamFuncL.Text = "$P_0 =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{4,2});
                case "Initial Culture Volume (V_0)"
                    app.GenParamUnitDD.Items = {'L','m^3'};
                    app.EnvironParamFuncL.Text = "$V_0 =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{5,2});
                case "Initial pH (pH_0)"
                    app.GenParamUnitDD.Items = {'N/A'};
                    app.EnvironParamFuncL.Text = "$pH_0 =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{6,2});
            end
        end

        % Button pushed function: EnvGeomUB, GenParamAB
        function GenParamABButtonPushed(app, event)
            paramVal = app.GenParamF.Value;
            paramName = app.GenParamDD.Value;
            paramUnits = app.GenParamUnitDD.Value;

            lightFunc = app.IncidentLightFuncVF.Value;

            reactorType = app.ReactorTypeDD.Value;

            % 1. updating table values
            % 2. unit conversions before input to ODESys
            % envParamNames = {'Model Run Time (t)';'Incident Light (I_0(t))';'Maximum Volume (V_m)';'Temperature (T(t))';'Pressure (P(t))'; ...
            % 'Initial Culture Volume (V_0)';'Initial pH (pH_0)';'Agitation Speed';'Tank Height';'Tank Diameter'; ...
            % 'Tank Surface Area';'Light Configuration';'# Light Sources';'Light Source Diameter';'Light Source Circle Radius'};
            plot_x_unit = "(hr)";
            switch paramName
                case "Model Runtime (t)"
                    app.EnvironT.Data(1,2) = {double(string(paramVal))};
                    app.EnvironT.Data(1,3) = {paramUnits};
                    plot_x_unit = paramUnits;
                case "Initial Temperature (T_0)"
                    app.EnvironT.Data(3,2) = {double(string(paramVal))};
                    app.EnvironT.Data(3,3) = {paramUnits};
                case "Initial Pressure (P_0)"
                    app.EnvironT.Data(4,2) = {double(string(paramVal))};
                    app.EnvironT.Data(4,3) = {paramUnits};
                case "Initial Culture Volume (V_0)"
                    app.EnvironT.Data(5,2) = {double(string(paramVal))};
                    app.EnvironT.Data(5,3) = {paramUnits};
                case "Initial pH (pH_0)"
                    app.EnvironT.Data(6,2) = {double(string(paramVal))};
                    app.EnvironT.Data(6,3) = {paramUnits};
            end

            app.EnvironT.Data(2,1) = {'Incident Light (I_0)'};
            if isempty(lightFunc), lightFunc = 100; end
            app.EnvironT.Data(2,2) = {char(string(lightFunc))};
            app.EnvironT.Data(2,3) = {'ppf'};
            I_func = "@(t)"+lightFunc;
            I_func = str2func(replace(replace(replace(I_func,'*','.*'),'/','./'),'^','.^'));
            t_span = linspace(0,1000,10000);
            if contains(lightFunc,'t')
                plot(app.EnvironParamFuncPlot,t_span,I_func(t_span),'b-');
            else
                plot(app.EnvironParamFuncPlot,t_span,I_func().*ones(size(t_span)),'b-');
            end
            title(app.EnvironParamFuncPlot,"Incident Light Function vs Model Runtime");
            xlabel(app.EnvironParamFuncPlot,"Model Runtime " + plot_x_unit);
            ylabel(app.EnvironParamFuncPlot,"Incident Light (ppf)");
            app.system.updateEnvironment('Incident Light (I_0(t))',lightFunc);

            % reactor geometry-specific parameters
            switch reactorType
                case "Vertical Continuous Stirred Tank"
                    agSpd = app.ReactParam1VF.Value;
                    agSpdU = app.ReactParamUnit1DD.Value;
                    tkHt_disp = app.ReactParam2VF.Value;
                    tkDiam_disp = app.ReactParam3VF.Value;
                    tkHtU = app.ReactParamUnit2DD.Value;
                    tkDiamU = app.ReactParamUnit3DD.Value;
                    tkHt = unit_standardization(tkHt_disp,tkHtU);
                    tkDiam = unit_standardization(tkDiam_disp,tkDiamU);
                    maxVol = (tkHt.*pi.*(tkDiam./2).^2).*1000;

                    lgtAttnModel = app.LgtAttnMdlDD.Value;
                    lgtBioAttn = app.LgtMdlCB1.Value;
                    lgtChemAttn = app.LgtMdlCB2.Value;
                    % lgtChemAttnGas = app.LgtMdlCB3.Value;
                    % lgtMedAttn = app.LgtMdlCB4.Value;

                    reactorSpecificParamN = {'Agitation Speed';'Tank Height';'Tank Diameter';'Max Tank Volume';'Light Attenuation Model'; ...
                        'Biological Light Shading';'Chemical Light Shading'};
                    reactorSpecificParams = {agSpd;tkHt_disp;tkDiam_disp;maxVol;lgtAttnModel;lgtBioAttn;lgtChemAttn};
                    reactorSpecificParamU = {agSpdU;tkHtU;tkDiamU;'L';'-';'-';'-'};

                    environ_vars = struct( ...
                        'agSpd',agSpd, ...
                        'tkHt',tkHt, ...
                        'tkDiam',tkDiam, ...
                        'maxVol',maxVol, ...
                        'lgtAttnModel',lgtAttnModel, ...
                        'lgtBioAttn',lgtBioAttn, ...
                        'lgtChemAttn',lgtChemAttn ...
                    );

                    % updating environment table display
                    app.EnvironT.Data((7):(6+length(reactorSpecificParams)),:) = ...
                        [reactorSpecificParamN,reactorSpecificParams,reactorSpecificParamU];

                case "Horizontal Plug Flow Reactor"
                    
                case "Flat Panel"
                    tkH_disp = app.ReactParam1VF.Value;
                    tkW_disp = app.ReactParam2VF.Value;
                    tkD_disp = app.ReactParam3VF.Value;
                    tkHU = app.ReactParamUnit1DD.Value;
                    tkWU = app.ReactParamUnit1DD.Value;
                    tkDU = app.ReactParamUnit1DD.Value;
                    tkH = unit_standardization(tkH_disp,tkHU);
                    tkW = unit_standardization(tkW_disp,tkWU);
                    tkD = unit_standardization(tkD_disp,tkDU);
                    maxVol = tkH.*tkW.*tkD.*1000;

                    lgtAttnModel = app.LgtAttnMdlDD.Value;
                    lgtBioAttn = app.LgtMdlCB1.Value;
                    lgtChemAttn = app.LgtMdlCB2.Value;

                    reactorSpecificParamN = {'Tank Height';'Tank Width';'Tank Depth';'Max Tank Volume';'Light Attenuation Model'; ...
                        'Biological Light Shading';'Chemical Light Shading'};
                    reactorSpecificParams = {tkH_disp;tkW_disp;tkD_disp;maxVol;lgtAttnModel;lgtBioAttn;lgtChemAttn};
                    reactorSpecificParamU = {tkHU;tkWU;tkDU;'L';'-';'-';'-'};

                    environ_vars = struct( ...
                        'tkH',tkH, ...
                        'tkW',tkW, ...
                        'tkD',tkD, ...
                        'maxVol',maxVol, ...
                        'lgtAttnModel',lgtAttnModel, ...
                        'lgtBioAttn',lgtBioAttn, ...
                        'lgtChemAttn',lgtChemAttn ...
                    );

                    % updating environment table display
                    app.EnvironT.Data((7):(6+length(reactorSpecificParams)),:) = ...
                        [reactorSpecificParamN,reactorSpecificParams,reactorSpecificParamU];
                case "Raceway Pond"

                case "Shaking Flask"

                case "Benchtop Bioreactor"

                case "Eutrophic Lake System"

            end
            % updating reactor specific environment parameters in ODESys
            lgtAttnMdlLaTeX = app.system.updateEnvironment("reactorSpecificParams",{reactorType,environ_vars});
            app.LgtAttnMdlL.Text = lgtAttnMdlLaTeX;

            % update environment parameters in ODESys
            app.system.updateEnvironment(paramName, paramVal);
            app.system.environ_vars = environ_vars;

            % updates GovFunc var table
            varNames = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames;
            varNames4 = app.system.getModelVarNames("plot");
            app.RegSysVarDD.Items = varNames4(:,1);

            % Plotting for incident light
            if strcmp(paramName,"Incident Light (I_0)")
                [paramVals,paramUnits] = app.system.getCurrentEnvironParams();
                I_func = str2func("@(t)"+paramVals{1});
                t_span = linspace(0,100,100);
                if contains(paramVals{1},'t')
                    plot(app.EnvironParamFuncPlot,t_span,I_func(t_span),'b-');
                else
                    plot(app.EnvironParamFuncPlot,t_span,I_func().*ones(size(t_span)),'b-');
                end
                title(app.EnvironParamFuncPlot,"Incident Light Function vs Model Runtime");
                xlabel(app.EnvironParamFuncPlot,"Model Runtime");
                ylabel(app.EnvironParamFuncPlot,"Incident Light ("+paramUnits{1}+")");
            end

            app.system.compileModel();

            app.updateSumL();
        end

        % Button pushed function: ChemAB
        function ChemABPushed(app, event)
            % get chemical name input
            chemName = app.ChemNameDD.Value;
            if strcmp(chemName,""), return; end
            % get chemical initial concentration input
            chemInitConc = app.ChemInitConcF.Value;
            % get chemical init conc unit input
            chemInitConcUnit = app.ChemInitConcUDD.Value;

            if strcmp(chemInitConc,"g/L")
                chemInitDispConc = chemInitConc;
            elseif strcmp(chemInitConcUnit,"mg/L")
                chemInitDispConc = chemInitConc;
                chemInitConc = chemInitConc./1000;
            else
                chemInitDispConc = chemInitConc;
            end

            is_vol = app.VolCB.Value;
            type = app.ChemTypeDD.Value;
            MW = double(string(app.MWVF.Value));
            h_const = double(string(app.HConstVF.Value));
            h_const_u = char(app.HConstUDD.Value);
            dh_const = double(string(app.HConst2VF.Value));
            dh_const_u = char(app.HConst2UDD.Value);

            if is_vol, is_vol_disp = 'Yes'; else, is_vol_disp = 'No'; end
            h_const_disp = h_const;
            dh_const_disp = dh_const;
            if ~is_vol
                h_const = 1;
                h_const_u = '';
                dh_const = 1;
                dh_const_u = '';
                h_const_disp = 'N/A';
                dh_const_disp = 'N/A';
            end

            % extracting data from table (can optimize)
            TNames = cell([height(app.ChemT.Data),1]);
            for k=1:1:length(TNames)
                TNames{k} = app.ChemT.Data{k,1};
            end

            % adds new Chemical to display table
            if any(strcmp(TNames,chemName))
                % updated Chemical table data
                updIdx = strcmp(TNames, chemName);
                app.ChemT.Data(updIdx,:) = {chemName, app.ChemT.Data{updIdx,2}, type, is_vol_disp, chemInitDispConc, chemInitConcUnit, ...
                    MW, h_const_disp, h_const_u, dh_const_disp, dh_const_u};
                % update Chemical in ODESys
                app.system.updateChemical(chemName,chemInitConc,chemInitConcUnit,is_vol,MW,h_const,h_const_u,dh_const,dh_const_u);
            else
                % new Chemical table data
                if strcmp(type,'Chemical Solute'), chemSym = 'C_'; elseif strcmp(type,'Suspended Solid Sorbent'), chemSym = 'V_S_'; end
                app.ChemT.Data = [app.ChemT.Data; {chemName, [chemSym,char(string(length(TNames)+1))], type, is_vol_disp, chemInitDispConc, chemInitConcUnit, ...
                    MW, h_const_disp, h_const_u, dh_const_disp, dh_const_u}];
                % add new Chemical to ODESys
                app.system.addChemical(length(TNames)+1,chemName,chemInitConc,chemInitConcUnit,type,is_vol,MW,h_const,h_const_u,dh_const,dh_const_u);
            end

            % updating ChemNameDD.Items
            app.ChemNameDD.Items = app.ChemT.Data(:,1);

            % InteractCompDD.Items should only be biological species and
            % the liquid phase chemical species
            varNames1 = app.system.getInteractCompDDItems();
            app.InteractCompDD.Items = varNames1(:,1);

            % MTCompDD.Items and CompSoluteDDs.Items should only be chemical solute species
            app.MTCompDD.Items = app.system.getSoluteChems('name',true);
            app.CompSoluteDD.Items = app.system.getSoluteChems('name',false);

            % CompParamDD.Items, RegCompParamDD.Items, RegSysVarDD.items, PlotDVDD.Items, 
            % should be all biological/chemical
            % solute species in all phases, and environmental comps
            varNames2 = app.system.getCompParamDDItems();
            app.CompParamDD.Items = varNames2(:,1);

            % SysVarT.Data and MTSysVarT.Data should be all biological/chemical 
            % solute species in all phases, environmental comps, and helper
            % functions
            varNames3 = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames3;
            app.SolverSysVarT.Data = varNames3;
            varNames4 = app.system.getModelVarNames("plot");
            app.RegSysVarDD.Items = varNames4(:,1);
            app.SolverEvtSysVarDD.Items = varNames4(:,1);
            varNames5 = app.system.getRegFuncNames();
            app.RegCompParamDD.Items = varNames5(:,1);
            varNames6 = app.system.getStrmSolvNames();
            app.MTSolvNDD.Items = varNames6(:,1);
            varNames7 = app.system.getModelVarNames("nonNeg");
            app.NonNegSysVarAvailLB.Items = varNames7(:,1);

            app.InteractCompDDValueChanged();
            app.MTCompDDValueChanged();
            app.CompSoluteDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();
            app.ChemNameDDValueChanged();

            app.system.compileModel();

            app.updateSumL();
        end

        % Button pushed function: ChemRB
        function ChemRBButtonPushed(app, event)
            chemName = app.ChemNameDD.Value;
            if chemName == "Custom Chemical"
                chemName = app.ChemCustNameF.Value;
            end

            % extracting data from table (can optimize)
            TNames = cell([height(app.ChemT.Data),1]);
            for k=1:1:length(TNames)
                TNames{k} = app.ChemT.Data{k,1};
            end

            % removes chemical from display table
            if any(strcmp(TNames,chemName))
                rmIdx = strcmp(TNames, chemName);
                app.ChemT.Data(rmIdx,:) = [];
            else
                return;
            end

            % removing Chemical from ODESys
            app.system.removeChemical(chemName);

            % updating ChemNameDD.Items
            try
                app.ChemNameDD.Items = app.ChemT.Data(:,1);
            catch err
                disp(err);
                app.ChemNameDD.Value = app.ChemNameDD.Items(1);
            end

            % InteractCompDD.Items should only be biological species and
            % the liquid phase chemical species
            varNames1 = app.system.getInteractCompDDItems();
            app.InteractCompDD.Items = varNames1(:,1);

            % MTCompDD.Items and CompSoluteDDs.Items should only be chemical solute species
            app.MTCompDD.Items = app.system.getSoluteChems('name',true);
            app.CompSoluteDD.Items = app.system.getSoluteChems('name',false);

            % CompParamDD.Items, RegCompParamDD.Items, RegSysVarDD.items, PlotDVDD.Items, 
            % should be all biological/chemical
            % solute species in all phases, and environmental comps
            varNames2 = app.system.getCompParamDDItems();
            app.CompParamDD.Items = varNames2(:,1);

            % SysVarT.Data and MTSysVarT.Data should be all biological/chemical 
            % solute species in all phases, environmental comps, and helper
            % functions
            varNames3 = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames3;
            app.SolverSysVarT.Data = varNames3;
            varNames4 = app.system.getModelVarNames("plot");
            app.RegSysVarDD.Items = varNames4(:,1);
            app.SolverEvtSysVarDD.Items = varNames4(:,1);
            varNames5 = app.system.getRegFuncNames();
            app.RegCompParamDD.Items = varNames5(:,1);
            varNames6 = app.system.getStrmSolvNames();
            app.MTSolvNDD.Items = varNames6(:,1);
            varNames7 = app.system.getModelVarNames("nonNeg");
            app.NonNegSysVarAvailLB.Items = varNames7(:,1);

            app.InteractCompDDValueChanged();
            app.MTCompDDValueChanged();
            app.CompSoluteDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();
            app.ChemNameDDValueChanged();

            app.system.compileModel();

            app.updateSumL();
        end

        % Value changed function: ChemNameDD
        function ChemNameDDValueChanged(app, event)
            chemName = app.ChemNameDD.Value;
            % ### STARTHERE: if the chemname is in chems that are already
            % existing in model, and the type is suspended solids, then
            % activate sorbent equilibrium stuff, else deactivate
            chem = app.system.getCompByName(chemName);
            try
                if strcmp(chem.getType(),'Chemical Solute')
                    app.VolCB.Enable = true;
                    if app.VolCB.Value
                        app.HConstL.Enable = true;
                        app.HConstVF.Enable = true;
                        app.HConstUDD.Enable = true;
                        app.HConst2L.Enable = true;
                        app.HConst2VF.Enable = true;
                        app.HConst2UDD.Enable = true;
                    end
    
                    app.CompSoluteDD.Enable = false;
                    app.SorptionModelDD.Enable = false;
                    app.SorpParam1VF.Enable = false;
                    app.SorpParam2VF.Enable = false;
                    app.SorpParam3VF.Enable = false;
                    app.Param1L.Enable = false;
                    app.Param2L.Enable = false;
                    app.Param3L.Enable = false;
                    app.SorptionModelUB.Enable = false;
                    app.SorptionModelRB.Enable = false;
                    app.CompSolutesL.Enable = false;
                    app.SorpEqML.Enable = false;
                    app.SorptionT.Enable = 'off';
    
                    app.ChemInitConcL.Text = "Initial Concentration";
                    app.ChemInitConcUDD.Items = {'g/L','mg/L','ug/L'};
                elseif strcmp(chem.getType(),'Suspended Solid Sorbent')
                    app.VolCB.Enable = false;
                    app.HConstL.Enable = false;
                    app.HConstVF.Enable = false;
                    app.HConstUDD.Enable = false;
                    app.HConst2L.Enable = false;
                    app.HConst2VF.Enable = false;
                    app.HConst2UDD.Enable = false;
    
                    app.CompSoluteDD.Enable = true;
                    app.SorptionModelDD.Enable = true;
                    app.SorpParam1VF.Enable = true;
                    app.SorpParam2VF.Enable = true;
                    app.SorpParam3VF.Enable = true;
                    app.Param1L.Enable = true;
                    app.Param2L.Enable = true;
                    app.Param3L.Enable = true;
                    app.SorptionModelUB.Enable = true;
                    app.SorptionModelRB.Enable = true;
                    app.CompSolutesL.Enable = true;
                    app.SorpEqML.Enable = true;
                    app.SorptionT.Enable = 'on';
                    app.SorptionModelDDValueChanged();
    
                    app.ChemInitConcL.Text = "Initial Volume";
                   app.ChemInitConcUDD.Items = {'L','mL','m^3'};
                end
            catch err
                err
            end
        end

        % Value changed function: CompParamDD
        function CompParamDDValueChanged(app, event)
            compName = app.CompParamDD.Value;

            if isempty(compName), return; end

            compNames = [app.system.getCompNamesByType("Biological Solute",false), ...
                app.system.getCompNamesByType("Chemical Solute",false), ...
                app.system.getCompNamesByType("Environ",false)];
            envFuncNames = app.system.getEnvFuncNames();
            helperFuncNames = app.system.getHelperFuncNames();
            if any(strcmp(compNames,compName))
                % getting growth parameter data from Species
                params = app.system.getGrthParamsByFuncName(compName);
            elseif any(strcmp(envFuncNames(:,1),compName))
                params = app.system.getGrthParamsByEnvFuncName(compName);
            elseif any(strcmp(helperFuncNames(:,1),compName))
                params = app.system.getGrthParamsByHelperFuncName(compName);
            else
                return;
            end
            
            % ### STARTHERE: Pressure has a bunch of random params that
            % shouldn't be params? If a function has no params, it throws
            % an error
            % test5 = params
            % updating the data in GrthParamT
            app.ParamT.Data = params;
            % ### UPDATE: add logic here to restrict users from editing
            % values for R and tau
            app.ParamNumDD.Items = params(:,1);
            app.ParamSymDD.Items = params(:,4);
            if length(app.ParamNumDD.Items) > 0 %#ok<ISMT> 
                app.ParamNumDD.Value = app.ParamNumDD.Items(1);
            end
            if length(app.ParamSymDD.Items) > 0 %#ok<ISMT>
                app.ParamSymDD.Value = app.ParamSymDD.Items(1);
            end
%             idx = convertCharsToStrings(app.ParamT.Data(:,4)) == convertCharsToStrings(params(:,4))
%             test = app.ParamT.Data
            % ### FIXMEUI: try and catch
            try
                app.ParamNF.Value = app.ParamT.Data{1,3};
                app.ParamValF.Value = app.ParamT.Data{1,5};
                app.ParamUnitDD.Value = app.ParamT.Data{1,6};
            catch err
                err
            end
        end

        % Button pushed function: ParamUB
        function ParamUBPushed(app, event)
            compName = app.CompParamDD.Value;
            paramSym = app.ParamSymDD.Value;
            paramNum = str2double(app.ParamNumDD.Value);
            newParamName = app.ParamNF.Value;
            newParamVal = app.ParamValF.Value;
            newParamUnit = app.ParamUnitDD.Value;
            
            compNames = [app.system.getCompNamesByType("Biological Solute",false),app.system.getCompNamesByType("Chemical Solute",false)];
            envFuncNames = app.system.getEnvFuncNames();
            helperFuncNames = app.system.getHelperFuncNames();
            if any(strcmp(compNames,compName))
                % funcName = app.ParamT.Data{paramNum,2};
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,true);
                params = app.system.getGrthParamsByFuncName(compName);
            elseif any(strcmp(envFuncNames(:,1),compName))
                % funcName = app.CompParamDD.Value;
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,true);
                params = app.system.getGrthParamsByEnvFuncName(compName);
            elseif any(strcmp(helperFuncNames(:,1),compName))
                % funcName = app.CompParamDD.Value;
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,true);
                params = app.system.getGrthParamsByHelperFuncName(compName);
            else
                return;
            end

            if ~any(strcmp([app.ParamUnitDD.Items{:}],newParamUnit))
                app.ParamUnitDD.Items = [app.ParamUnitDD.Items,{newParamUnit}];
            end

            % updating the data in GrthParamT
            app.ParamT.Data = params;

            app.system.compileModel();
        end

        % Value changed function: ParamSymDD
        function ParamSymDDValueChanged(app, event)
            paramSym = app.ParamSymDD.Value;
            % ### FIXME: if 2 params of the same name, won't automatically
            % adjust editor values when the parameter symbol is changed
            idx = convertCharsToStrings(app.ParamT.Data(:,4)) == convertCharsToStrings(paramSym);
            app.ParamNumDD.Value = app.ParamNumDD.Items{idx};
            app.ParamNF.Value = app.ParamT.Data{idx,3};
            app.ParamValF.Value = app.ParamT.Data{idx,5};
            app.ParamUnitDD.Value = app.ParamT.Data{idx,6};

%             idx = convertCharsToStrings(app.ParamT.Data(:,4)) == convertCharsToStrings(paramSym)
        end

        % Value changed function: ParamNumDD
        function ParamNumDDValueChanged(app, event)
            paramNum = app.ParamNumDD.Value;
            idx = [app.ParamT.Data{:,1}] == paramNum;
            app.ParamSymDD.Value = app.ParamSymDD.Items{idx};
            app.ParamNF.Value = app.ParamT.Data{idx,3};
            app.ParamValF.Value = app.ParamT.Data{idx,5};
            app.ParamUnitDD.Value = app.ParamT.Data{idx,6};

%             idx = convertCharsToStrings(app.ParamT.Data(:,4)) == convertCharsToStrings(paramSym)
        end

        % Value changed function: InteractCompDD
        function InteractCompDDValueChanged(app, event)
            compName = app.InteractCompDD.Value;

            if isempty(compName), return; end

            % update the displayed governing function
            compSym = app.system.getCompSymByName(compName);
            [existFunc,existFuncUnicode] = app.system.getGovFuncByCompName(compName);
            app.GovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFunc+"$";
            app.GovFuncVF.Value = existFuncUnicode;

            % enables piecewise editors
            app.PiecewiseLimsP.Enable = true;

            % disables ModelUB
            app.ModelUB.Enable = false;

            % app.CompParamDD.Value = compName;
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();
        end

        % Button pushed function: ModelUB
        function ModelUBButtonPushed(app, event)
            compName = app.InteractCompDD.Value;
            funcVal = app.GovFuncVF.Value;

            % save the new function to the component
            app.system.updateModel(compName,funcVal,'');

            % set governing func saved status to "Saved"
            app.SaveL.Text = "Saved";

            % enables relevant editors
            app.PiecewiseLimsP.Enable = true;

            % disables ModelUB, reenable when there is change
            app.ModelUB.Enable = false;

            % getting growth parameter data from Species
            params = app.system.getGrthParamsByFuncName(compName);
            % updating the data in GrthParamT
            app.ParamT.Data = params;

            varNames = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames;

            % ### UPDATE: automatically remove any piecewise expressions
            % that no longer exist in the governing function


            % updating the Param editors with CompParamDD callback
            idx = regexp(compName,'\(');
            compName = char(compName);
            if ~isempty(idx), compName = compName(1:idx-2); end
            app.CompParamDD.Value = compName;
            app.CompParamDDValueChanged();
            % getting growth parameter data for regression
            app.RegCompParamDDValueChanged();

            % updates SumL text
            app.updateSumL();

            % ### UPDATE/DEMO: separate compilation of parameters from
            % compilation of everything else
            app.system.compileModel();

            app.ModelFunctionsPanel.Scrollable = "on";
        end

        % Callback function
        function PlotTitleAxUBButtonPushed(app, event)
            plotName = app.PlotTitleDD.Value;
            title = app.PlotTitleF.Value;
            if app.NbAxesDD.Value == "2-Axis"
                axesNb = 2;
            else
                axesNb = 3;
                app.AxVarUBButtonPushed(struct('Source',app.YAxVarAB));
            end
            % ### FIXME: add code to disable Z-axis if only 2 axes
            % specified
            axesUI = app.system.updatePlotNameAx(plotName,title,axesNb);
            app.updateAxesUI(axesUI);
            items = convertCharsToStrings(app.PlotTitleDD.Items);
            items(items == app.PlotTitleDD.Value) = title;
            app.PlotTitleDD.Items = ~strcmp(items,"");
            app.PlotTitleDD.Value = title;

            % update IC + Eval Specs
            axesNames = cell(length(axesUI),1);
            for k=1:1:length(axesNames)
                axesNames{k} = axesUI{k}{2};
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};
            app.ICEvalAxisDDValueChanged();
        end

        % Button pushed function: PlotAB
        function PlotABButtonPushed(app, event)
            % ### FIXME: convert to more efficient plot data getting
            [plot,axes] = app.system.createNewPlot();
            app.NbAxesDD.Value = app.NbAxesDD.Items{1};
            app.updatePlotUI(plot);
            items = [app.PlotTitleDD.Items,plot{1}];
            app.PlotTitleDD.Items = items(~strcmp(items,""));
            app.PlotTitleDD.Value = plot{1};
%             app.(dir+"AxVarL").Text = "Variable(s): "+varName;

            % setting default axes editor values
            app.updateAxesUI(axes);
        end

        % Button pushed function: PlotRB
        function PlotRBButtonPushed(app, event)
            % update title, axes number, save as function, generation
            % options, initial condition stuff
            plotName = app.PlotTitleDD.Value;
            if length(app.PlotTitleDD.Items) > 1 
                [plot,axes] = app.system.removePlot(plotName,false);
                app.updatePlotUI(plot);
                items = convertCharsToStrings(app.PlotTitleDD.Items);
                items(items == plotName) = [];
                app.PlotTitleDD.Items = items(~strcmp(items,""));
                app.PlotTitleDD.Value = app.PlotTitleDD.Items{1};
                for k=1:1:length(app.plotDispTreeNodes)
                    if app.plotDispTreeNodes{k}.name == plotName
                        app.plotDispTreeNodes{k}.main.delete();
                        app.plotDispTreeNodes{k}.display.delete();
                        app.plotDispTreeNodes{k}.download.delete();
                        app.plotDispTreeNodes(k) = [];
                        break;
                    end
                end
                app.updateAxesUI(axes);
            else
                % code to reset entire plot customization tab to default,
                % remove last item - can do this later, or can just leave
                % as is
                % [plot,axes] = app.system.removePlot(plotName,true);
            end
        end

        % Value changed function: ICEvalAxisDD
        function ICEvalAxisDDValueChanged(app, event)
            axisName = app.ICEvalAxisDD.Value;
            plotName = app.PlotTitleF.Value;
            % 1 - Vars, 2 - Eval Var at t, 3 - Lower Eval Lim, 4 - Upper
            % Eval Lim, 5 - Number of Eval Points, 6 - isDV (boolean), 7 -
            % varIsIC (boolean)
            ICEvalData = app.system.getAxisICEvalData(plotName,axisName);
            app.ICEvalVarDD.Items = ICEvalData{1};
            app.ICEvalVarDD.Value = app.ICEvalVarDD.Items{1};

            app.ICEvalVarDDValueChanged();
        end

        % Callback function
        function AxDispLimUBButtonPushed(app, event)
            dir = event.Source.Tag;
            plotName = app.PlotTitleF.Value;
            loDispLim = app.(dir+"AxLoDispLimVF").Value;
            upDispLim = app.(dir+"AxUpDispLimVF").Value;
            useDefR = app.(dir+"AxDefaultRCB").Value;
            app.system.updateAxDispLims(plotName,dir,loDispLim,upDispLim,useDefR);
        end

        % Button pushed function: PlotDownloadDirB
        function PlotDownloadDirBButtonPushed(app, event)
            dirName = uigetdir;
            plotName = app.PlotTitleDD.Value;
            app.system.setPlotDir(plotName,dirName);
            app.PlotDirL.Text = "Directory: "+dirName;
        end

        % Button pushed function: GenPlotB
        function GenPlotBButtonPushed(app, event)
            if app.ClosePrevPlotsCB.Value
                close all;
            end

            app.system.generatePlots();
        end

        % Callback function
        function PlotDispTreeCheckedNodesChanged(app, event)
            % ### FIXME: issue with Parent node being switched if the
            % actual parent is not checked, this causes inaccuracies.
            nodes = app.PlotDispTree.CheckedNodes;
            app.system.clearPlotDispDownload();
            
            for k=1:1:length(nodes)
                if nodes(k).Text == "Display"
                    disp("worked")
                    prop = "display";
                    plotName = nodes(k).Parent.Text;
                    app.system.updatePlotDispDownload(plotName,prop,true);
                elseif nodes(k).Text == "Download"
                    prop = "download";
                    plotName = nodes(k).Parent.Text;
                    app.system.updatePlotDispDownload(plotName,prop,true);
                else
                    plotName = nodes(k).Text;
                    app.system.updatePlotDispDownload(plotName,"display",true);
                    app.system.updatePlotDispDownload(plotName,"download",true);
                end
            end
        end

        % Button pushed function: AxVarAB, AxVarRB
        function AxVarUBButtonPushed(app, event)
            % ### FIXME: add functionality to update whether selected
            % variable is a set of IC
            % add functionality to update the options for the ranges of max
            % and min
            tag = event.Source.Tag;
            if tag == "add"
                addVar = true;
                varName = string(app.PlotAvailCompLB.Value);
            else
                addVar = false;
                varName = string(app.PlotSelectedCompLB.Value);
            end
            plotName = app.PlotTitleF.Value;
            axis_dir = app.AxisDD.Value;
            if strcmp(axis_dir,'X-Axis'), dir = 'X'; elseif strcmp(axis_dir,'Y-Axis'), dir = 'Y'; else, dir = 'Z'; end
            axesUI = app.system.updateAxVars(plotName,dir,varName,addVar);
            app.updateAxesUI(axesUI);
            app.ICEvalAxisDDValueChanged();
        end

        % Callback function
        function AxTitleUpdate(app, event)
            dir = event.Source.Tag;
            plotName = app.PlotTitleF.Value;
            title = app.(dir+"AxTitleF").Value;
            axesUI = app.system.updateAxTitle(plotName,dir,title);
            % update IC + Eval Specs
            axesNames = cell(length(axesUI),1);
            for k=1:1:length(axesNames)
                axesNames{k} = axesUI{k}{2};
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};
            app.ICEvalAxisDDValueChanged();
        end

        % Button pushed function: PlotICEvalSpecUB
        function PlotICEvalSpecUBButtonPushed(app, event)
            plotName = app.PlotTitleF.Value;
            axisName = app.ICEvalAxisDD.Value;
            varUnits = app.evalUnitsVF.Value;
            evaltVal = app.AddSpecVF3.Value;
            loEvalLim = app.AddSpecVF3.Value;
            upEvalLim = app.AddSpecVF2.Value;
            nbEvalPts = app.AddSpecVF3.Value;

            varName = string(app.ICEvalVarDD.Value);
            app.system.setVarICEvalData(plotName,axisName,varName,varUnits,evaltVal,loEvalLim,upEvalLim,nbEvalPts);
        end

        % Value changed function: ICEvalVarDD
        function ICEvalVarDDValueChanged(app, event)
            varName = string(app.ICEvalVarDD.Value);
            plotName = app.PlotTitleF.Value;
            axisName = app.ICEvalAxisDD.Value;
            % ### FIXME: put in options for each of the DD
            % ### FIXME: also need to make sure that only the relevant
            % variables are being displayed
            % 1 - evaltVal, 2 - loEvalLim, 3 - upEvalLim, 4 - nbEvalPts, 5
            % - isDV, 6 - varIsIC, 7 - needDefineEvalt
            varData = app.system.getVarICEvalData(plotName,axisName,varName);
            % ### STARTHERE: need to fix this, units is getting messed up
            % UIcomps = ["evalUnitsVF","AddSpecVF1","AddSpecVF2","AddSpecVF3"];
            app.evalUnitsVF.Value = varData{1};
            if varData{end}
                app.AddSpecVF1.Value = varData{2};
                app.AddSpecVF2.Value = 0;
                app.AddSpecVF3.Value = 0;
            else
                app.AddSpecVF1.Value = varData{3};
                app.AddSpecVF2.Value = varData{4};
                app.AddSpecVF3.Value = varData{5};
            end
        
            % if var is DV
            if varData{6}
                % if need to define eval t
                if varData{end}
                    app.AddSpecL1.Text = "Evaluate @ Time (t) =";
                    app.AddSpecL1.Visible = true;
                    app.AddSpecVF3.Visible = true;
                    app.AddSpecVF3.Enable = true;
                    % ### FIXME: add time evaluation options
                    % set evaluation time, check if it is within the
                    % sys.model_time

                    app.AddSpecL2.Text = "";
                    app.AddSpecL2.Visible = false;
                    app.AddSpecVF2.Visible = false;
                    app.AddSpecVF2.Enable = false;

                    app.AddSpecL3.Text = "";
                    app.AddSpecL3.Visible = false;
                    app.AddSpecVF3.Visible = false;
                    app.AddSpecVF3.Enable = false;
                else
                    app.AddSpecL1.Text = "";
                    app.AddSpecL1.Visible = false;
                    app.AddSpecVF3.Visible = false;
                    app.AddSpecVF3.Enable = false;

                    app.AddSpecL2.Text = "";
                    app.AddSpecL2.Visible = false;
                    app.AddSpecVF2.Visible = false;
                    app.AddSpecVF2.Enable = false;

                    app.AddSpecL3.Text = "";
                    app.AddSpecL3.Visible = false;
                    app.AddSpecVF3.Visible = false;
                    app.AddSpecVF3.Enable = false;
                end
            else
                % if var is IC
                if varData{7}
                    app.AddSpecL1.Text = "Lower Evaluation Limit =";
                    app.AddSpecL1.Visible = true;
                    app.AddSpecVF3.Visible = true;
                    app.AddSpecVF3.Enable = true;

                    app.AddSpecL2.Text = "Upper Evaluation Limit =";
                    app.AddSpecL2.Visible = true;
                    app.AddSpecVF2.Visible = true;
                    app.AddSpecVF2.Enable = true;

                    app.AddSpecL3.Text = "Number of Evaluation Points (#Pts) =";
                    app.AddSpecL3.Visible = true;
                    app.AddSpecVF3.Visible = true;
                    app.AddSpecVF3.Enable = true;
                else
                    app.AddSpecL1.Text = "";
                    app.AddSpecL1.Visible = false;
                    app.AddSpecVF3.Visible = false;
                    app.AddSpecVF3.Enable = false;

                    app.AddSpecL2.Text = "";
                    app.AddSpecL2.Visible = false;
                    app.AddSpecVF2.Visible = false;
                    app.AddSpecVF2.Enable = false;

                    app.AddSpecL3.Text = "";
                    app.AddSpecL3.Visible = false;
                    app.AddSpecVF3.Visible = false;
                    app.AddSpecVF3.Enable = false;
                end
            end
        end

        % Value changed function: PlotTitleDD
        function PlotTitleDDValueChanged(app, event)
            plotName = app.PlotTitleDD.Value;
            plot = app.system.getPlotByName(plotName);
            plotUI = plot.getAllPlotProps();
            axesUI = plot.getAllAxProps();
            app.updatePlotUI(plotUI);
%             app.PlotTitleF.Value = plotName;
%             app.NbAxesDD.Value = app.NbAxesDD.Items{length(axesUI)-1};
            app.updateAxesUI(axesUI);
        end

        % Button pushed function: ImportDataB
        function ImportBButtonPushed(app, event)
            [importedData,path,colNames] = app.system.getUserImportedData();
            app.ImportDataB.Enable = false;
            app.DataImportWindow = DataImportWindow(app,path,importedData,colNames,app.ImportDataB); %#ok<ADPROPLC> 
        end

        % Value changed function: RegCompParamDD
        function RegCompParamDDValueChanged(app, event)
            % ### FIXME: add functionality to take both helper functions
            % and env functions
            funcName = app.RegCompParamDD.Value;
            % getting growth parameter data from Species
            params = app.system.getGrthParamsByFuncName(funcName);
            if isempty(params)
                app.RegParamDD.Items = {};
            else
                app.RegParamDD.Items = params(:,3);
            end
        end

        % Button pushed function: RegParamAB, RegParamRB
        function RegParamUpdate(app, event)
            tag = event.Source.Tag;
            paramSym = app.RegParamDD.Value;
            compName = app.RegCompParamDD.Value;
            params = app.system.updateRegParamList(compName,paramSym,tag);
            app.RegParamT.Data = params;
            app.SaveRegParamDD.Items = params(:,1);
        end

        % Button pushed function: VarDataMatchB, VarDataUnmatchB
        function UpdateDVDataMatching(app, event)
            match = event.Source.Tag;
            sysVar = app.RegSysVarDD.Value;
            importVar = app.ImportedDataDD.Value;
            importVarNum = convertCharsToStrings(app.ImportedDataDD.Items) == convertCharsToStrings(importVar);
            matches = app.system.updateVarMatch(sysVar,importVar,importVarNum,match);
            app.RegVarDataT.Data = matches;
            matches_2 = matches(:,1);
            app.RegDVDD.Items = matches_2(~strcmp(matches_2,'Model Runtime'));
            regParamList = app.system.getRegParamList();
            if ~isempty(app.RegDVDD.Items) && ~isempty(regParamList)
                app.RegSpecsB.Enable = true;
                app.RunRegB.Enable = true;
            else
                app.RegSpecsB.Enable = false;
                app.RunRegB.Enable = false;
                app.RegAnalysisB.Enable = false;
            end
        end

        % Button pushed function: RegDVAB
        function RegDVPlotUpdate(app, event)
            varName = string(app.RegDVDD.Value);
            comps = app.system.getModelVarNamesReg();
            DVIdx = strcmp(comps(:,1),varName);
            [plotRegData,plotImportData] = app.system.updateRegPlotDV(DVIdx);
            cla(app.RegPlot);
            hold(app.RegPlot,"on");
            plot(app.RegPlot,plotImportData(:,1),plotImportData(:,2),'ko');
            plot(app.RegPlot,linspace(plotRegData(1,1),plotRegData(end,1),100), ...
                spline(plotRegData(:,1),plotRegData(:,2), ...
                linspace(plotRegData(1,1),plotRegData(end,1),100)),'k-');
            hold(app.RegPlot,"off");
        end

        % Button pushed function: RegSpecsB
        function RegSpecsBButtonPushed(app, event)
            regSpecs = app.system.getRegSpecs();
            regParamList = app.system.getRegParamList();
            regParamList = regParamList(:,[1,4]);
            app.RegSpecsB.Enable = false;
            app.RegSpecsWindow = RegSpecsWindow(app,regSpecs,regParamList,app.RegSpecsB); %#ok<ADPROPLC> 
        end

        % Button pushed function: RunRegB
        function RunRegBPushed(app, event)
            % ### FIXME: for the starting point for the regression, the
            % first data point of the imported data should be the initial
            % condition for the regression, but since not every DV will
            % necessarily be imported, need to just recommend that each
            % imported data set start at time = 0

            regStats = app.system.compileRegression();
            varName = string(app.RegDVDD.Value);
            comps = app.system.getModelVarNamesReg();
            DVIdx = strcmp(comps(:,1),varName);
            cla(app.RegPlot);
            hold(app.RegPlot,"on");
            plot(app.RegPlot,regStats.IVs,regStats.DVs(:,(app.RegDVDD.Items == varName)),'ko');
            plot(app.RegPlot,linspace(regStats.tRes(1),regStats.tRes(end),100), ...
                spline(regStats.tRes,regStats.yRes(:,DVIdx), ...
                linspace(regStats.tRes(1),regStats.tRes(end),100)),'k-');
            hold(app.RegPlot,"off");

            for k=1:1:length(regStats.beta), app.RegParamT.Data{k,5} = regStats.beta(k); end

            app.RegAnalysisB.Enable = true;
        end

        % Close request function: MAGMAUIFigure
        function MAGMAUIFigureCloseRequest(app, event)
            delete(app.RegSpecsWindow)
            delete(app.DataImportWindow)
            delete(app.RegAnalysisWindow)
            delete(app.ODESolverSpecsWindow)
            delete(app)
            
        end

        % Button pushed function: RegAnalysisB
        function RegAnalysisBPushed(app, event)
            % ### FIXME: need to code the nlparci and nlpredci functions,
            % give user options to customize the analysis
            % create popup window that can give these options to user
            % NRMSE should be done per DV
            app.RegAnalysisWindow = RegAnalysisWindow(app); %#ok<ADPROPLC>
        end

        % Button pushed function: HelperAB
        function HelperABButtonPushed(app, event)
            helperName = "Helper Function "+(length(app.HelperDD.Items)+1);
            app.HelperNameF.Value = helperName;
            app.HelperSymF.Value = "H_F_"+(length(app.HelperDD.Items)+1);
            app.HelperValF.Value = "1";
            app.HelperDD.Items = [app.HelperDD.Items,helperName];
            app.HelperDD.Value = app.HelperDD.Items{end};

            % app.MTHelperNameF.Value = helperName;
            % app.MTHelperSymF.Value = "H_F_"+(length(app.HelperDD.Items));
            % app.MTHelperValF.Value = "1";
            % app.MTHelperDD.Items = [app.HelperDD.Items,helperName];
            % app.MTHelperDD.Value = app.HelperDD.Items{end};

            app.HelperAB.Enable = false;
            app.HelperRB.Enable = false;
            app.HelperDD.Enable = false;
            app.HelperUB.Enable = true;

            % app.MTHelperAB.Enable = false;
            % app.MTHelperRB.Enable = false;
            % app.MTHelperDD.Enable = false;
            % app.MTHelperUB.Enable = true;

            app.HelperSaveL.Text = "Not Saved";
            % app.MTHelperSaveL.Text = "Not Saved";
        end

        % Button pushed function: HelperUB
        function HelperUBButtonPushed(app, event)
            if strcmp(event.Source.Tag,'GF')
                funcName = app.HelperNameF.Value;
                funcSym = app.HelperSymF.Value;
                funcVal = app.HelperValF.Value;
    
                nameEnabled = app.HelperNameF.Enable;
                symEnabled = app.HelperSymF.Enable;
                valEnabled = app.HelperValF.Enable;
            elseif strcmp(event.Source.Tag,'MT')
                funcName = app.MTHelperNameF.Value;
                funcSym = app.MTHelperSymF.Value;
                funcVal = app.MTHelperValF.Value;

                nameEnabled = app.MTHelperNameF.Enable;
                symEnabled = app.MTHelperSymF.Enable;
                valEnabled = app.MTHelperValF.Enable;
            end

            if nameEnabled && symEnabled && valEnabled
                app.system.addRmHelperFuncs(funcName,funcSym,funcVal,true);
            elseif nameEnabled && ~symEnabled && ~valEnabled
                app.system.updateHelperFuncs("name",funcName,funcSym,funcVal);
            elseif ~nameEnabled && symEnabled && ~valEnabled
                app.system.updateHelperFuncs("sym",funcName,funcSym,funcVal);
            elseif ~nameEnabled && ~symEnabled && valEnabled
                app.system.updateHelperFuncs("val",funcName,funcSym,funcVal);
            end

            app.HelperAB.Enable = true;
            app.HelperRB.Enable = true;
            app.HelperDD.Enable = true;
            app.HelperUB.Enable = false;
            app.HelperNameF.Enable = true;
            app.HelperSymF.Enable = true;
            app.HelperValF.Enable = true;

            % app.MTHelperAB.Enable = true;
            % app.MTHelperRB.Enable = true;
            % app.MTHelperDD.Enable = true;
            % app.MTHelperUB.Enable = false;
            % app.MTHelperNameF.Enable = true;
            % app.MTHelperSymF.Enable = true;
            % app.MTHelperValF.Enable = true;

            app.HelperSaveL.Text = "Saved";
            % app.MTHelperSaveL.Text = "Saved";

            % add helperFunc to sysVar table
            varNames = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames;
            % app.MTSysVarT.Data = varNames;

            % ### UPDATE/DEMO: separate compilation of parameters from
            % compilation of everything else
            app.system.compileModel();
        end

        % Button pushed function: HelperRB
        function HelperRBButtonPushed(app, event)
            if strcmp(event.Source.Tag,'GF')
                funcName = app.HelperNameF.Value;
                funcSym = app.HelperSymF.Value;
                funcVal = app.HelperValF.Value;
            elseif strcmp(event.Source.Tag,'MT')
                funcName = app.MTHelperNameF.Value;
                funcSym = app.MTHelperSymF.Value;
                funcVal = app.MTHelperValF.Value;
            end
            app.system.addRmHelperFuncs(funcName,funcSym,funcVal,false);

            app.HelperDDValueChanged();

            % ### UPDATE/DEMO: separate compilation of parameters from
            % compilation of everything else
            app.system.compileModel();
        end

        % Value changed function: HelperDD
        function HelperDDValueChanged(app, event)
            if strcmp(event.Source.Tag,'GF')
                funcName = app.HelperDD.Value;
            elseif strcmp(event.Source.Tag,'MT')
                funcName = app.MTHelperDD.Value;
            end
            if isempty(funcName)
                funcName = app.HelperDD.Items{1};
            end
            app.HelperNameF.Value = funcName;
            % app.MTHelperNameF.Value = funcName;
            [funcSym,funcVal] = app.system.getHelperFunc(funcName);
            app.HelperSymF.Value = funcSym;
            app.HelperValF.Value = funcVal;
            % app.MTHelperSymF.Value = funcSym;
            % app.MTHelperValF.Value = funcVal;

            app.HelperValFValueChanging();
        end

        % Value changing function: HelperValF
        function HelperValFValueChanging(app, event)
            funcVal = string(event.Value);

            if strcmp(event.Source.Tag,'GF')
                funcSym = string(app.HelperSymF.Value);
            elseif strcmp(event.Source.Tag,'MT')
                funcSym = string(app.MTHelperSymF.Value);
            end

            if app.HelperSaveL.Text == "Saved"
                % disables relevant editors
                app.HelperAB.Enable = false;
                app.HelperDD.Enable = false;
                app.HelperRB.Enable = false;
                app.HelperAB.Enable = false;
                app.HelperNameF.Enable = false;
                app.HelperSymF.Enable = false;

                % app.MTHelperAB.Enable = false;
                % app.MTHelperDD.Enable = false;
                % app.MTHelperRB.Enable = false;
                % app.MTHelperAB.Enable = false;
                % app.MTHelperNameF.Enable = false;
                % app.MTHelperSymF.Enable = false;
    
                % enable ModelUB
                app.HelperUB.Enable = true;
                % app.MTHelperUB.Enable = true;
            end

            try
                funcVal = uni2latex(char(funcVal));
                app.HelperValF.BackgroundColor = [1,1,1];
                app.HelperUB.Enable = true;
                % app.MTHelperValF.BackgroundColor = [1,1,1];
                % app.MTHelperUB.Enable = true;
            catch
                % turns HelperSymF color red
                app.HelperValF.BackgroundColor = [1,0.7,0.7];
                app.HelperUB.Enable = false;
                % app.MTHelperValF.BackgroundColor = [1,0.7,0.7];
                % app.MTHelperUB.Enable = false;
                disp("err")
                return;
            end

            % set helper func saved status to "Not Saved"
            app.HelperSaveL.Text = "Not Saved";
            % app.MTHelperSaveL.Text = "Not Saved";

            funcStr = "$" + funcSym + "=" + funcVal + "$";
            
            app.HelperFuncL.Text = funcStr;
            % app.MTHelperFuncL.Text = funcStr;
        end

        % Value changing function: HelperNameF
        function HelperNameFValueChanging(app, event)
            funcName = string(event.Value);
            
            if app.HelperSaveL.Text == "Saved"
                % disables relevant editors
                app.HelperAB.Enable = false;
                app.HelperDD.Enable = false;
                app.HelperRB.Enable = false;
                app.HelperAB.Enable = false;
                app.HelperSymF.Enable = false;
                app.HelperValF.Enable = false;

                % app.MTHelperAB.Enable = false;
                % app.MTHelperDD.Enable = false;
                % app.MTHelperRB.Enable = false;
                % app.MTHelperAB.Enable = false;
                % app.MTHelperSymF.Enable = false;
                % app.MTHelperValF.Enable = false;
    
                % enable ModelUB
                app.HelperUB.Enable = true;
                % app.MTHelperUB.Enable = true;
            end

            try
                funcName = uni2latex(char(funcName));
                app.HelperNameF.BackgroundColor = [1,1,1];
                app.HelperUB.Enable = true;
                % app.MTHelperNameF.BackgroundColor = [1,1,1];
                % app.MTHelperUB.Enable = true;
            catch
                % turns GovFuncVF color red
                app.HelperNameF.BackgroundColor = [1,0.7,0.7];
                % makes ModelUB inactive
                app.HelperUB.Enable = false;
                % app.MTHelperUB.Enable = false;
                disp("err")
                return;
            end

            % update the name of the function in SubFuncDD
            items = convertCharsToStrings(app.HelperDD.Items);
            items(items == app.HelperDD.Value) = funcName;
            app.HelperDD.Items = items;
            app.HelperDD.Value = funcName;
            % app.MTHelperDD.Items = items;
            % app.MTHelperDD.Value = funcName;

            % set helper func saved status to "Not Saved"
            app.HelperSaveL.Text = "Not Saved";
            % app.MTHelperSaveL.Text = "Not Saved";
        end

        % Value changing function: HelperSymF
        function HelperSymFValueChanging(app, event)
            funcSym = string(event.Value);
            funcVal = string(app.HelperValF.Value);

            if app.HelperSaveL.Text == "Saved"
                % disables relevant editors
                app.HelperAB.Enable = false;
                app.HelperDD.Enable = false;
                app.HelperRB.Enable = false;
                app.HelperAB.Enable = false;
                app.HelperNameF.Enable = false;
                app.HelperValF.Enable = false;
            
                % app.MTHelperAB.Enable = false;
                % app.MTHelperDD.Enable = false;
                % app.MTHelperRB.Enable = false;
                % app.MTHelperAB.Enable = false;
                % app.MTHelperNameF.Enable = false;
                % app.MTHelperValF.Enable = false;
    
                % enable ModelUB
                app.HelperUB.Enable = true;
                % app.MTHelperUB.Enable = true;
            end

            try
                funcSym = uni2latex(char(funcSym));
                app.HelperSymF.BackgroundColor = [1,1,1];
                app.HelperUB.Enable = true;
                % app.MTHelperSymF.BackgroundColor = [1,1,1];
                % app.MTHelperUB.Enable = true;
            catch
                % turns HelperSymF color red
                app.HelperSymF.BackgroundColor = [1,0.7,0.7];
                app.HelperUB.Enable = false;
                % app.MTHelperSymF.BackgroundColor = [1,0.7,0.7];
                % app.MTHelperUB.Enable = false;
                disp("err")
                return;
            end

            % set helper func saved status to "Not Saved"
            app.HelperSaveL.Text = "Not Saved";
            % app.MTHelperSaveL.Text = "Not Saved";

            funcStr = "$" + funcSym + "=" + uni2latex(char(funcVal)) + "$";
            
            app.HelperFuncL.Text = funcStr;
            % app.MTHelperFuncL.Text = funcStr;
        end

        % Button pushed function: SaveRegParamB
        function SaveRegParamBButtonPushed(app, event)
            paramSym = app.SaveRegParamDD.Value;
            RegParamT_Idx = strcmp(app.RegParamT.Data(:,1),paramSym);
            compName = app.RegParamT.Data{RegParamT_Idx,2};
            newParamVal = app.RegParamT.Data{:,5};

            compNames = [app.system.getCompNamesByType("Biological Solute",true),app.system.getCompNamesByType("Chemical Solute",true)];
            envFuncNames = app.system.getEnvFuncNames();
            helperFuncNames = app.system.getHelperFuncNames();
            if any(strcmp(compNames,compName))
                params = app.system.getGrthParamsByFuncName(compName);
            elseif any(strcmp(envFuncNames(:,1),compName))
                params = app.system.getGrthParamsByEnvFuncName(compName);
            elseif any(strcmp(helperFuncNames(:,1),compName))
                params = app.system.getGrthParamsByHelperFuncName(compName);
            else
                return;
            end

            param_idx = strcmp(params(:,3),paramSym);
            newParamUnit = params{param_idx,6};
            newParamName = params{param_idx,4};
            funcName = params{param_idx,2};

            if any(strcmp(compNames,funcName))
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,false);
                params = app.system.getGrthParamsByFuncName(funcName);
            elseif any(strcmp(envFuncNames(:,1),funcName))
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,false);
                params = app.system.getGrthParamsByEnvFuncName(funcName);
            elseif any(strcmp(helperFuncNames(:,1),funcName))
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,false);
                params = app.system.getGrthParamsByHelperFuncName(funcName);
            else
                return;
            end

            % updating the data in RegParamT
            app.RegParamT.Data{RegParamT_Idx,4} = app.RegParamT.Data{RegParamT_Idx,5};

            % updating the data in GrthParamT
            app.ParamT.Data = params;
        end

        % Button pushed function: SaveAllRegParamB
        function SaveAllRegParamBButtonPushed(app, event)
            paramSym = string(app.SaveRegParamDD.Items);
            newParamVal = [app.RegParamT.Data{:,5}];
            for k=1:1:length(paramSym)
                compName = app.RegParamT.Data{k,2};
    
                compNames = [app.system.getCompNamesByType("Biological Solute",true),app.system.getCompNamesByType("Chemical Solute",true)];
                envFuncNames = app.system.getEnvFuncNames();
                helperFuncNames = app.system.getHelperFuncNames();
                if any(strcmp(compNames,compName))
                    params = app.system.getGrthParamsByFuncName(compName);
                elseif any(strcmp(envFuncNames(:,1),compName))
                    params = app.system.getGrthParamsByEnvFuncName(compName);
                elseif any(strcmp(helperFuncNames(:,1),compName))
                    params = app.system.getGrthParamsByHelperFuncName(compName);
                else
                    return;
                end

                param_idx = strcmp(convertCharsToStrings(params(:,3)),paramSym(k));
                newParamUnit = params{param_idx,6};
                newParamName = params{param_idx,4};
                % funcName = params{param_idx,2};
    
                if any(strcmp(compNames,compName))
                    app.system.updateMultiParams(paramSym(k),newParamVal(k),newParamUnit,newParamName,false);
                    params = app.system.getGrthParamsByFuncName(compName);
                elseif any(strcmp(envFuncNames(:,1),compName))
                    app.system.updateMultiParams(paramSym(k),newParamVal(k),newParamUnit,newParamName,false);
                    params = app.system.getGrthParamsByEnvFuncName(compName);
                elseif any(strcmp(helperFuncNames(:,1),compName))
                    app.system.updateMultiParams(paramSym(k),newParamVal(k),newParamUnit,newParamName,false);
                    params = app.system.getGrthParamsByHelperFuncName(compName);
                else
                    break;
                end
    
                % updating the data in RegParamT
                app.RegParamT.Data{k,4} = app.RegParamT.Data{k,5};
            end

            % updating the data in GrthParamT
            app.ParamT.Data = params;
        end

        % Value changed function: VolCB
        function VolCBValueChanged(app, event)
            val = app.VolCB.Value;
            
            if val
                app.HConstVF.Enable = true;
                app.HConstUDD.Enable = true;
                app.HConst2L.Enable = true;
                app.HConst2VF.Enable = true;
                app.HConst2UDD.Enable = true;
                app.HConstL.Enable = true;
            else
                app.HConstVF.Enable = false;
                app.HConstUDD.Enable = false;
                app.HConst2L.Enable = false;
                app.HConst2VF.Enable = false;
                app.HConst2UDD.Enable = false;
                app.HConstL.Enable = false;
            end
        end

        % Value changed function: SorptionModelDD
        function SorptionModelDDValueChanged(app, event)
            modelName = app.SorptionModelDD.Value;
            solventName = app.ChemNameDD.Value;
            soluteName = app.CompSoluteDD.Value;
            soluteNum = app.system.getChemicalNumFromName(soluteName);
            solventNum = app.system.getChemicalNumFromName(solventName);
            if solventNum == 0
                disp("chemical not added yet")
                solventNum = length(app.system.chemicals) + 1;
            end

            switch modelName
                case 'Partition Coefficient'
                    app.Param1L.Text = string(['$K_{C_{',char(string(solventNum)),'_{',char(string(soluteNum)),'}}}=$']);
                    app.Param2L.Text = '';
                    app.Param3L.Text = '';
                    
                case 'Freundlich Adsorption'
                    app.Param1L.Text = ['$K_{C_{',char(string(solventNum)),'_{',char(string(soluteNum)),'}}}=$'];
                    app.Param2L.Text = ['$n_{',char(string(solventNum)),'_{',char(string(soluteNum)),'}}=$'];
                    app.Param3L.Text = '';

            end
        end

        % Value changed function: MTCompDD
        function MTCompDDValueChanged(app, event)
            compName = app.MTCompDD.Value;

            if isempty(compName), return; end

            % check which phases the component is in
            % is_vol
            % sorpFuncParams is empty?
            items = {'Liquid'};
            comp = app.system.getCompByName(compName);
            if comp.is_vol
                items{end+1} = 'Gas';
            end
            if ~isempty(comp.sorpFuncParams)
                for k=1:1:length(comp.sorpFuncParams)
                    items{end+1} = comp.sorpFuncParams{k}.solventName; %#ok<AGROW>
                end
            end
            
            % fill in PhaseADD and PhaseBDD
            app.MTPhaseADD.Items = items;
            app.MTPhaseADD.Value = items{1};
            app.MTPhaseBDD.Items = items;
            app.MTPhaseBDD.Value = items{2};
 
            % update MTGovFuncL, MTFuncVF, and MTPiecewiseT by calling
            % MTPhaseDDValueChanged
            app.MTPhaseDDValueChanged(struct('Source',struct('Tag','A')));
        end

        % Value changed function: CompSoluteDD
        function CompSoluteDDValueChanged(app, event)
            value = app.CompSoluteDD.Value;
            
        end

        % Value changed function: StrmTypeDD
        function StrmTypeDDValueChanged(app, event)
            strmType = app.StrmTypeDD.Value;

            switch string(strmType)
                case 'Liquid Flow In'
                    dir = 'in';
                    phase = 'L';

                    app.MTStrmCompT.Enable = true;
                    app.StrmCompBasisDD.Enable = true;
                    app.StrmCompBasisUDD.Enable = true;
                    app.MTSolvNDD.Enable = true;

                    app.MTStrmFlowUDD.Items = {'L/s','m^3/s','mL/s'};
                    app.MTStrmCompBasisUDD.Items = {'g/L','kg/L','g/m^3'};
                    app.MTStrmCompBasisDD.Value = 'Concentration';
                case 'Gas Flow In'
                    dir = 'in';
                    phase = 'G';

                    app.MTStrmCompT.Enable = true;
                    app.StrmCompBasisDD.Enable = true;
                    app.StrmCompBasisUDD.Enable = true;
                    app.MTSolvNDD.Enable = false;

                    app.MTStrmFlowUDD.Items = {'kPa/s','MPa/s','Pa/s'};
                    app.MTStrmCompBasisUDD.Items = {''};
                   app.MTStrmCompBasisDD.Value = 'Mass Fraction';
                case 'Liquid Flow Out'
                    dir = 'out';
                    phase = 'L';

                    app.MTStrmCompT.Enable = false;
                    app.StrmCompBasisDD.Enable = false;
                    app.StrmCompBasisUDD.Enable = false;
                    app.MTSolvNDD.Enable = false;

                    app.MTStrmFlowUDD.Items = {'L/s','m^3/s','mL/s'};
                    app.MTStrmCompBasisUDD.Items = {'g/L','kg/L','g/m^3'};
                    app.MTStrmCompBasisDD.Value = 'Concentration';
                case 'Gas Flow Out'
                    dir = 'out';
                    phase = 'G';

                    app.MTStrmCompT.Enable = false;
                    app.StrmCompBasisDD.Enable = false;
                    app.StrmCompBasisUDD.Enable = false;
                    app.MTSolvNDD.Enable = false;

                    app.MTStrmFlowUDD.Items = {'kPa/s','MPa/s','Pa/s'};
                    app.MTStrmCompBasisUDD.Items = {''};
                    app.MTStrmCompBasisDD.Value = 'Mass Fraction';
            end

            if strcmp(phase,'G')
                app.MTSolvNDD.Enable = false;
            else
                app.MTSolvNDD.Enable = true;
            end

            strms = app.system.getStrms(dir,phase);
            
            strmNames = cell(size(strms));
            for k=1:1:length(strmNames), strmNames{k} = strms{k}.name; end
            app.StrmNDD.Items = strmNames;
            app.StrmNDDValueChanged();
        end

        % Value changed function: StrmNDD
        function StrmNDDValueChanged(app, event)
            strmName = app.StrmNDD.Value;

            strmNames = app.StrmT.Data(:,1);
            
            if any(strcmp(strmNames,strmName))
                strm = app.system.getStrmByName(strmName);

                if strcmp(strm.getDir(),"in")
                    if strcmp(strm.getPhase(),"L")
                        strmType = 'Liquid Flow In';
                    elseif strcmp(strm.getPhase(),"G")
                        strmType = 'Gas Flow In';
                    end
                elseif strcmp(strm.getDir(),"out")
                    if strcmp(strm.getPhase(),"L")
                        strmType = 'Liquid Flow Out';
                    elseif strcmp(strm.getPhase(),"G")
                        strmType = 'Gas Flow Out';
                    end
                end
                app.StrmTypeDD.Value = strmType;

                strmCompsData = strm.getCompsData();
                if isempty(strmCompsData)
                    app.MTStrmCompT.Data = [];
                else
                    app.MTStrmCompT.Data = strmCompsData;
                end
            else
                return;
            end
        end

        % Button pushed function: StrmAB
        function StrmABButtonPushed(app, event)
            strmName = app.StrmNDD.Value;
            strmType = app.StrmTypeDD.Value;

            switch strmType
                case 'Liquid Flow In'
                    dir = 'in';
                    phase = 'L';
                case 'Gas Flow In'
                    dir = 'in';
                    phase = 'G';
                case 'Liquid Flow Out'
                    dir = 'out';
                    phase = 'L';
                case 'Gas Flow Out'
                    dir = 'out';
                    phase = 'G';
            end

            if isempty(app.StrmT.Data), strmNames = []; else, strmNames = app.StrmT.Data(:,1); end
            if any(strcmp(strmNames,strmName))
                app.system.updateStrm(strmName,dir,phase,[],[],[]);
            else
                app.system.addStrm(strmName,dir,phase);
            end

            % should only be called after 1st addition of stream of each
            % type
            app.system.updateIOFunc();

            strms = app.system.getStrms(dir,phase);
            strmNames = cell(size(strms));
            for k=1:1:length(strmNames), strmNames{k} = strms{k}.getName(); end
            app.StrmNDD.Items = strmNames;

            app.StrmT.Data = app.system.getStrmTData();

            app.StrmNDDValueChanged();

            app.system.compileModel();
        end

        % Button pushed function: StrmRB
        function StrmRBButtonPushed(app, event)
            strmName = app.StrmNDD.Value;
            strmType = app.StrmTypeDD.Value;

            switch strmType
                case 'Liquid Flow In'
                    dir = 'in';
                    phase = 'L';
                case 'Gas Flow In'
                    dir = 'in';
                    phase = 'G';
                case 'Liquid Flow Out'
                    dir = 'out';
                    phase = 'L';
                case 'Gas Flow Out'
                    dir = 'out';
                    phase = 'G';
            end

            app.system.removeStrm(strmName,dir,phase);

            % should only be called after 1st addition of stream of each
            % type
            app.system.updateIOFunc(strm_types);

            app.StrmNDDValueChanged();

            app.system.compileModel();
        end

        % Value changed function: MTPhaseADD, MTPhaseBDD
        function MTPhaseDDValueChanged(app, event)
            % ### DEBUG: query the MTFuncName from ODESys on change of
            % phase to reduce confusion            
            phaseA = app.MTPhaseADD.Value;
            phaseB = app.MTPhaseBDD.Value;
            compName = app.MTCompDD.Value;

            tag = event.Source.Tag;

            % update the items for PhaseADD and PhaseBDD
            if strcmp(phaseA,phaseB)
                if strcmp(tag,'A')
                    % fill in PhaseADD and PhaseBDD
                    app.MTPhaseBDD.Value = app.currPhaseA;
                elseif strcmp(tag,'B')
                    app.MTPhaseADD.Value = app.currPhaseB;
                end
            end

            app.currPhaseA = app.MTPhaseADD.Value;
            app.currPhaseB = app.MTPhaseBDD.Value;

            currModel = app.system.getCurrMTFuncType(compName,app.currPhaseA,app.currPhaseB);
            app.MTFuncDD.Value = currModel;

            % set governing func saved status to "Not Saved"
            app.MTSaveL.Text = "Not Saved";

            % disabling MTCompDD
            app.MTCompDD.Enable = false;
            app.MTModelUB.Enable = true;

            % get current MT gov func
            currGovFuncRaw = app.system.getMTGovFunc(compName,app.currPhaseA,app.currPhaseB);
            compSym = app.system.getCompSymByName([compName,' (',app.currPhaseA,' Phase)']);
            funcStr = "$\frac{d"+compSym+"}{dt}="+uni2latex(char(currGovFuncRaw))+"$";
            app.MTGovFuncL.Text = funcStr;
        end

        % Button pushed function: MTModelUB
        function MTModelUBButtonPushed(app, event)
            funcName = app.MTFuncDD.Value;
            compName = app.MTCompDD.Value;
            phaseA = app.MTPhaseADD.Value;
            phaseB = app.MTPhaseBDD.Value;

            funcVal = app.system.getPresetMTFuncByName(funcName,compName,phaseA,phaseB);

            app.system.setMTModel(compName,funcVal,phaseA,phaseB,funcName);

            app.MTSaveL.Text = "Saved";
            app.MTModelUB.Enable = false;
            % enabling MTCompDD
            app.MTCompDD.Enable = true;

            % getting growth parameter data from Species
            params = app.system.getGrthParamsByFuncName(compName);
            % updating the data in GrthParamT
            app.ParamT.Data = params;

            % updating the Param editors with CompParamDD callback
            app.CompParamDDValueChanged();
            % getting growth parameter data for regression
            app.RegCompParamDDValueChanged();

            % updates MSumL text
            app.updateSumL();

            % ### UPDATE/DEMO: separate compilation of parameters from
            % compilation of everything else
            app.system.compileModel();
        end

        % Value changing function: GovFuncVF
        function GovFuncVFValueChanging(app, event)
            funcVal = event.Value;
            
            if app.SaveL.Text == "Saved"
                % disables relevant editors
                app.PiecewiseLimsP.Enable = false;
    
                % enable ModelUB
                app.ModelUB.Enable = true;
            end

            try
                newFunc = uni2latex(char(funcVal));
                if isempty(newFunc)
                    % turns GovFuncVF color red
                    app.GovFuncVF.BackgroundColor = [1,0.7,0.7];
                    % makes ModelUB inactive
                    app.ModelUB.Enable = false;
                    disp("err")
                    return;
                end
                app.GovFuncVF.BackgroundColor = [1,1,1];
                app.ModelUB.Enable = true;
            catch
                % turns GovFuncVF color red
                app.GovFuncVF.BackgroundColor = [1,0.7,0.7];
                % makes ModelUB inactive
                app.ModelUB.Enable = false;
                disp("err")
                return;
            end
            
            compName = app.InteractCompDD.Value;
            compSym = app.system.getCompSymByName(compName);

            % set governing func saved status to "Not Saved"
            app.SaveL.Text = "Not Saved";

            funcStr = "$\frac{d"+compSym+"}{dt}="+newFunc;

            app.GovFuncL.Text = funcStr;            
        end

        % Button pushed function: FuncNewAB
        function FuncNewABButtonPushed(app, event)
            compName = app.InteractCompDD.Value;
            
            currFuncVal = app.GovFuncVF.Value;
            newExpressionName = app.FuncTypeDD.Value;

            % ### FIXME
            % gets new expression value from defaults
            newFunc = app.system.getDefaultFuncVal(compName,newExpressionName);

            % set governing func saved status to "Not Saved"
            app.SaveL.Text = "Not Saved";
            
            % add expression to GovFuncVF
            app.GovFuncVF.Value = [currFuncVal,newFunc];

            % disables relevant editors
            app.PiecewiseLimsP.Enable = false;

            % enable ModelUB
            app.ModelUB.Enable = true;
        end

        % Button pushed function: PiecewiseAB
        function PiecewiseABButtonPushed(app, event)
            % check if the value is new or existing
            compName = app.InteractCompDD.Value;
            newExpr = app.PiecewiseDD.Value;
            lowLim = app.LowLimVF.Value;
            upLim = app.UpLimVF.Value;
            sysVar = app.PiecewiseVarDD.Value;
            elseVal = app.ElseValDD.Value;
            lowLimOp = app.LowLimOpDD.Value;
            upLimOp = app.UpLimOpDD.Value;
            if contains(app.PiecewiseDD.Items,newExpr)
                pwTInfo = app.system.editNewPWExpr(compName,newExpr,lowLim,upLim,sysVar,elseVal,lowLimOp,upLimOp,'Main','');
            else
                app.PiecewiseDD.Items = [app.PiecewiseDD.Items,{newExpr}];
                pwTInfo = app.system.addNewPWExpr(compName,newExpr,lowLim,upLim,sysVar,elseVal,lowLimOp,upLimOp,'Main','');
            end

            % display the piecewise function info in the table
            app.GovFuncPiecewiseT.Data = pwTInfo;

            % edit the displayed govfunc to give piecewise functions
            compSym = app.system.getCompSymByName(compName);
            existFunc = app.system.getGovFuncByCompName(compName);
            app.GovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFunc+"$";
        end

        % Button pushed function: PiecewiseRB
        function PiecewiseRBButtonPushed(app, event)
            % check if the value exists
            % just break out from this function if the expression doesn't
            % exist. give the user a warning or note it in documentation
            compName = app.InteractCompDD.Value;
            newExpr = app.PiecewiseDD.Value;
            lowLim = app.LowLimVF.Value;
            upLim = app.UpLimVF.Value;
            sysVar = app.PiecewiseVarDD.Value;
            elseVal = app.ElseValDD.Value;
            if contains(app.PiecewiseDD.Items,newExpr)
                app.PiecewiseDD.Items = app.PiecewiseDD.Items([app.PiecewiseDD.Items{:}] ~= newExpr);
                pwTInfo = app.system.rmPWExpr(compName,newExpr,lowLim,upLim,sysVar,elseVal,lowLimOp,upLimOp,'Main','');
            else
                disp("piecewise expression doesn't exist, line 2423")
                return;
            end

            % edit the piecewise function info in the table
            app.GovFuncPiecewiseT.Data = pwTInfo;

            % edit the displayed govfunc to give piecewise functions
            compSym = app.system.getCompSymByNamec(compName);
            existFunc = app.system.getGovFuncByCompName(compName);
            app.GovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFunc+"$";
        end

        % Value changed function: PlotTitleF
        function PlotTitleFValueChanged(app, event)
            % ### FIXME: add functionality to update whether selected
            % variable is a set of IC
            % add functionality to update the options for the ranges of max
            % and min

            plotName = app.PlotTitleDD.Value;
            title = app.PlotTitleF.Value;
            if app.NbAxesDD.Value == "2-Axis"
                axesNb = 2;
            else
                axesNb = 3;
                app.AxVarUBButtonPushed(struct('Source',app.YAxVarAB));
            end
            % ### FIXME: add code to disable Z-axis if only 2 axes
            % specified
            axesUI = app.system.updatePlotNameAx(plotName,title,axesNb);
            app.updateAxesUI(axesUI);
            items = convertCharsToStrings(app.PlotTitleDD.Items);
            items(items == app.PlotTitleDD.Value) = title;
            app.PlotTitleDD.Items = ~strcmp(items,"");
            app.PlotTitleDD.Value = title;

            % update IC + Eval Specs
            axesNames = cell(length(axesUI),1);
            for k=1:1:length(axesNames)
                axesNames{k} = axesUI{k}{2};
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};            
        end

        % Value changed function: AxTitleF
        function AxTitleFValueChanged(app, event)
            dir = event.Source.Tag;
            plotName = app.PlotTitleF.Value;
            newTitle = string(app.AxTitleF.Value);
            axesUI = app.system.updateAxTitle(plotName,dir,newTitle);
            axesNames = cell(1,length(axesUI));
            for k=1:1:length(axesNames)
                axesNames{k} = char(axesUI{k}{2});
            end
            app.ICEvalAxisDD.Items = axesNames;
            app.ICEvalAxisDDValueChanged();              
        end

        % Value changed function: NbAxesDD
        function NbAxesDDValueChanged(app, event)
            if app.NbAxesDD.Value == "2-Axis"
                axesNb = 2;
            else
                axesNb = 3;
            end
            plotName = app.PlotTitleDD.Value;
            title = app.PlotTitleF.Value;

            axesUI = app.system.updatePlotNameAx(plotName,title,axesNb);
            app.updateAxesUI(axesUI);

            % update IC + Eval Specs
            axesNames = cell(length(axesUI),1);
            for k=1:1:length(axesNames)
                axesNames{k} = axesUI{k}{2};
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};
            app.ICEvalAxisDDValueChanged();
        end

        % Button pushed function: CreateNewModelB
        function CreateNewModelBButtonPushed(app, event)
            % should re-instantiate the ODESys class
            app.system = ODESys();

            app.reinstantiateODESysUI();
        end

        % Window button down function: MAGMAUIFigure
        function MAGMAUIFigureWindowButtonDown(app, event)
            % set the Home Tab "Saved" label to "Not Saved"
            app.ModelSavedL.Text = "Model NOT Saved!";
        end

        % Button pushed function: ModelSaveB
        function ModelSaveBButtonPushed(app, event)
            app.ModelSavedL.Text = "Model Saved!";

            if strcmp(app.system.save_type,"file")
                try
                    fid = fopen(app.system.save_file_name,'w');
                    fprintf(fid,'%s',jsonencode(app.system));
                    fclose(fid);
                catch err
                    disp(err)
                end
            elseif strcmp(app.system.save_type,"database")

            end
        end

        % Button pushed function: ModelSave2FileB
        function ModelSave2FileBButtonPushed(app, event)
            app.ModelSavedL.Text = "Model Saved!";

            [file,path] = uiputfile();
            try
                app.system.save_file_name = [path,file];
                fid = fopen([path,file],'w');
                fprintf(fid,'%s',jsonencode(app.system));
                fclose(fid);
            catch err
                disp(err)
            end
        end

        % Button pushed function: ModelSave2DBB
        function ModelSave2DBBButtonPushed(app, event)
            app.ModelSavedL.Text = "Model Saved!";
        end

        % Button pushed function: ModelFileLoadB
        function ModelFileLoadBButtonPushed(app, event)
            app.ModelSavedL.Text = "Model Saved!";

            [file,path] = uiputfile();
            try
                app.system = jsondecode(fileread([path,file]));
    
                app.reinstantiateODESysUI();
            catch err
                disp(err)
            end
        end

        % Value changed function: ModelDescHTA
        function ModelDescHTAValueChanged(app, event)
            value = app.ModelDescHTA.Value;
            app.system.save_model_desc = value;
        end

        % Value changed function: ModelNameTA
        function ModelNameTAValueChanged(app, event)
            value = app.ModelNameTA.Value;
            app.system.save_model_name = value;
        end

        % Value changed function: RefHTA
        function RefHTAValueChanged(app, event)
            value = app.RefHTA.Value;
            app.system.save_model_ref_str = value;
        end

        % Button pushed function: SubplotUB
        function SubplotUBButtonPushed(app, event)
            rows = ap.SubplotRVF.Value;
            cols = app.SubplotCVF.Value;

            app.system.subplot_row_ct = rows;
            app.system.subplot_col_ct = cols;

            app.SubplotGroupDDValueChanged();
        end

        % Value changed function: SubplotGroupDD
        function SubplotGroupDDValueChanged(app, event)
            subplotGroup = app.SubplotGroupDD.Value;
            subplotSlot = app.PlotPosDD.Value;
            tot_ct = app.system.subplot_row_ct(subplotGroup)*app.system.subplot_col_ct*(subplotGroup);
            app.PlotPosDD.Items = 1:1:tot_ct;

            plotName = app.PlotTitleDD.Value;
            app.system.updatePlotGroupSlot(plotName,subplotGroup,subplotSlot);
        end

        % Button pushed function: SubplotAB
        function SubplotABPushed(app, event)
            % add new number to SubplotDD
            app.SubplotDD.Items{end+1} = length(app.SubplotDD.Items) + 1;

            % update subplot_ct in ODESys
            app.system.subplot_ct = app.system.subplot_ct + 1;
            app.system.subplot_row_ct(end+1) = 1;
            app.system.subplot_col_ct(end+1) = 1;

            % update subplot UI on Plots Tab
            app.PlotTitleDDValueChanged();
            app.SubplotGroupDDValueChanged();
        end

        % Button pushed function: SubplotRB
        function SubplotRBButtonPushed(app, event)
            % update subplot props in Plot class
            % if the subplot that the Plot was on was deleted, reset all
            % subplot props in that Plot to 0
            app.system.updateSubplotPropsinPlot(length(app.SubplotDD.Items));
            
            % remove last number from SubplotDD
            app.SubplotDD.Items(end) = [];

            % update subplot_ct in ODESys
            app.system.subplot_ct = app.system.subplot_ct - 1;
            app.system.subplot_row_ct(end) = [];
            app.system.subplot_col_ct(end) = [];

            % update subplot UI on Plots Tab
            app.PlotTitleDDValueChanged();
            app.SubplotGroupDDValueChanged();
        end

        % Value changed function: SubplotDD
        function SubplotDDValueChanged(app, event)
            numSubplots = app.SubplotDD.Value;
            
            [app.SubplotRVF.Value,app.SubplotCVF.Value] = app.system.getSubplotRowAndCol(numSubplots);
        end

        % Value changed function: PlotPosDD
        function PlotPosDDValueChanged(app, event)
            plotName = app.PlotTitleDD.Value;
            subplotGroup = app.SubplotGroupDD.Value;
            subplotSlot = app.PlotPosDD.Value;
            app.system.updatePlotGroupSlot(plotName,subplotGroup,subplotSlot);            
        end

        % Button pushed function: DataExportDirB
        function DataExportDirBButtonPushed(app, event)
            [fileName,dirName] = uiputfile;
            app.system.data_export_dir = [dirName,fileName];
            app.PlotDirL.Text = "Directory: "+[dirName,fileName];
        end

        % Button pushed function: DataExportB
        function DataExportBButtonPushed(app, event)
            % run the model and export data
            app.system.runModelAndExportData();

            % loading screen
        end

        % Value changed function: LgtAttnMdlDD, LgtMdlCB1, LgtMdlCB2, 
        % ...and 2 other components
        function LgtAttnMdlDDValueChanged(app, event)
            lgtAttnMdlN = app.LgtAttnMdlDD.Value;
            PBRGeomN = app.ReactorTypeDD.Value;
            lgtBioAttn = app.LgtMdlCB1.Value;
            lgtChemAttn = app.LgtMdlCB2.Value;

            specs = app.system.getSpecies('name');
            chems = app.system.getChemicals('name');

            if ~lgtBioAttn && ~lgtChemAttn
                newLgtMdlLaTeX = "I=I_{0}";
            else
                if isempty(specs) && isempty(chems)
                    newLgtMdlLaTeX = "I=I_{0}";
                else
                    compSyms = "(";
                    if lgtBioAttn
                        for k=1:1:length(specs)
                            if k == 1
                                compSyms = compSyms+"X_"+k;
                            else
                                compSyms = compSyms+"+X_"+k;
                            end
                        end
                    end
                    if lgtChemAttn
                        for k=1:1:length(chems)
                            if strcmp(compSyms,"(")
                                compSyms = compSyms+"C_"+k;
                            else
                                compSyms = compSyms+"+C_"+k;
                            end                
                        end
                    end
                    compSyms = compSyms + ")";
                    
                    % ### FIXME: current model assumes that all light
                    % approaches PBR geometry perpendicular to longest
                    % axis, need to add in features to consider different
                    % angles of approach
                    switch PBRGeomN
                        case "Vertical Continuous Stirred Tank"
                            switch lgtAttnMdlN
                                case "Model 1"
                                    newLgtMdlLaTeX = "I=\frac{\int_0^L \int_0^\sqrt{L^2-y^2} I_0\,exp(-A\,(l+B\,"+compSyms+")) dxdy}{pi.*(L./2).^2}";
                            end

                        case "Horizontal Plug Flow Reactor"

                        case "Flat Panel"
                            switch lgtAttnMdlN
                                case "Beer-Lambert"
                                    newLgtMdlLaTeX = "I = \frac{\int_0^L I_0\,exp(-A\,l\,"+compSyms+") dl}{L}";
                                case "Model 1"
                                    newLgtMdlLaTeX = "I=\frac{\int_0^L I_0\,exp(-A\,(l+B\,"+compSyms+")) dl}{L}";
                
                                case "Model 2"
            
                            end
                        case "Raceway Pond"
        
                        case "Shaking Flask"
        
                        case "Benchtop Bioreactor"
        
                        case "Eutrophic Lake System"

                    end
                end
            end

            app.LgtAttnMdlL.Text = "$" + newLgtMdlLaTeX + "$";
        end

        % Value changed function: MTFuncDD
        function MTFuncDDValueChanged(app, event)
            MTFuncName = app.MTFuncDD.Value;
            compName = app.MTCompDD.Value;
            phaseA = app.MTPhaseADD.Value;
            phaseB = app.MTPhaseBDD.Value;

            MTFunc = app.system.getPresetMTFuncByName(MTFuncName,compName,phaseA,phaseB);
            compSym = app.system.getCompSymByName(compName + " (" + phaseA + " Phase)");

            % set governing func saved status to "Not Saved"
            app.MTSaveL.Text = "Not Saved";

            % disabling MTCompDD
            app.MTCompDD.Enable = false;
            app.MTModelUB.Enable = true;
            
            app.MTGovFuncL.Text = "$\frac{d"+compSym+"}{dt} = " + uni2latex(char(MTFunc)) + "$";
        end

        % Value changed function: AxisDD
        function AxisDDValueChanged(app, event)
            plotName = app.PlotTitleDD.Value;
            title = app.PlotTitleF.Value;
            axesNb = app.NbAxesDD.Value;
            if strcmp(axesNb,'2-Axis'), axesNb = 2; else, axesNb = 3; end
            axesUI = app.system.updatePlotNameAx(plotName,title,axesNb);
            app.updateAxesUI(axesUI);
        end

        % Callback function: MTSolvNDD, MTStrmCompBasisDD, 
        % ...and 4 other components
        function MTStrmCompSpecChanged(app, event)
            strmName = app.StrmNDD.Value;
            solventName = app.MTSolvNDD.Value;

            compsData = app.MTStrmCompT.Data;
            basis = app.MTStrmCompBasisDD.Value;
            basisU = app.MTStrmCompBasisUDD.Value;
            flowrate = app.MTStrmFlowVF.Value;
            flowrateU = app.MTStrmFlowUDD.Value;
            
            strm = app.system.getStrmByName(strmName);
            dir = strm.getDir();
            phase = strm.getPhase();
            
            app.system.updateStrm(strmName,dir,phase,compsData,basis,basisU,solventName,flowrate,flowrateU);

            app.system.compileModel();
        end

        % Value changed function: ReactorTypeDD
        function ReactorTypeDDValueChanged(app, event)
            reactorType = app.ReactorTypeDD.Value;

            switch reactorType

            end
            
            app.LgtAttnMdlDDValueChanged();
        end

        % Callback function
        function EnvironTCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            dataName = app.EnvironT.Data(indices(1),1);
            % switch dataName
            %     case 
            % end
        end

        % Value changed function: ChemTypeDD
        function ChemTypeDDValueChanged(app, event)
            type = app.ChemTypeDD.Value;
            
            switch type
                case 'Chemical Solute'
                    app.VolCB.Value = false;
                    app.VolCB.Enable = true;
                    app.ChemInitConcL.Text = "Initial Concentration: ";
                    app.ChemInitConcUDD.Items = {'g/L','mg/L','ug/L'};

                case 'Suspended Solid Sorbent'
                    app.VolCB.Value = false;
                    app.VolCB.Enable = false;
                    app.ChemInitConcL.Text = "Initial Volume: ";
                    app.ChemInitConcUDD.Items = {'L','mL','m^3'};

            end

            app.VolCBValueChanged();
        end

        % Button pushed function: SorptionModelUB
        function SorptionModelUBButtonPushed(app, event)
            solventName = app.ChemNameDD.Value;
            soluteName = app.CompSoluteDD.Value;
            modelName = app.SorptionModelDD.Value;
            param1Val = app.SorpParam1VF.Value;
            param2Val = app.SorpParam2VF.Value;
            param3Val = app.SorpParam3VF.Value;

            % perform unit conversions on parameters

            % update sorption information in ODESys
            sorpTInfo = app.system.addSorptionEq(solventName,soluteName,modelName,param1Val,param2Val,param3Val);

            % update SorptionT
            app.SorptionT.Data = sorpTInfo;

            % add helperFunc to sysVar table
            varNames = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames;
            app.MTCompDD.Items = app.system.getSoluteChems('name',true);
            app.CompSoluteDD.Items = app.system.getSoluteChems('name',false);
            app.InteractCompDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();
            app.MTCompDDValueChanged();

            app.system.compileModel();
        end

        % Button pushed function: SorptionModelRB
        function SorptionModelRBButtonPushed(app, event)
            solventName = app.ChemNameDD.Value;
            soluteName = app.CompSoluteDD.Value;
            modelName = app.SorptionModelDD.Value;
            param1Val = app.SorpParam1VF.Value;
            param2Val = app.SorpParam2VF.Value;
            param3Val = app.SorpParam3VF.Value;

            % update sorption information in ODESys
            sorpTInfo = app.system.rmSorptionEq(solventName,soluteName,modelName,param1Val,param2Val,param3Val);

            % update SorptionT
            app.SorptionT.Data = sorpTInfo;

            % add helperFunc to sysVar table
            varNames = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames;
            app.MTCompDD.Items = app.system.getSoluteChems('name',true);
            app.CompSoluteDD.Items = app.system.getSoluteChems('name',false);
            app.InteractCompDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();
            app.MTCompDDValueChanged();

            app.system.compileModel();
        end

        % Callback function
        function ModelSolverSettingsButtonPushed(app, event)
            app.ODESolverSpecsWindow = ODESolverSpecsWindow(app,app.system.solver); %#ok<ADPROPLC>
        end

        % Button pushed function: NonNegAB, NonNegRB
        function NonNegSysVarUpdate(app, event)
            tag = event.Source.Tag;
            if strcmp(tag,"add")
                sysVar = app.NonNegSysVarAvailLB.Value;
                app.NonNegSysVarLB.Items = [app.NonNegSysVarLB.Items,{sysVar}];
                app.system.updateNonNegSysVarList(sysVar,true);
            else
                sysVar = app.NonNegSysVarLB.Value;
                app.NonNegSysVarLB.Items = app.NonNegSysVarLB.Items(~strcmp(app.NonNegSysVarLB.Items,sysVar));
                app.system.updateNonNegSysVarList(sysVar,false);
            end
        end

        % Value changed function: SolverDD
        function SolverDDValueChanged(app, event)
            solverName = app.SolverDD.Value;
            if solverName == "auto" || solverName == "stiff" || solverName == "nonstiff"
                app.RelTolVF.Enable = false;
                app.TolXVF.Enable = false;
                app.InitStepSizeVF.Enable = false;
                app.MaxStepSizeVF.Enable = false;
                app.NormCtrlCB.Enable = false;
                app.VectorizationCB.Enable = false;
                app.BDFCB.Enable = false;
                app.MaxOrderDD.Enable = false;
            elseif solverName == "ode23" || solverName == "ode45" || solverName == "ode78" || solverName == "ode89" || solverName == "ode113"
                app.RelTolVF.Enable = true;
                app.TolXVF.Enable = true;
                app.InitStepSizeVF.Enable = true;
                app.MaxStepSizeVF.Enable = true;
                app.NormCtrlCB.Enable = true;
                app.VectorizationCB.Enable = false;
                app.BDFCB.Enable = false;
                app.MaxOrderDD.Enable = false;
            elseif solverName == "ode15s"
                app.RelTolVF.Enable = true;
                app.TolXVF.Enable = true;
                app.InitStepSizeVF.Enable = true;
                app.MaxStepSizeVF.Enable = true;
                app.NormCtrlCB.Enable = true;
                app.VectorizationCB.Enable = true;
                app.BDFCB.Enable = true;
                app.MaxOrderDD.Enable = true;
            elseif solverName == "ode23s" || solverName == "odet" || solverName == "ode23tb"
                app.RelTolVF.Enable = true;
                app.TolXVF.Enable = true;
                app.InitStepSizeVF.Enable = true;
                app.MaxStepSizeVF.Enable = true;
                app.NormCtrlCB.Enable = true;
                app.VectorizationCB.Enable = true;
                app.BDFCB.Enable = false;
                app.MaxOrderDD.Enable = false;
            end
            app.system.updateODESolverName(solverName);
            app.SolverL.Text = ['Current Solver: ',solverName];
        end

        % Value changed function: BDFCB, InitStepSizeVF, MaxOrderDD, 
        % ...and 5 other components
        function SolverUpdate(app, event)
            solverSpecs = struct();
            solverSpecs.RelTol = double(string(app.RelTolVF.Value));
            solverSpecs.TolX = double(string(app.TolXVF.Value));
            solverSpecs.InitialStep = double(string(app.InitStepSizeVF.Value));
            solverSpecs.MaxStep = double(string(app.MaxStepSizeVF.Value));
            solverSpecs.NormControl = app.NormCtrlCB.Value;
            solverSpecs.Vectorized = app.VectorizationCB.Value;
            solverSpecs.BDF = app.BDFCB.Value;
            solverSpecs.MaxOrder = double(string(app.MaxOrderDD.Value));
            app.system.updateODESolverSpecs(solverSpecs);
        end

        % Button pushed function: ConsoleB
        function ConsoleBPushed(app, event)
            if app.Console.Enable
                app.ConsoleB.Text = "Show Console";
                app.Console.Enable = false;
                app.Console.Visible = false;
            else
                app.ConsoleB.Text = "Hide Console";
                app.Console.Enable = true;
                app.Console.Visible = true;
            end
        end

        % Button pushed function: ClearConsoleButton
        function ClearConsoleButtonPushed(app, event)
            app.ConsoleL.Text = '';
            app.ConsoleL.Position = [app.ConsoleL.Position(1),app.ConsoleL.Position(2),app.ConsoleL.Position(3),520];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MAGMAUIFigure = uifigure('Visible', 'off');
            app.MAGMAUIFigure.Name = 'MAGMA';
            app.MAGMAUIFigure.CloseRequestFcn = createCallbackFcn(app, @MAGMAUIFigureCloseRequest, true);
            app.MAGMAUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @MAGMAUIFigureWindowButtonDown, true);
            app.GridLayout46 = uigridlayout(app.MAGMAUIFigure);
            app.Console = uipanel(app.GridLayout46);
            app.Console.Enable = 'off';
            app.Console.Title = 'MAGMA Console';
            app.Console.Visible = 'off';
            app.ConsoleL = uilabel(app.Console);
            app.ConsoleL.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout46);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.HomeTabGL = uigridlayout(app.HomeTab);
            app.ReferencesLabel = uilabel(app.HomeTabGL);
            app.ReferencesLabel.Text = 'Reference(s): ';
            app.RefHTA = uitextarea(app.HomeTabGL);
            app.RefHTA.ValueChangedFcn = createCallbackFcn(app, @RefHTAValueChanged, true);
            app.ModelDescriptionLabel = uilabel(app.HomeTabGL);
            app.ModelDescriptionLabel.Text = 'Model Description: ';
            app.ModelDescHTA = uitextarea(app.HomeTabGL);
            app.ModelDescHTA.ValueChangedFcn = createCallbackFcn(app, @ModelDescHTAValueChanged, true);
            app.ModelNameLabel = uilabel(app.HomeTabGL);
            app.ModelNameLabel.Text = 'Model Name: ';
            app.ModelNameTA = uitextarea(app.HomeTabGL);
            app.ModelNameTA.ValueChangedFcn = createCallbackFcn(app, @ModelNameTAValueChanged, true);
            app.ModelSave2FileB = uibutton(app.HomeTabGL, 'push');
            app.ModelSave2FileB.ButtonPushedFcn = createCallbackFcn(app, @ModelSave2FileBButtonPushed, true);
            app.ModelSave2FileB.Text = 'Save Model to File';
            app.ModelSave2DBB = uibutton(app.HomeTabGL, 'push');
            app.ModelSave2DBB.ButtonPushedFcn = createCallbackFcn(app, @ModelSave2DBBButtonPushed, true);
            app.ModelSave2DBB.Text = 'Save Model to Database';
            app.CreateNewModelB = uibutton(app.HomeTabGL, 'push');
            app.CreateNewModelB.ButtonPushedFcn = createCallbackFcn(app, @CreateNewModelBButtonPushed, true);
            app.CreateNewModelB.Text = 'Create New Model';
            app.ModelFileLoadB = uibutton(app.HomeTabGL, 'push');
            app.ModelFileLoadB.ButtonPushedFcn = createCallbackFcn(app, @ModelFileLoadBButtonPushed, true);
            app.ModelFileLoadB.Text = 'Load Model From File';
            app.ModelSearchTree = uitree(app.HomeTabGL);
            app.ModelSearchVF = uieditfield(app.HomeTabGL, 'text');
            app.ModelSearchB = uibutton(app.HomeTabGL, 'push');
            app.ModelSearchB.Text = 'Search';
            app.ModelDBLoadB = uibutton(app.HomeTabGL, 'push');
            app.ModelDBLoadB.Text = 'Load Model From Database';
            app.SearchByLabel = uilabel(app.HomeTabGL);
            app.SearchByLabel.Text = 'Search By: ';
            app.ModelSearchByDD = uidropdown(app.HomeTabGL);
            app.ModelSearchByDD.Items = {'Model Name', 'Model Description', 'Biological Solutes', 'Chemical Species', 'References'};
            app.ModelSearchByDD.Value = 'Model Name';
            app.ModelSaveB = uibutton(app.HomeTabGL, 'push');
            app.ModelSaveB.ButtonPushedFcn = createCallbackFcn(app, @ModelSaveBButtonPushed, true);
            app.ModelSaveB.Text = 'Save';
            app.ModelSavedL = uilabel(app.HomeTabGL);
            app.ModelSavedL.Text = 'Model Saved!';
            app.ModelBioChemT = uitable(app.HomeTabGL);
            app.ModelBioChemT.ColumnName = {'Biological Solutes'; 'Chemical Species'};
            app.ModelBioChemT.RowName = {};
            app.SpecTab = uitab(app.TabGroup);
            app.SpecTab.Title = 'Species';
            app.SpecEnvironGrid = uigridlayout(app.SpecTab);
            app.SpeciesNameLabel = uilabel(app.SpecEnvironGrid);
            app.SpeciesNameLabel.Text = 'Biological Species';
            app.SpecNameDD = uidropdown(app.SpecEnvironGrid);
            app.SpecNameDD.Items = {''};
            app.SpecNameDD.Editable = 'on';
            app.SpecNameDD.Value = '';
            app.SpecAB = uibutton(app.SpecEnvironGrid, 'push');
            app.SpecAB.ButtonPushedFcn = createCallbackFcn(app, @SpecABPushed, true);
            app.SpecAB.Text = 'Add/Update';
            app.SpecT = uitable(app.SpecEnvironGrid);
            app.SpecT.ColumnName = {'Name'; 'Symbol'; 'Initial Concentration'; 'Unit'};
            app.SpecT.RowName = {};
            app.SpecT.ColumnSortable = [true false false false];
            app.SpecRB = uibutton(app.SpecEnvironGrid, 'push');
            app.SpecRB.ButtonPushedFcn = createCallbackFcn(app, @SpecRBButtonPushed, true);
            app.SpecRB.Text = 'Remove';
            app.InitialConcentrationEditField_2Label = uilabel(app.SpecEnvironGrid);
            app.InitialConcentrationEditField_2Label.Text = 'Initial Concentration:';
            app.SpecInitConcUnitDD = uidropdown(app.SpecEnvironGrid);
            app.SpecInitConcUnitDD.Items = {'g/L', 'mg/L', 'ug/L'};
            app.SpecInitConcUnitDD.Editable = 'on';
            app.SpecInitConcUnitDD.Value = 'g/L';
            app.ChemAB = uibutton(app.SpecEnvironGrid, 'push');
            app.ChemAB.ButtonPushedFcn = createCallbackFcn(app, @ChemABPushed, true);
            app.ChemAB.Text = 'Add/Update';
            app.ChemicalNameLabel = uilabel(app.SpecEnvironGrid);
            app.ChemicalNameLabel.Text = 'Chemical Species';
            app.ChemNameDD = uidropdown(app.SpecEnvironGrid);
            app.ChemNameDD.Items = {''};
            app.ChemNameDD.Editable = 'on';
            app.ChemNameDD.ValueChangedFcn = createCallbackFcn(app, @ChemNameDDValueChanged, true);
            app.ChemNameDD.Value = '';
            app.ChemInitConcL = uilabel(app.SpecEnvironGrid);
            app.ChemInitConcL.Text = 'Initial Concentration:';
            app.ChemInitConcUDD = uidropdown(app.SpecEnvironGrid);
            app.ChemInitConcUDD.Items = {'g/L', 'mg/L', 'ug/L'};
            app.ChemInitConcUDD.Editable = 'on';
            app.ChemInitConcUDD.Value = 'g/L';
            app.ChemT = uitable(app.SpecEnvironGrid);
            app.ChemT.ColumnName = {'Chemical Name'; 'Symbol'; 'Type'; 'Volatile'; 'Initial State'; 'Unit'; 'MW'; 'H_0'; 'Unit'; 'A'; 'Unit'};
            app.ChemT.RowName = {};
            app.ChemRB = uibutton(app.SpecEnvironGrid, 'push');
            app.ChemRB.ButtonPushedFcn = createCallbackFcn(app, @ChemRBButtonPushed, true);
            app.ChemRB.Text = 'Remove';
            app.VolCB = uicheckbox(app.SpecEnvironGrid);
            app.VolCB.ValueChangedFcn = createCallbackFcn(app, @VolCBValueChanged, true);
            app.VolCB.Text = 'Volatile';
            app.HConstVF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.HConstVF.Enable = 'off';
            app.HConstUDD = uidropdown(app.SpecEnvironGrid);
            app.HConstUDD.Items = {'kPa*m^3/kg', 'kPa*m^3/mol'};
            app.HConstUDD.Enable = 'off';
            app.HConstUDD.Value = 'kPa*m^3/kg';
            app.CompSoluteDD = uidropdown(app.SpecEnvironGrid);
            app.CompSoluteDD.Items = {''};
            app.CompSoluteDD.Editable = 'on';
            app.CompSoluteDD.ValueChangedFcn = createCallbackFcn(app, @CompSoluteDDValueChanged, true);
            app.CompSoluteDD.Enable = 'off';
            app.CompSoluteDD.Value = '';
            app.SorptionT = uitable(app.SpecEnvironGrid);
            app.SorptionT.ColumnName = {'Compatible Solutes'; 'Sorption Model'; 'Parameters'};
            app.SorptionT.RowName = {};
            app.SorptionT.Enable = 'off';
            app.SorptionModelUB = uibutton(app.SpecEnvironGrid, 'push');
            app.SorptionModelUB.ButtonPushedFcn = createCallbackFcn(app, @SorptionModelUBButtonPushed, true);
            app.SorptionModelUB.Enable = 'off';
            app.SorptionModelUB.Text = 'Add/Update';
            app.SorptionModelRB = uibutton(app.SpecEnvironGrid, 'push');
            app.SorptionModelRB.ButtonPushedFcn = createCallbackFcn(app, @SorptionModelRBButtonPushed, true);
            app.SorptionModelRB.Enable = 'off';
            app.SorptionModelRB.Text = 'Remove';
            app.SorptionModelDD = uidropdown(app.SpecEnvironGrid);
            app.SorptionModelDD.Items = {'Partition Coefficient', 'Freundlich Adsorption'};
            app.SorptionModelDD.Editable = 'on';
            app.SorptionModelDD.ValueChangedFcn = createCallbackFcn(app, @SorptionModelDDValueChanged, true);
            app.SorptionModelDD.Enable = 'off';
            app.SorptionModelDD.Value = 'Partition Coefficient';
            app.SorpParam1VF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.SorpParam1VF.Enable = 'off';
            app.SorpParam2VF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.SorpParam2VF.Enable = 'off';
            app.SorpParam3VF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.SorpParam3VF.Enable = 'off';
            app.HConst2UDD = uidropdown(app.SpecEnvironGrid);
            app.HConst2UDD.Items = {'K', 'C', 'F'};
            app.HConst2UDD.Enable = 'off';
            app.HConst2UDD.Value = 'K';
            app.HConst2VF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.HConst2VF.Enable = 'off';
            app.HConst2L = uilabel(app.SpecEnvironGrid);
            app.HConst2L.Enable = 'off';
            app.HConst2L.Interpreter = 'latex';
            app.HConst2L.Text = '$A=$';
            app.HConstHB = uibutton(app.SpecEnvironGrid, 'push');
            app.HConstHB.Text = '?';
            app.HConst2HB = uibutton(app.SpecEnvironGrid, 'push');
            app.HConst2HB.Text = '?';
            app.Param1L = uilabel(app.SpecEnvironGrid);
            app.Param1L.Enable = 'off';
            app.Param1L.Interpreter = 'latex';
            app.Param1L.Text = '$K_C=$';
            app.Param2L = uilabel(app.SpecEnvironGrid);
            app.Param2L.Enable = 'off';
            app.Param2L.Interpreter = 'latex';
            app.Param2L.Text = '';
            app.Param3L = uilabel(app.SpecEnvironGrid);
            app.Param3L.Enable = 'off';
            app.Param3L.Interpreter = 'latex';
            app.Param3L.Text = '';
            app.Button_4 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_4.Text = '?';
            app.Button_5 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_5.Text = '?';
            app.Button_6 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_6.Text = '?';
            app.Button_7 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_7.Text = '?';
            app.Button_8 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_8.Text = '?';
            app.ChemInitConcF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.SpecInitConcF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.HConstL = uilabel(app.SpecEnvironGrid);
            app.HConstL.Enable = 'off';
            app.HConstL.Interpreter = 'latex';
            app.HConstL.Text = '$H_0$';
            app.CompSolutesL = uilabel(app.SpecEnvironGrid);
            app.CompSolutesL.Enable = 'off';
            app.CompSolutesL.Text = 'Compatible Solutes:';
            app.SorpEqML = uilabel(app.SpecEnvironGrid);
            app.SorpEqML.Enable = 'off';
            app.SorpEqML.Text = 'Sorption Equilibrium Model:';
            app.TypeDropDownLabel = uilabel(app.SpecEnvironGrid);
            app.TypeDropDownLabel.Text = 'Type';
            app.ChemTypeDD = uidropdown(app.SpecEnvironGrid);
            app.ChemTypeDD.Items = {'Chemical Solute', 'Suspended Solid Sorbent'};
            app.ChemTypeDD.Editable = 'on';
            app.ChemTypeDD.ValueChangedFcn = createCallbackFcn(app, @ChemTypeDDValueChanged, true);
            app.ChemTypeDD.Value = 'Chemical Solute';
            app.MolecularWeightEditFieldLabel = uilabel(app.SpecEnvironGrid);
            app.MolecularWeightEditFieldLabel.Text = 'Molecular Weight';
            app.MWVF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.Button_9 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_9.Text = '?';
            app.DensityDropDown = uidropdown(app.SpecEnvironGrid);
            app.DensityDropDown.Items = {'kg/L', 'g/L', 'g/cm^3'};
            app.DensityDropDown.Value = 'kg/L';
            app.Button_13 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_13.Text = '?';
            app.DensityEditFieldLabel = uilabel(app.SpecEnvironGrid);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.EnvironmentTab = uitab(app.TabGroup);
            app.EnvironmentTab.Title = 'Environment';
            app.GridLayout12 = uigridlayout(app.EnvironmentTab);
            app.EnvironParamFuncPlot = uiaxes(app.GridLayout12);
            zlabel(app.EnvironParamFuncPlot, 'Z')
            app.EnvironT = uitable(app.GridLayout12);
            app.EnvironT.ColumnName = {'Parameter'; 'Value'; 'Unit'};
            app.EnvironT.RowName = {};
            app.EnvironT.ColumnEditable = true;
            app.EnvironT.Tag = 'GenParamT';
            app.GenParamF = uieditfield(app.GridLayout12, 'text');
            app.GenParamUnitDD = uidropdown(app.GridLayout12);
            app.GenParamUnitDD.Items = {'hr', 'min', 'sec', 'day'};
            app.GenParamUnitDD.Editable = 'on';
            app.GenParamUnitDD.Value = 'hr';
            app.GenParamAB = uibutton(app.GridLayout12, 'push');
            app.GenParamAB.ButtonPushedFcn = createCallbackFcn(app, @GenParamABButtonPushed, true);
            app.GenParamAB.Tag = 'GenParamUB';
            app.GenParamAB.Text = 'Update';
            app.genparamddLabel = uilabel(app.GridLayout12);
            app.genparamddLabel.Text = 'Parameter';
            app.GenParamDD = uidropdown(app.GridLayout12);
            app.GenParamDD.Items = {'Model Runtime (t)', 'Initial Temperature (T_0)', 'Initial Pressure (P_0)', 'Initial Culture Volume (V_0)', 'Initial pH (pH_0)'};
            app.GenParamDD.Editable = 'on';
            app.GenParamDD.ValueChangedFcn = createCallbackFcn(app, @GenParamDDValueChanged, true);
            app.GenParamDD.Value = 'Model Runtime (t)';
            app.EnvironParamFuncL = uilabel(app.GridLayout12);
            app.EnvironParamFuncL.Interpreter = 'latex';
            app.EnvironParamFuncL.Text = '$t =$';
            app.EnvironmentDesignPanel = uipanel(app.GridLayout12);
            app.EnvironmentDesignPanel.Title = 'Environment Design';
            app.GridLayout36 = uigridlayout(app.EnvironmentDesignPanel);
            app.ReactorImg = uiimage(app.GridLayout36);
            app.ReactorImg.ImageSource = fullfile(pathToMLAPP, 'img', 'Flat_Panel.png');
            app.ReactorTypeDropDownLabel = uilabel(app.GridLayout36);
            app.ReactorTypeDropDownLabel.Text = 'Reactor Type';
            app.ReactorTypeDD = uidropdown(app.GridLayout36);
            app.ReactorTypeDD.Items = {'Vertical Continuous Stirred Tank', 'Flat Panel', 'Horizontal Plug Flow Reactor', 'Raceway Pond', 'Shaking Flask', 'Benchtop Bioreactor', 'Eutrophic Lake System'};
            app.ReactorTypeDD.Editable = 'on';
            app.ReactorTypeDD.ValueChangedFcn = createCallbackFcn(app, @ReactorTypeDDValueChanged, true);
            app.ReactorTypeDD.Value = 'Flat Panel';
            app.ReactParam1VF = uieditfield(app.GridLayout36, 'numeric');
            app.ReactParam1VF.Value = 100;
            app.ReactParamUnit1DD = uidropdown(app.GridLayout36);
            app.ReactParamUnit1DD.Items = {'cm', 'm'};
            app.ReactParamUnit1DD.Editable = 'on';
            app.ReactParamUnit1DD.Value = 'cm';
            app.LightAttenuationModelLabel = uilabel(app.GridLayout36);
            app.LightAttenuationModelLabel.Text = 'Light Attenuation Model:';
            app.LgtAttnMdlDD = uidropdown(app.GridLayout36);
            app.LgtAttnMdlDD.Items = {'Beer-Lambert', 'Cornet', 'Hyperbolic', 'Model 1'};
            app.LgtAttnMdlDD.Editable = 'on';
            app.LgtAttnMdlDD.ValueChangedFcn = createCallbackFcn(app, @LgtAttnMdlDDValueChanged, true);
            app.LgtAttnMdlDD.Value = 'Beer-Lambert';
            app.ReactParam2VF = uieditfield(app.GridLayout36, 'numeric');
            app.ReactParam2VF.Value = 100;
            app.ReactParamUnit2DD = uidropdown(app.GridLayout36);
            app.ReactParamUnit2DD.Items = {'cm', 'mm', 'm'};
            app.ReactParamUnit2DD.Editable = 'on';
            app.ReactParamUnit2DD.Value = 'cm';
            app.ReactParamUnit3DD = uidropdown(app.GridLayout36);
            app.ReactParamUnit3DD.Items = {'cm', 'm', 'mm'};
            app.ReactParamUnit3DD.Editable = 'on';
            app.ReactParamUnit3DD.Value = 'cm';
            app.ReactParam3VF = uieditfield(app.GridLayout36, 'numeric');
            app.ReactParam3VF.Value = 5;
            app.ReactParam4VF = uieditfield(app.GridLayout36, 'numeric');
            app.ReactParam4VF.Enable = 'off';
            app.ReactParamUnit4DD = uidropdown(app.GridLayout36);
            app.ReactParamUnit4DD.Items = {''};
            app.ReactParamUnit4DD.Editable = 'on';
            app.ReactParamUnit4DD.Enable = 'off';
            app.ReactParamUnit4DD.Value = '';
            app.HelpBut1 = uibutton(app.GridLayout36, 'push');
            app.HelpBut1.Tag = '1';
            app.HelpBut1.Text = '?';
            app.EnvReactProp4L = uilabel(app.GridLayout36);
            app.EnvReactProp4L.Text = '';
            app.EnvReactProp3L = uilabel(app.GridLayout36);
            app.EnvReactProp3L.Text = 'PBR Depth';
            app.EnvReactProp2L = uilabel(app.GridLayout36);
            app.EnvReactProp2L.Interpreter = 'html';
            app.EnvReactProp2L.Text = 'PBR Width';
            app.LgtAttnMdlL = uilabel(app.GridLayout36);
            app.LgtAttnMdlL.Interpreter = 'latex';
            app.LgtAttnMdlL.Text = '$I=I_0$';
            app.ShadingEffectLabel = uilabel(app.GridLayout36);
            app.ShadingEffectLabel.Text = 'Shading Effect:';
            app.HelpBut2 = uibutton(app.GridLayout36, 'push');
            app.HelpBut2.Tag = '2';
            app.HelpBut2.Text = '?';
            app.HelpBut3 = uibutton(app.GridLayout36, 'push');
            app.HelpBut3.Tag = '3';
            app.HelpBut3.Text = '?';
            app.HelpBut4 = uibutton(app.GridLayout36, 'push');
            app.HelpBut4.Tag = '4';
            app.HelpBut4.Text = '?';
            app.HelpBut5 = uibutton(app.GridLayout36, 'push');
            app.HelpBut5.Tag = '5';
            app.HelpBut5.Text = '?';
            app.HelpBut6 = uibutton(app.GridLayout36, 'push');
            app.HelpBut6.Tag = '6';
            app.HelpBut6.Text = '?';
            app.HelpBut7 = uibutton(app.GridLayout36, 'push');
            app.HelpBut7.Tag = '7';
            app.HelpBut7.Text = '?';
            app.HelpBut8 = uibutton(app.GridLayout36, 'push');
            app.HelpBut8.Tag = '8';
            app.HelpBut8.Text = '?';
            app.HelpBut9 = uibutton(app.GridLayout36, 'push');
            app.HelpBut9.Tag = '9';
            app.HelpBut9.Text = '?';
            app.EnvReactProp1L = uilabel(app.GridLayout36);
            app.EnvReactProp1L.Text = 'PBR Height';
            app.EnvGeomUB = uibutton(app.GridLayout36, 'push');
            app.EnvGeomUB.ButtonPushedFcn = createCallbackFcn(app, @GenParamABButtonPushed, true);
            app.EnvGeomUB.Tag = 'GenParamUB';
            app.EnvGeomUB.Text = 'Update';
            app.DropDown_7 = uidropdown(app.GridLayout36);
            app.DropDown_7.Items = {''};
            app.DropDown_7.Editable = 'on';
            app.DropDown_7.Enable = 'off';
            app.DropDown_7.Value = '';
            app.EditField_9 = uieditfield(app.GridLayout36, 'numeric');
            app.EditField_9.Enable = 'off';
            app.IncidentLightFuncVF = uieditfield(app.GridLayout36, 'text');
            app.IncidentLightFuncVF.Placeholder = '100';
            app.IncidentLightFuncVF.Value = '100';
            app.IncidentLightFuncL = uilabel(app.GridLayout36);
            app.IncidentLightFuncL.Interpreter = 'latex';
            app.IncidentLightFuncL.Text = '$I_0(t)=$';
            app.Button_3 = uibutton(app.GridLayout36, 'push');
            app.Button_3.Text = '?';
            app.LgtMdlCB1 = uicheckbox(app.GridLayout36);
            app.LgtMdlCB1.ValueChangedFcn = createCallbackFcn(app, @LgtAttnMdlDDValueChanged, true);
            app.LgtMdlCB1.Text = 'Biological Components';
            app.LgtMdlCB2 = uicheckbox(app.GridLayout36);
            app.LgtMdlCB2.ValueChangedFcn = createCallbackFcn(app, @LgtAttnMdlDDValueChanged, true);
            app.LgtMdlCB2.Text = 'Chemical Components (Liquid Phase)';
            app.LgtMdlCB4 = uicheckbox(app.GridLayout36);
            app.LgtMdlCB4.ValueChangedFcn = createCallbackFcn(app, @LgtAttnMdlDDValueChanged, true);
            app.LgtMdlCB4.Enable = 'off';
            app.LgtMdlCB4.Visible = 'off';
            app.LgtMdlCB4.Text = 'Liquid Medium';
            app.LgtMdlCB3 = uicheckbox(app.GridLayout36);
            app.LgtMdlCB3.ValueChangedFcn = createCallbackFcn(app, @LgtAttnMdlDDValueChanged, true);
            app.LgtMdlCB3.Enable = 'off';
            app.LgtMdlCB3.Visible = 'off';
            app.LgtMdlCB3.Text = 'Chemical Components (Gas Phase)';
            app.EnvReactProp5L = uilabel(app.GridLayout36);
            app.EnvReactProp5L.Text = '';
            app.MassTransferTab = uitab(app.TabGroup);
            app.MassTransferTab.Title = 'Mass Transfer';
            app.GridLayout27 = uigridlayout(app.MassTransferTab);
            app.InputandOutputStreamsPanel = uipanel(app.GridLayout27);
            app.InputandOutputStreamsPanel.Title = 'Input and Output Streams';
            app.GridLayout34 = uigridlayout(app.InputandOutputStreamsPanel);
            app.StrmT = uitable(app.GridLayout34);
            app.StrmT.ColumnName = {'Stream'; 'Symbol'; 'Phase'; 'Direction'};
            app.StrmT.RowName = {};
            app.StrmTypeDD = uidropdown(app.GridLayout34);
            app.StrmTypeDD.Items = {'Liquid Flow In', 'Liquid Flow Out', 'Gas Flow In', 'Gas Flow Out'};
            app.StrmTypeDD.Editable = 'on';
            app.StrmTypeDD.ValueChangedFcn = createCallbackFcn(app, @StrmTypeDDValueChanged, true);
            app.StrmTypeDD.Value = 'Liquid Flow In';
            app.StreamNameDropDownLabel = uilabel(app.GridLayout34);
            app.StreamNameDropDownLabel.Text = 'Stream Name';
            app.StrmNDD = uidropdown(app.GridLayout34);
            app.StrmNDD.Items = {''};
            app.StrmNDD.Editable = 'on';
            app.StrmNDD.ValueChangedFcn = createCallbackFcn(app, @StrmNDDValueChanged, true);
            app.StrmNDD.Value = '';
            app.StrmRB = uibutton(app.GridLayout34, 'push');
            app.StrmRB.ButtonPushedFcn = createCallbackFcn(app, @StrmRBButtonPushed, true);
            app.StrmRB.Text = 'Remove';
            app.StrmTypeL = uilabel(app.GridLayout34);
            app.StrmTypeL.Text = 'Stream Type';
            app.StrmAB = uibutton(app.GridLayout34, 'push');
            app.StrmAB.ButtonPushedFcn = createCallbackFcn(app, @StrmABButtonPushed, true);
            app.StrmAB.Text = 'Add/Update';
            app.MTStrmCompT = uitable(app.GridLayout34);
            app.MTStrmCompT.ColumnName = {'Component'; 'Composition'};
            app.MTStrmCompT.RowName = {};
            app.MTStrmCompT.ColumnEditable = true;
            app.MTStrmCompT.CellEditCallback = createCallbackFcn(app, @MTStrmCompSpecChanged, true);
            app.MTStrmCompBasisDD = uidropdown(app.GridLayout34);
            app.MTStrmCompBasisDD.Items = {'Concentration', 'Mass Fraction'};
            app.MTStrmCompBasisDD.ValueChangedFcn = createCallbackFcn(app, @MTStrmCompSpecChanged, true);
            app.MTStrmCompBasisDD.Value = 'Concentration';
            app.MTStrmCompBasisUDD = uidropdown(app.GridLayout34);
            app.MTStrmCompBasisUDD.Items = {'g/L', 'kg/L', 'g/m^3'};
            app.MTStrmCompBasisUDD.ValueChangedFcn = createCallbackFcn(app, @MTStrmCompSpecChanged, true);
            app.MTStrmCompBasisUDD.Value = 'g/L';
            app.MTStrmFlowVF = uieditfield(app.GridLayout34, 'numeric');
            app.MTStrmFlowVF.ValueChangedFcn = createCallbackFcn(app, @MTStrmCompSpecChanged, true);
            app.MTStrmFlowUDD = uidropdown(app.GridLayout34);
            app.MTStrmFlowUDD.Items = {'kg/s'};
            app.MTStrmFlowUDD.ValueChangedFcn = createCallbackFcn(app, @MTStrmCompSpecChanged, true);
            app.MTStrmFlowUDD.Value = 'kg/s';
            app.FlowrateL = uilabel(app.GridLayout34);
            app.FlowrateL.Text = 'Flowrate: ';
            app.MTSolvNDD = uidropdown(app.GridLayout34);
            app.MTSolvNDD.Items = {''};
            app.MTSolvNDD.Editable = 'on';
            app.MTSolvNDD.ValueChangedFcn = createCallbackFcn(app, @MTStrmCompSpecChanged, true);
            app.MTSolvNDD.Value = '';
            app.SolventLabel = uilabel(app.GridLayout34);
            app.SolventLabel.Text = 'Solvent: ';
            app.MTP = uipanel(app.GridLayout27);
            app.MTP.Title = 'Mass Transfer Functions';
            app.GridLayout37 = uigridlayout(app.MTP);
            app.MTGovFuncL = uilabel(app.GridLayout37);
            app.MTGovFuncL.Interpreter = 'latex';
            app.MTGovFuncL.Text = '';
            app.MTSaveL = uilabel(app.GridLayout37);
            app.MTSaveL.Text = 'Saved';
            app.ComponentDropDownLabel_2 = uilabel(app.GridLayout37);
            app.ComponentDropDownLabel_2.Text = 'Component';
            app.MTCompDD = uidropdown(app.GridLayout37);
            app.MTCompDD.Items = {''};
            app.MTCompDD.Editable = 'on';
            app.MTCompDD.ValueChangedFcn = createCallbackFcn(app, @MTCompDDValueChanged, true);
            app.MTCompDD.Value = '';
            app.GoverningEquationofLabel = uilabel(app.GridLayout37);
            app.GoverningEquationofLabel.Text = 'Phase A:';
            app.MTPhaseADD = uidropdown(app.GridLayout37);
            app.MTPhaseADD.Items = {''};
            app.MTPhaseADD.Editable = 'on';
            app.MTPhaseADD.ValueChangedFcn = createCallbackFcn(app, @MTPhaseDDValueChanged, true);
            app.MTPhaseADD.Tag = 'A';
            app.MTPhaseADD.Value = '';
            app.MTPhaseBL = uilabel(app.GridLayout37);
            app.MTPhaseBL.Text = 'Phase B:';
            app.MTPhaseBDD = uidropdown(app.GridLayout37);
            app.MTPhaseBDD.Items = {''};
            app.MTPhaseBDD.Editable = 'on';
            app.MTPhaseBDD.ValueChangedFcn = createCallbackFcn(app, @MTPhaseDDValueChanged, true);
            app.MTPhaseBDD.Tag = 'B';
            app.MTPhaseBDD.Value = '';
            app.MTFuncDD = uidropdown(app.GridLayout37);
            app.MTFuncDD.Items = {'None', 'First-Order with respect to Concentration Differential'};
            app.MTFuncDD.Editable = 'on';
            app.MTFuncDD.ValueChangedFcn = createCallbackFcn(app, @MTFuncDDValueChanged, true);
            app.MTFuncDD.Value = 'None';
            app.Button_11 = uibutton(app.GridLayout37, 'push');
            app.Button_11.Text = '?';
            app.MTModelUB = uibutton(app.GridLayout37, 'push');
            app.MTModelUB.ButtonPushedFcn = createCallbackFcn(app, @MTModelUBButtonPushed, true);
            app.MTModelUB.Text = 'Update';
            app.GasSpargingPhysicsSpecificationPanel = uipanel(app.GridLayout27);
            app.GasSpargingPhysicsSpecificationPanel.Title = 'Gas Sparging Physics Specification';
            app.GridLayout40 = uigridlayout(app.GasSpargingPhysicsSpecificationPanel);
            app.ReactionsTab = uitab(app.TabGroup);
            app.ReactionsTab.Title = 'Reactions';
            app.GridLayout = uigridlayout(app.ReactionsTab);
            app.ComponentDropDownLabel_3 = uilabel(app.GridLayout);
            app.ComponentDropDownLabel_3.Text = 'Component';
            app.InteractCompDD = uidropdown(app.GridLayout);
            app.InteractCompDD.Items = {};
            app.InteractCompDD.Editable = 'on';
            app.InteractCompDD.ValueChangedFcn = createCallbackFcn(app, @InteractCompDDValueChanged, true);
            app.InteractCompDD.Value = {};
            app.GovFuncPanel = uipanel(app.GridLayout);
            app.GovFuncPanel.Title = 'Governing Function: ';
            app.GridLayout18 = uigridlayout(app.GovFuncPanel);
            app.GovFuncL = uilabel(app.GridLayout18);
            app.GovFuncL.Interpreter = 'latex';
            app.GovFuncL.Text = '';
            app.SaveL = uilabel(app.GridLayout18);
            app.SaveL.Text = 'Saved';
            app.SysVarT = uitable(app.GridLayout);
            app.SysVarT.ColumnName = {'System Variable'; 'Expression'};
            app.SysVarT.RowName = {};
            app.SubFuncPanel = uipanel(app.GridLayout);
            app.SubFuncPanel.Title = 'Edit Functions';
            app.GridLayout19 = uigridlayout(app.SubFuncPanel);
            app.FuncTypeDD = uidropdown(app.GridLayout19);
            app.FuncTypeDD.Items = {'Monod', 'Linear', 'Custom'};
            app.FuncTypeDD.Editable = 'on';
            app.FuncTypeDD.Value = 'Monod';
            app.FuncNewAB = uibutton(app.GridLayout19, 'push');
            app.FuncNewAB.ButtonPushedFcn = createCallbackFcn(app, @FuncNewABButtonPushed, true);
            app.FuncNewAB.Text = 'Add Expression';
            app.ModelUB = uibutton(app.GridLayout19, 'push');
            app.ModelUB.ButtonPushedFcn = createCallbackFcn(app, @ModelUBButtonPushed, true);
            app.ModelUB.Enable = 'off';
            app.ModelUB.Text = 'Update';
            app.GovFuncVF = uieditfield(app.GridLayout19, 'text');
            app.GovFuncVF.ValueChangingFcn = createCallbackFcn(app, @GovFuncVFValueChanging, true);
            app.OpenExpressionsReferenceButton = uibutton(app.GridLayout19, 'push');
            app.OpenExpressionsReferenceButton.Text = 'Open Expressions Reference';
            app.PiecewiseLimsP = uipanel(app.GridLayout);
            app.PiecewiseLimsP.Title = 'Set Piecewise Limits on Functions';
            app.GridLayout20 = uigridlayout(app.PiecewiseLimsP);
            app.IfLabel_2 = uilabel(app.GridLayout20);
            app.IfLabel_2.Text = 'If';
            app.PiecewiseVarDD = uidropdown(app.GridLayout20);
            app.PiecewiseVarDD.Items = {'t', 'Option 2', 'Option 3', 'Option 4'};
            app.PiecewiseVarDD.Editable = 'on';
            app.PiecewiseVarDD.Value = 't';
            app.GovFuncPiecewiseT = uitable(app.GridLayout20);
            app.GovFuncPiecewiseT.ColumnName = {'Lower Bound'; 'Upper Bound'; 'Variable'; 'Value Outside Bounds'};
            app.GovFuncPiecewiseT.RowName = {};
            app.PiecewiseAB = uibutton(app.GridLayout20, 'push');
            app.PiecewiseAB.ButtonPushedFcn = createCallbackFcn(app, @PiecewiseABButtonPushed, true);
            app.PiecewiseAB.Text = 'Add';
            app.PiecewiseRB = uibutton(app.GridLayout20, 'push');
            app.PiecewiseRB.ButtonPushedFcn = createCallbackFcn(app, @PiecewiseRBButtonPushed, true);
            app.PiecewiseRB.Text = 'Remove';
            app.ExpressionDropDownLabel = uilabel(app.GridLayout20);
            app.ExpressionDropDownLabel.Text = 'Expression';
            app.PiecewiseDD = uidropdown(app.GridLayout20);
            app.PiecewiseDD.Items = {};
            app.PiecewiseDD.Editable = 'on';
            app.PiecewiseDD.Value = {};
            app.ElseDropDownLabel = uilabel(app.GridLayout20);
            app.ElseDropDownLabel.Text = 'Else';
            app.ElseValDD = uidropdown(app.GridLayout20);
            app.ElseValDD.Items = {'0', '1'};
            app.ElseValDD.Editable = 'on';
            app.ElseValDD.Value = '0';
            app.LowLimVF = uieditfield(app.GridLayout20, 'numeric');
            app.LowLimVF.AllowEmpty = 'on';
            app.LowLimVF.Value = [];
            app.UpLimVF = uieditfield(app.GridLayout20, 'numeric');
            app.UpLimVF.AllowEmpty = 'on';
            app.UpLimVF.Value = [];
            app.LowLimOpDD = uidropdown(app.GridLayout20);
            app.LowLimOpDD.Items = {'<=', '<'};
            app.LowLimOpDD.Value = '<=';
            app.UpLimOpDD = uidropdown(app.GridLayout20);
            app.UpLimOpDD.Items = {'<=', '<'};
            app.UpLimOpDD.Value = '<=';
            app.HelperFuncP = uipanel(app.GridLayout);
            app.HelperFuncP.Title = 'Helper Functions';
            app.GridLayout21 = uigridlayout(app.HelperFuncP);
            app.HelperDD = uidropdown(app.GridLayout21);
            app.HelperDD.Items = {};
            app.HelperDD.Editable = 'on';
            app.HelperDD.ValueChangedFcn = createCallbackFcn(app, @HelperDDValueChanged, true);
            app.HelperDD.Tag = 'GF';
            app.HelperDD.Value = {};
            app.HelperUB = uibutton(app.GridLayout21, 'push');
            app.HelperUB.ButtonPushedFcn = createCallbackFcn(app, @HelperUBButtonPushed, true);
            app.HelperUB.Tag = 'GF';
            app.HelperUB.Enable = 'off';
            app.HelperUB.Text = 'Update';
            app.HelperRB = uibutton(app.GridLayout21, 'push');
            app.HelperRB.ButtonPushedFcn = createCallbackFcn(app, @HelperRBButtonPushed, true);
            app.HelperRB.Tag = 'GF';
            app.HelperRB.Text = 'Remove';
            app.Label_15 = uilabel(app.GridLayout21);
            app.Label_15.Text = '=';
            app.HelperValF = uieditfield(app.GridLayout21, 'text');
            app.HelperValF.ValueChangingFcn = createCallbackFcn(app, @HelperValFValueChanging, true);
            app.HelperValF.Tag = 'GF';
            app.HelperSymF = uieditfield(app.GridLayout21, 'text');
            app.HelperSymF.ValueChangingFcn = createCallbackFcn(app, @HelperSymFValueChanging, true);
            app.HelperSymF.Tag = 'GF';
            app.HelperNameF = uieditfield(app.GridLayout21, 'text');
            app.HelperNameF.ValueChangingFcn = createCallbackFcn(app, @HelperNameFValueChanging, true);
            app.HelperNameF.Tag = 'GF';
            app.Label_16 = uilabel(app.GridLayout21);
            app.Label_16.Text = ':';
            app.HelperAB = uibutton(app.GridLayout21, 'push');
            app.HelperAB.ButtonPushedFcn = createCallbackFcn(app, @HelperABButtonPushed, true);
            app.HelperAB.Text = 'Add New';
            app.HelperFuncL = uilabel(app.GridLayout21);
            app.HelperFuncL.Interpreter = 'latex';
            app.HelperFuncL.Text = '';
            app.HelperSaveL = uilabel(app.GridLayout21);
            app.HelperSaveL.Text = 'Saved';
            app.SolverTab = uitab(app.TabGroup);
            app.SolverTab.Title = 'Solver';
            app.GridLayout41 = uigridlayout(app.SolverTab);
            app.NonNegativeSystemVariablesPanel = uipanel(app.GridLayout41);
            app.NonNegativeSystemVariablesPanel.Title = 'Non-Negative System Variables';
            app.GridLayout42 = uigridlayout(app.NonNegativeSystemVariablesPanel);
            app.NonNegSysVarAvailLB = uilistbox(app.GridLayout42);
            app.NonNegSysVarAvailLB.Items = {''};
            app.NonNegSysVarAvailLB.Value = '';
            app.NonNegSysVarLB = uilistbox(app.GridLayout42);
            app.NonNegSysVarLB.Items = {''};
            app.NonNegSysVarLB.Value = '';
            app.NonNegAB = uibutton(app.GridLayout42, 'push');
            app.NonNegAB.ButtonPushedFcn = createCallbackFcn(app, @NonNegSysVarUpdate, true);
            app.NonNegAB.Tag = 'add';
            app.NonNegAB.Text = 'Add >>';
            app.NonNegRB = uibutton(app.GridLayout42, 'push');
            app.NonNegRB.ButtonPushedFcn = createCallbackFcn(app, @NonNegSysVarUpdate, true);
            app.NonNegRB.Tag = 'rm';
            app.NonNegRB.Text = '<< Remove';
            app.SolverSpecificSettingsPanel = uipanel(app.GridLayout41);
            app.SolverSpecificSettingsPanel.Title = 'Solver-Specific Settings';
            app.GridLayout44 = uigridlayout(app.SolverSpecificSettingsPanel);
            app.SelectSolverDropDownLabel = uilabel(app.GridLayout44);
            app.SelectSolverDropDownLabel.Text = 'Select Solver:';
            app.SolverDD = uidropdown(app.GridLayout44);
            app.SolverDD.Items = {'Auto', 'Stiff', 'Nonstiff', 'ode23', 'ode45', 'ode78', 'ode89', 'ode113', 'ode15s', 'ode23s', 'ode23t', 'ode23tb'};
            app.SolverDD.ValueChangedFcn = createCallbackFcn(app, @SolverDDValueChanged, true);
            app.SolverDD.Value = 'ode45';
            app.SolverL = uilabel(app.GridLayout44);
            app.SolverL.Text = 'Current Solver: ode45';
            app.AbsoluteToleranceEditFieldLabel = uilabel(app.GridLayout44);
            app.AbsoluteToleranceEditFieldLabel.Text = 'Absolute Tolerance:';
            app.TolXVF = uieditfield(app.GridLayout44, 'numeric');
            app.TolXVF.ValueChangedFcn = createCallbackFcn(app, @SolverUpdate, true);
            app.TolXVF.Value = 1e-06;
            app.RelativeToleranceLabel = uilabel(app.GridLayout44);
            app.RelativeToleranceLabel.Text = 'Relative Tolerance:';
            app.RelTolVF = uieditfield(app.GridLayout44, 'numeric');
            app.RelTolVF.ValueChangedFcn = createCallbackFcn(app, @SolverUpdate, true);
            app.RelTolVF.Value = 0.001;
            app.InitialStepSizeEditFieldLabel = uilabel(app.GridLayout44);
            app.InitialStepSizeEditFieldLabel.Text = 'Initial Step Size:';
            app.InitStepSizeVF = uieditfield(app.GridLayout44, 'numeric');
            app.InitStepSizeVF.ValueChangedFcn = createCallbackFcn(app, @SolverUpdate, true);
            app.MaximumStepSizeEditFieldLabel = uilabel(app.GridLayout44);
            app.MaximumStepSizeEditFieldLabel.Text = 'Maximum Step Size:';
            app.MaxStepSizeVF = uieditfield(app.GridLayout44, 'numeric');
            app.MaxStepSizeVF.ValueChangedFcn = createCallbackFcn(app, @SolverUpdate, true);
            app.NormCtrlCB = uicheckbox(app.GridLayout44);
            app.NormCtrlCB.ValueChangedFcn = createCallbackFcn(app, @SolverUpdate, true);
            app.NormCtrlCB.Text = 'Norm Control';
            app.VectorizationCB = uicheckbox(app.GridLayout44);
            app.VectorizationCB.ValueChangedFcn = createCallbackFcn(app, @SolverUpdate, true);
            app.VectorizationCB.Text = 'Vectorization';
            app.BDFCB = uicheckbox(app.GridLayout44);
            app.BDFCB.ValueChangedFcn = createCallbackFcn(app, @SolverUpdate, true);
            app.BDFCB.Text = 'Backwards Differential Formulas';
            app.MaximumOrderDropDownLabel = uilabel(app.GridLayout44);
            app.MaximumOrderDropDownLabel.Text = 'Maximum Order:';
            app.MaxOrderDD = uidropdown(app.GridLayout44);
            app.MaxOrderDD.Items = {'5', '4', '3', '2', '1'};
            app.MaxOrderDD.ValueChangedFcn = createCallbackFcn(app, @SolverUpdate, true);
            app.MaxOrderDD.Value = '5';
            app.DropDown_8 = uidropdown(app.GridLayout44);
            app.DropDown_8.Items = {'sec', 'min', 'hr'};
            app.DropDown_8.Value = 'min';
            app.MaximumRuntimeEditFieldLabel = uilabel(app.GridLayout44);
            app.MaximumRuntimeEditFieldLabel.Text = 'Maximum Runtime:';
            app.MaximumRuntimeEditField = uieditfield(app.GridLayout44, 'numeric');
            app.MaximumRuntimeEditField.Value = 2;
            app.SolverEventsPanel = uipanel(app.GridLayout41);
            app.SolverEventsPanel.Title = 'Solver Events';
            app.GridLayout43 = uigridlayout(app.SolverEventsPanel);
            app.UITable = uitable(app.GridLayout43);
            app.UITable.ColumnName = {'Event'; 'System Variable'; 'Value'; 'Direction'; 'Stop Solver'; 'Action'};
            app.UITable.RowName = {};
            app.SystemVariableLabel = uilabel(app.GridLayout43);
            app.SystemVariableLabel.Text = 'System Variable:';
            app.SolverEvtSysVarDD = uidropdown(app.GridLayout43);
            app.SolverEvtSysVarDD.Items = {''};
            app.SolverEvtSysVarDD.Editable = 'on';
            app.SolverEvtSysVarDD.Value = '';
            app.SolverSysVarT = uitable(app.GridLayout43);
            app.SolverSysVarT.ColumnName = {'System Variable'; 'Expression'};
            app.SolverSysVarT.RowName = {};
            app.ValueLabel = uilabel(app.GridLayout43);
            app.ValueLabel.Text = 'Value:';
            app.ValueEditField = uieditfield(app.GridLayout43, 'text');
            app.DirectionDropDownLabel = uilabel(app.GridLayout43);
            app.DirectionDropDownLabel.Text = 'Direction:';
            app.DirectionDropDown = uidropdown(app.GridLayout43);
            app.DirectionDropDown.Items = {'Both', 'Ascending', 'Descending'};
            app.DirectionDropDown.Value = 'Both';
            app.StopSolverCheckBox = uicheckbox(app.GridLayout43);
            app.StopSolverCheckBox.Text = 'Stop Solver';
            app.EventDropDownLabel = uilabel(app.GridLayout43);
            app.EventDropDownLabel.Text = 'Event:';
            app.EventDropDown = uidropdown(app.GridLayout43);
            app.EventDropDown.Items = {''};
            app.EventDropDown.Value = '';
            app.AddButton_2 = uibutton(app.GridLayout43, 'push');
            app.AddButton_2.Text = 'Add';
            app.RemoveButton_2 = uibutton(app.GridLayout43, 'push');
            app.RemoveButton_2.Text = 'Remove';
            app.ActionVariableDropDownLabel = uilabel(app.GridLayout43);
            app.ActionVariableDropDownLabel.Text = 'Action Variable:';
            app.ActionVariableDropDown = uidropdown(app.GridLayout43);
            app.ActionVariableDropDown.Items = {''};
            app.ActionVariableDropDown.Value = '';
            app.ActionVariableValueEditFieldLabel = uilabel(app.GridLayout43);
            app.ActionVariableValueEditFieldLabel.Text = 'Action Variable Value:';
            app.ActionVariableValueEditField = uieditfield(app.GridLayout43, 'text');
            app.ModelReviewTab = uitab(app.TabGroup);
            app.ModelReviewTab.Title = 'Model Review';
            app.GridLayout39 = uigridlayout(app.ModelReviewTab);
            app.ModelFunctionsPanel = uipanel(app.GridLayout39);
            app.ModelFunctionsPanel.Title = 'Model Functions';
            app.SummaryL = uilabel(app.ModelFunctionsPanel);
            app.SummaryL.Interpreter = 'latex';
            app.SummaryL.Text = '';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout7 = uigridlayout(app.ParametersTab);
            app.ParamT = uitable(app.GridLayout7);
            app.ParamT.ColumnName = {'Number'; 'Function'; 'Symbol'; 'Name'; 'Value'; 'Unit'; 'Editable'};
            app.ParamT.RowName = {};
            app.ParamT.ColumnSortable = [true true true true true true false];
            app.ParameterNumberLabel = uilabel(app.GridLayout7);
            app.ParameterNumberLabel.Text = 'Parameter Number';
            app.ParamNumDD = uidropdown(app.GridLayout7);
            app.ParamNumDD.Items = {};
            app.ParamNumDD.Editable = 'on';
            app.ParamNumDD.ValueChangedFcn = createCallbackFcn(app, @ParamNumDDValueChanged, true);
            app.ParamNumDD.Value = {};
            app.ParamValF = uieditfield(app.GridLayout7, 'numeric');
            app.ParamUnitDD = uidropdown(app.GridLayout7);
            app.ParamUnitDD.Items = {'', 'g/L'};
            app.ParamUnitDD.Editable = 'on';
            app.ParamUnitDD.Value = 'g/L';
            app.ParamUB = uibutton(app.GridLayout7, 'push');
            app.ParamUB.ButtonPushedFcn = createCallbackFcn(app, @ParamUBPushed, true);
            app.ParamUB.Text = 'Update';
            app.SpeciesChemicalLabel = uilabel(app.GridLayout7);
            app.SpeciesChemicalLabel.Text = 'Species/Chemical';
            app.CompParamDD = uidropdown(app.GridLayout7);
            app.CompParamDD.Items = {};
            app.CompParamDD.Editable = 'on';
            app.CompParamDD.ValueChangedFcn = createCallbackFcn(app, @CompParamDDValueChanged, true);
            app.CompParamDD.Value = {};
            app.ParameterNameEditFieldLabel = uilabel(app.GridLayout7);
            app.ParameterNameEditFieldLabel.Text = 'Parameter Name';
            app.ParamNF = uieditfield(app.GridLayout7, 'text');
            app.ParameterSymbolDropDownLabel = uilabel(app.GridLayout7);
            app.ParameterSymbolDropDownLabel.Text = 'Parameter Symbol';
            app.ParamSymDD = uidropdown(app.GridLayout7);
            app.ParamSymDD.Items = {};
            app.ParamSymDD.Editable = 'on';
            app.ParamSymDD.ValueChangedFcn = createCallbackFcn(app, @ParamSymDDValueChanged, true);
            app.ParamSymDD.Value = {};
            app.RegressionTab = uitab(app.TabGroup);
            app.RegressionTab.Title = 'Regression';
            app.GridLayout32 = uigridlayout(app.RegressionTab);
            app.GridLayout32.Tag = 'unmatch';
            app.RegPlot = uiaxes(app.GridLayout32);
            title(app.RegPlot, 'Regression')
            xlabel(app.RegPlot, 'Time')
            ylabel(app.RegPlot, 'Dependent Variable(s)')
            zlabel(app.RegPlot, 'Z')
            app.RegParamDD = uidropdown(app.GridLayout32);
            app.RegParamDD.Items = {};
            app.RegParamDD.Editable = 'on';
            app.RegParamDD.Value = {};
            app.SaveRegParamB = uibutton(app.GridLayout32, 'push');
            app.SaveRegParamB.ButtonPushedFcn = createCallbackFcn(app, @SaveRegParamBButtonPushed, true);
            app.SaveRegParamB.Text = 'Save';
            app.ParametersDropDownLabel = uilabel(app.GridLayout32);
            app.ParametersDropDownLabel.Text = 'Parameters';
            app.RegParamAB = uibutton(app.GridLayout32, 'push');
            app.RegParamAB.ButtonPushedFcn = createCallbackFcn(app, @RegParamUpdate, true);
            app.RegParamAB.Tag = 'Add';
            app.RegParamAB.Text = 'Add';
            app.RegParamRB = uibutton(app.GridLayout32, 'push');
            app.RegParamRB.ButtonPushedFcn = createCallbackFcn(app, @RegParamUpdate, true);
            app.RegParamRB.Tag = 'Remove';
            app.RegParamRB.Text = 'Remove';
            app.SystemVariableDropDownLabel = uilabel(app.GridLayout32);
            app.SystemVariableDropDownLabel.Text = 'System Variable';
            app.RegSysVarDD = uidropdown(app.GridLayout32);
            app.RegSysVarDD.Items = {};
            app.RegSysVarDD.Editable = 'on';
            app.RegSysVarDD.Value = {};
            app.ImportedDataDropDownLabel = uilabel(app.GridLayout32);
            app.ImportedDataDropDownLabel.Text = 'Imported Data';
            app.ImportedDataDD = uidropdown(app.GridLayout32);
            app.ImportedDataDD.Items = {};
            app.ImportedDataDD.Editable = 'on';
            app.ImportedDataDD.Value = {};
            app.VarDataMatchB = uibutton(app.GridLayout32, 'push');
            app.VarDataMatchB.ButtonPushedFcn = createCallbackFcn(app, @UpdateDVDataMatching, true);
            app.VarDataMatchB.Tag = 'match';
            app.VarDataMatchB.Text = 'Match';
            app.RegParamT = uitable(app.GridLayout32);
            app.RegParamT.ColumnName = {'Parameter'; 'Component'; 'Function'; 'Value'; 'Regressed Value'};
            app.RegParamT.RowName = {};
            app.RunRegB = uibutton(app.GridLayout32, 'push');
            app.RunRegB.ButtonPushedFcn = createCallbackFcn(app, @RunRegBPushed, true);
            app.RunRegB.Enable = 'off';
            app.RunRegB.Text = 'Run Regression';
            app.ParameterDropDownLabel_2 = uilabel(app.GridLayout32);
            app.ParameterDropDownLabel_2.Text = 'Parameter';
            app.SaveRegParamDD = uidropdown(app.GridLayout32);
            app.SaveRegParamDD.Items = {};
            app.SaveRegParamDD.Editable = 'on';
            app.SaveRegParamDD.Value = {};
            app.SaveAllRegParamB = uibutton(app.GridLayout32, 'push');
            app.SaveAllRegParamB.ButtonPushedFcn = createCallbackFcn(app, @SaveAllRegParamBButtonPushed, true);
            app.SaveAllRegParamB.Text = 'Save All';
            app.ImportDataB = uibutton(app.GridLayout32, 'push');
            app.ImportDataB.ButtonPushedFcn = createCallbackFcn(app, @ImportBButtonPushed, true);
            app.ImportDataB.Text = 'Import Data';
            app.DependentVariableLabel = uilabel(app.GridLayout32);
            app.DependentVariableLabel.Text = 'Dependent Variable';
            app.RegDVDD = uidropdown(app.GridLayout32);
            app.RegDVDD.Items = {};
            app.RegDVDD.Editable = 'on';
            app.RegDVDD.Value = {};
            app.RegDVAB = uibutton(app.GridLayout32, 'push');
            app.RegDVAB.ButtonPushedFcn = createCallbackFcn(app, @RegDVPlotUpdate, true);
            app.RegDVAB.Tag = 'add';
            app.RegDVAB.Text = 'Plot';
            app.RegVarDataT = uitable(app.GridLayout32);
            app.RegVarDataT.ColumnName = {'System Variable Name'; 'Imported Variable Name'};
            app.RegVarDataT.RowName = {};
            app.ComponentDropDown_2Label = uilabel(app.GridLayout32);
            app.ComponentDropDown_2Label.Text = 'Component';
            app.RegCompParamDD = uidropdown(app.GridLayout32);
            app.RegCompParamDD.Items = {};
            app.RegCompParamDD.Editable = 'on';
            app.RegCompParamDD.ValueChangedFcn = createCallbackFcn(app, @RegCompParamDDValueChanged, true);
            app.RegCompParamDD.Value = {};
            app.VarDataUnmatchB = uibutton(app.GridLayout32, 'push');
            app.VarDataUnmatchB.ButtonPushedFcn = createCallbackFcn(app, @UpdateDVDataMatching, true);
            app.VarDataUnmatchB.Text = 'Unmatch';
            app.RegSpecsB = uibutton(app.GridLayout32, 'push');
            app.RegSpecsB.ButtonPushedFcn = createCallbackFcn(app, @RegSpecsBButtonPushed, true);
            app.RegSpecsB.Enable = 'off';
            app.RegSpecsB.Text = 'Regression Specifications';
            app.RegAnalysisB = uibutton(app.GridLayout32, 'push');
            app.RegAnalysisB.ButtonPushedFcn = createCallbackFcn(app, @RegAnalysisBPushed, true);
            app.RegAnalysisB.Enable = 'off';
            app.RegAnalysisB.Text = 'Goodness of Fit Analysis';
            app.PlotCustomizationTab = uitab(app.TabGroup);
            app.PlotCustomizationTab.Title = 'Plot Customization';
            app.GridLayout4 = uigridlayout(app.PlotCustomizationTab);
            app.PlotsDropDownLabel = uilabel(app.GridLayout4);
            app.PlotsDropDownLabel.Text = 'Plots';
            app.PlotRB = uibutton(app.GridLayout4, 'push');
            app.PlotRB.ButtonPushedFcn = createCallbackFcn(app, @PlotRBButtonPushed, true);
            app.PlotRB.Text = 'Remove';
            app.AxisSpecificationsP = uipanel(app.GridLayout4);
            app.AxisSpecificationsP.Title = 'Axis Specifications';
            app.GridLayout22 = uigridlayout(app.AxisSpecificationsP);
            app.XAxisTitleLabel = uilabel(app.GridLayout22);

            app.XAxisTitleLabel.Text = 'X Axis Title';
            app.AxTitleF = uieditfield(app.GridLayout22, 'text');
            app.AxTitleF.ValueChangedFcn = createCallbackFcn(app, @AxTitleFValueChanged, true);
            app.AxTitleF.Tag = 'X';
            app.AxDefaultRCB = uicheckbox(app.GridLayout22);
            app.AxDefaultRCB.Text = 'Use Default Range';
            app.NumberofAxesDropDownLabel = uilabel(app.GridLayout22);
            app.NumberofAxesDropDownLabel.Text = 'Number of Axes';
            app.NbAxesDD = uidropdown(app.GridLayout22);
            app.NbAxesDD.Items = {'2-Axis', '3-Axis'};
            app.NbAxesDD.ValueChangedFcn = createCallbackFcn(app, @NbAxesDDValueChanged, true);
            app.NbAxesDD.Value = '2-Axis';
            app.LowerDisplayLimitDropDown_3Label = uilabel(app.GridLayout22);
            app.LowerDisplayLimitDropDown_3Label.Text = 'Lower Display Limit';
            app.UpperDisplayLimitDropDown_3Label = uilabel(app.GridLayout22);
            app.UpperDisplayLimitDropDown_3Label.Text = 'Upper Display Limit';
            app.Div2 = uilabel(app.GridLayout22);
            app.Div2.Text = '';
            app.AxDispLimUB = uibutton(app.GridLayout22, 'push');
            app.AxDispLimUB.Tag = 'X';
            app.AxDispLimUB.Text = 'Update';
            app.AxVarAB = uibutton(app.GridLayout22, 'push');
            app.AxVarAB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.AxVarAB.Tag = 'add';
            app.AxVarAB.Text = 'Add >>';
            app.AxVarRB = uibutton(app.GridLayout22, 'push');
            app.AxVarRB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.AxVarRB.Tag = 'rm';
            app.AxVarRB.Text = '<< Remove';
            app.AxLoLimVF = uieditfield(app.GridLayout22, 'numeric');
            app.AxUpLimVF = uieditfield(app.GridLayout22, 'numeric');
            app.Label_18 = uilabel(app.GridLayout22);
            app.Button_12 = uibutton(app.GridLayout22, 'push');
            app.Button_12.Text = '?';
            app.AxisDropDownLabel_2 = uilabel(app.GridLayout22);
            app.AxisDropDownLabel_2.Text = 'Axis';
            app.AxisDD = uidropdown(app.GridLayout22);
            app.AxisDD.Items = {'X-Axis', 'Y-Axis', 'Z-Axis'};
            app.AxisDD.ValueChangedFcn = createCallbackFcn(app, @AxisDDValueChanged, true);
            app.AxisDD.Value = 'X-Axis';
            app.PlotAvailCompLB = uilistbox(app.GridLayout22);
            app.PlotAvailCompLB.Items = {''};
            app.PlotAvailCompLB.Value = '';
            app.PlotSelectedCompLB = uilistbox(app.GridLayout22);
            app.PlotSelectedCompLB.Items = {''};
            app.PlotSelectedCompLB.Value = '';
            app.PlotInitCondP = uipanel(app.GridLayout4);
            app.PlotInitCondP.Title = 'Additional Specifications';
            app.GridLayout23 = uigridlayout(app.PlotInitCondP);
            app.SysVarInitCondT = uitable(app.GridLayout23);
            app.SysVarInitCondT.ColumnName = {'Variable'; 'Unit'};
            app.SysVarInitCondT.RowName = {};
            app.SysVarInitCondT.ColumnSortable = true;
            app.SysVarInitCondT.ColumnEditable = true;
            app.PlotICEvalSpecUB = uibutton(app.GridLayout23, 'push');
            app.PlotICEvalSpecUB.ButtonPushedFcn = createCallbackFcn(app, @PlotICEvalSpecUBButtonPushed, true);
            app.PlotICEvalSpecUB.Text = 'Update';
            app.AxisDropDownLabel = uilabel(app.GridLayout23);
            app.AxisDropDownLabel.Text = 'Axis: ';
            app.VariableDropDownLabel_2 = uilabel(app.GridLayout23);
            app.VariableDropDownLabel_2.Text = 'Variable: ';
            app.UnitsLabel = uilabel(app.GridLayout23);
            app.UnitsLabel.Text = 'Units: ';
            app.AddSpecL1 = uilabel(app.GridLayout23);
            app.AddSpecL1.Visible = 'off';
            app.AddSpecL1.Text = '';
            app.AddSpecL2 = uilabel(app.GridLayout23);
            app.AddSpecL2.Visible = 'off';
            app.AddSpecL2.Text = '';
            app.AddSpecL3 = uilabel(app.GridLayout23);
            app.AddSpecL3.Visible = 'off';
            app.AddSpecL3.Text = '';
            app.ICEvalAxisDD = uidropdown(app.GridLayout23);
            app.ICEvalAxisDD.Items = {''};
            app.ICEvalAxisDD.Editable = 'on';
            app.ICEvalAxisDD.ValueChangedFcn = createCallbackFcn(app, @ICEvalAxisDDValueChanged, true);
            app.ICEvalAxisDD.Value = '';
            app.ICEvalVarDD = uidropdown(app.GridLayout23);
            app.ICEvalVarDD.Items = {''};
            app.ICEvalVarDD.Editable = 'on';
            app.ICEvalVarDD.ValueChangedFcn = createCallbackFcn(app, @ICEvalVarDDValueChanged, true);
            app.ICEvalVarDD.Value = '';
            app.evalUnitsVF = uieditfield(app.GridLayout23, 'text');
            app.AddSpecVF1 = uieditfield(app.GridLayout23, 'numeric');
            app.AddSpecVF1.AllowEmpty = 'on';
            app.AddSpecVF1.Value = [];
            app.AddSpecVF2 = uieditfield(app.GridLayout23, 'numeric');
            app.AddSpecVF2.AllowEmpty = 'on';
            app.AddSpecVF2.Value = [];
            app.AddSpecVF3 = uieditfield(app.GridLayout23, 'numeric');
            app.AddSpecVF3.AllowEmpty = 'on';
            app.AddSpecVF3.Value = [];
            app.PlotGenerationP = uipanel(app.GridLayout4);
            app.PlotGenerationP.Title = 'Model Compilation';
            app.GridLayout24 = uigridlayout(app.PlotGenerationP);
            app.GenPlotB = uibutton(app.GridLayout24, 'push');
            app.GenPlotB.ButtonPushedFcn = createCallbackFcn(app, @GenPlotBButtonPushed, true);
            app.GenPlotB.Text = 'Compile Model';
            app.OptionsL = uilabel(app.GridLayout24);
            app.OptionsL.Text = 'Options';
            app.ClosePrevPlotsCB = uicheckbox(app.GridLayout24);
            app.ClosePrevPlotsCB.Text = 'Close Previous Plots';
            app.SelectPlotsL = uilabel(app.GridLayout24);
            app.SelectPlotsL.Text = 'Select Plots:';
            app.PlotDispTree = uitree(app.GridLayout24, 'checkbox');
            app.PlotDispTree.Editable = 'on';
            app.PlotDownloadP = uipanel(app.GridLayout4);
            app.PlotDownloadP.Title = 'Plot Download';
            app.GridLayout25 = uigridlayout(app.PlotDownloadP);
            app.PlotDirL = uilabel(app.GridLayout25);
            app.PlotDirL.Text = 'Directory: None Selected';
            app.PlotDownloadDirB = uibutton(app.GridLayout25, 'push');
            app.PlotDownloadDirB.ButtonPushedFcn = createCallbackFcn(app, @PlotDownloadDirBButtonPushed, true);
            app.PlotDownloadDirB.Text = 'Select Directory';
            app.FileTypeDropDownLabel = uilabel(app.GridLayout25);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDD = uidropdown(app.GridLayout25);
            app.FileTypeDD.Items = {'.png', '.jpg', '.jpeg', '.fig'};
            app.FileTypeDD.Value = '.png';
            app.PlotAB = uibutton(app.GridLayout4, 'push');
            app.PlotAB.ButtonPushedFcn = createCallbackFcn(app, @PlotABButtonPushed, true);
            app.PlotAB.Text = 'Add';
            app.PlotTitleDD = uidropdown(app.GridLayout4);
            app.PlotTitleDD.Items = {''};
            app.PlotTitleDD.Editable = 'on';
            app.PlotTitleDD.ValueChangedFcn = createCallbackFcn(app, @PlotTitleDDValueChanged, true);
            app.PlotTitleDD.Value = '';
            app.TitleEditFieldLabel_2 = uilabel(app.GridLayout4);
            app.TitleEditFieldLabel_2.Text = 'Title';
            app.PlotTitleF = uieditfield(app.GridLayout4, 'text');
            app.PlotTitleF.ValueChangedFcn = createCallbackFcn(app, @PlotTitleFValueChanged, true);
            app.Label4 = uilabel(app.GridLayout4);
            app.Label4.Text = 'Label4';
            app.ofSubplotRowsEditFieldLabel = uilabel(app.GridLayout4);
            app.ofSubplotRowsEditFieldLabel.Text = '# of Subplot Rows:';
            app.SubplotRVF = uieditfield(app.GridLayout4, 'numeric');
            app.SubplotRVF.Limits = [1 Inf];
            app.SubplotRVF.Value = 1;
            app.SubplotUB = uibutton(app.GridLayout4, 'push');
            app.SubplotUB.ButtonPushedFcn = createCallbackFcn(app, @SubplotUBButtonPushed, true);
            app.SubplotUB.Text = 'Update';
            app.PlotGroupDropDownLabel = uilabel(app.GridLayout4);
            app.PlotGroupDropDownLabel.Text = 'Plot Group:';
            app.SubplotGroupDD = uidropdown(app.GridLayout4);
            app.SubplotGroupDD.Items = {'1'};
            app.SubplotGroupDD.ValueChangedFcn = createCallbackFcn(app, @SubplotGroupDDValueChanged, true);
            app.SubplotGroupDD.Value = '1';
            app.ofSubplotColumnsEditFieldLabel = uilabel(app.GridLayout4);
            app.ofSubplotColumnsEditFieldLabel.Text = '# of Subplot Columns:';
            app.SubplotCVF = uieditfield(app.GridLayout4, 'numeric');
            app.SubplotCVF.Value = 1;
            app.PlotPositionDropDownLabel = uilabel(app.GridLayout4);
            app.PlotPositionDropDownLabel.Text = 'Plot Position:';
            app.PlotPosDD = uidropdown(app.GridLayout4);
            app.PlotPosDD.Items = {'1'};
            app.PlotPosDD.ValueChangedFcn = createCallbackFcn(app, @PlotPosDDValueChanged, true);
            app.PlotPosDD.Value = '1';
            app.SubplotAB = uibutton(app.GridLayout4, 'push');
            app.SubplotAB.ButtonPushedFcn = createCallbackFcn(app, @SubplotABPushed, true);
            app.SubplotAB.Text = 'Add Subplot';
            app.SubplotDropDownLabel = uilabel(app.GridLayout4);
            app.SubplotDropDownLabel.Text = 'Subplot:';
            app.SubplotDD = uidropdown(app.GridLayout4);
            app.SubplotDD.Items = {'1'};
            app.SubplotDD.ValueChangedFcn = createCallbackFcn(app, @SubplotDDValueChanged, true);
            app.SubplotDD.Value = '1';
            app.SubplotRB = uibutton(app.GridLayout4, 'push');
            app.SubplotRB.ButtonPushedFcn = createCallbackFcn(app, @SubplotRBButtonPushed, true);
            app.SubplotRB.Text = 'Remove Subplot';
            app.ModelResultsExportPanel = uipanel(app.GridLayout4);
            app.ModelResultsExportPanel.Title = 'Model Results Export';
            app.GridLayout38 = uigridlayout(app.ModelResultsExportPanel);
            app.DataExportB = uibutton(app.GridLayout38, 'push');
            app.DataExportB.ButtonPushedFcn = createCallbackFcn(app, @DataExportBButtonPushed, true);
            app.DataExportB.Text = 'Export Data';
            app.DataExportDirB = uibutton(app.GridLayout38, 'push');
            app.DataExportDirB.ButtonPushedFcn = createCallbackFcn(app, @DataExportDirBButtonPushed, true);
            app.DataExportDirB.Text = 'Select Directory';
            app.DataExportDIrL = uilabel(app.GridLayout38);
            app.DataExportDIrL.Text = 'Directory: None Selected';
            app.ConsoleB = uibutton(app.GridLayout46, 'push');
            app.ConsoleB.ButtonPushedFcn = createCallbackFcn(app, @ConsoleBPushed, true);
            app.ConsoleB.Text = 'Show Console';
            app.ClearConsoleButton = uibutton(app.GridLayout46, 'push');
            app.ClearConsoleButton.ButtonPushedFcn = createCallbackFcn(app, @ClearConsoleButtonPushed, true);
            app.ClearConsoleButton.Text = 'Clear Console';
            app.StopCalculationButton = uibutton(app.GridLayout46, 'push');
            app.StopCalculationButton.Text = 'Stop Calculation';
            % Show the figure after all components are created
            app.MAGMAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAGMA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MAGMAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MAGMAUIFigure)
        end
    end
end