classdef RegSpecsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        RegSpecsP                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        RegSpecDD1RB                   matlab.ui.control.Button
        RegSpecDD1AB                   matlab.ui.control.Button
        RegSpecSpin6                   matlab.ui.control.Spinner
        RegSpecL6                      matlab.ui.control.Label
        RegSpecL5                      matlab.ui.control.Label
        RegSpecL4                      matlab.ui.control.Label
        RegSpecL3                      matlab.ui.control.Label
        RegSpecL2                      matlab.ui.control.Label
        RegSpecL1                      matlab.ui.control.Label
        RegSolverDD                    matlab.ui.control.DropDown
        RegressionSolverDropDownLabel  matlab.ui.control.Label
        RegSpecUB                      matlab.ui.control.Button
        RegSpecCB7                     matlab.ui.control.CheckBox
        RegSpecSpin5                   matlab.ui.control.Spinner
        RegSpecDD2                     matlab.ui.control.DropDown
        RegSpecValL2                   matlab.ui.control.Label
        RegSpecDD1                     matlab.ui.control.DropDown
        RegSpecVF4                     matlab.ui.control.NumericEditField
        RegSpecVF3                     matlab.ui.control.NumericEditField
        RegSpecValL1                   matlab.ui.control.Label
        ParameterSpecificationsPanel   matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        RegParamSpecUB                 matlab.ui.control.Button
        DerivStepDD                    matlab.ui.control.DropDown
        RelativeDifferenceforFiniteDifferenceGradientDropDownLabel  matlab.ui.control.Label
        DerivStepL                     matlab.ui.control.Label
        ParamIGLL3                     matlab.ui.control.Label
        ParamIGDD3                     matlab.ui.control.DropDown
        withDropDownLabel              matlab.ui.control.Label
        ParamIGDD2                     matlab.ui.control.DropDown
        toDropDownLabel                matlab.ui.control.Label
        ParamIGDD1                     matlab.ui.control.DropDown
        ParameterInitialGuessDropDownLabel  matlab.ui.control.Label
        RegParamDD                     matlab.ui.control.DropDown
        RegressionParameterDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        regSpecs % struct of regression specifications
        regParamList % List of parameter names
        RegSpecB;
        regSpecLs = {};
        regSpecFs = {};
        nlinfitSpecs = struct( ...
            'solver','nlinfit', ...
            'Weight_Function','None', ...
            'Tuning_Constant','Default', ...
            'TolFun',1E-8, ...
            'TolX',1E-8, ...
            'Maximum_Iterations',100, ...
            'checkNaNInf',false, ...
            'DerivStep',[] ...
        );
        lsqcurvefitSpecs = struct([]);
        fminsearchSpecs = struct( ...
            'solver','fminsearch', ...
            'PlotFcns',[], ...
            'TolFun',1E-4, ...
            'TolX',1E-4, ...
            'Maximum_Iterations',1000, ...
            'Maximum_Function_Evaluations',1000, ...
            'checkComplexInf',false ...
        );
        fminconSpecs = struct([]);
    end

    % ### FIXME: add error model functionality
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, regSpecs, regParamList, RegSpecB)
            app.CallingApp = mainapp;
            app.regSpecs = regSpecs;
            app.regSpecs.paramIGs = zeros(size(regParamList,1),1);
            for k=1:1:size(regParamList,1), app.regSpecs.paramIGs(k,1) = regParamList{k,2}; end
            app.regParamList = regParamList;
            app.RegParamDD.Items = regParamList(:,1);

            app.ParamIGDD1.Items = string(regParamList{1,2});
            app.ParamIGDD2.Items = string(regParamList{1,2});
            app.ParamIGDD3.Items = ["1"]; %#ok<NBRAK> 
            app.RegSpecB = RegSpecB;

            switch regSpecs.solverSpecs.solver
                case "nlinfit"
                    app.DerivStepL.Text = string(eps.^(1./3));
                    app.nlinfitSpecs.DerivStep = zeros(size(regParamList,1),1);
                    for k=1:1:size(regParamList,1)
                        app.nlinfitSpecs.DerivStep(k,1) = eps^(1/3);
                    end

                case "lsqcurvefit"


                case "fminsearch"


                case "fmincon"


            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.RegSpecB.Enable = true;
            delete(app);
        end

        % Button pushed function: RegSpecUB
        function RegSpecUBPushed(app, event)
            solverName = app.RegSolverDD.Value;
            
            app.regSpecs.params = app.regParamList{:,1};

            switch solverName
                case "nlinfit"
                    app.regSpecs.solverSpecs = app.nlinfitSpecs;
                case "lsqcurvefit"
                    app.regSpecs.solverSpecs = app.lsqcurvefitSpecs;
                case "fminsearch"
                    app.regSpecs.solverSpecs = app.fminsearchSpecs;
                case "fmincon"
                    app.regSpecs.solverSpecs = app.fminconSpecs;
            end

            updateRegSpecs(app.CallingApp,app.regSpecs); 
        end

        % Button pushed function: RegParamSpecUB
        function RegParamSpecUBButtonPushed(app, event)
            solverName = app.RegSolverDD.Value;
            loLim = double(string(app.ParamIGDD1.Value));
%             upLim = double(string(app.ParamIGDD2.Value));
%             nbPts = double(string(app.ParamIGDD3.Value));
            derivStep = double(string(app.DerivStepDD.Value));
            paramName = app.RegParamDD.Value;
            for k=1:1:size(app.regParamList,1)
                if strcmp(app.regParamList{k,1},paramName)
                    % ### FIXME: upgrade to enable user to try multiple
                    % starting guesses for each parameter
%                     app.regSpecs.paramIGs{k} = linspace(loLim,upLim,nbPts);
                    app.regSpecs.paramIGs(k,1) = loLim;
                    if strcmp(solverName,"nlinfit")
                        if isnan(derivStep)
                            app.nlinfitSpecs.DerivStep(k,1) = eps^(1/3);
                        else
                            app.nlinfitSpecs.DerivStep(k,1) = derivStep;
                        end
                    end
                end
            end

            updateRegSpecs(app.CallingApp,app.regSpecs); 
        end

        % Value changed function: RegParamDD
        function RegParamDDValueChanged(app, event)
            solverName = app.RegSolverDD.Value;
            
            paramName = string(app.RegParamDD.Value);
            for k=1:1:length(app.regParamList)
                if strcmp(app.regParamList{k,1},paramName)
                    app.ParamIGDD1.Items = string(app.regSpecs.paramIGs(k,1));
                    app.ParamIGDD1.Value = string(app.regSpecs.paramIGs(k,1));
%                     app.ParamIGDD2.Items = app.regSpecs.paramIGs{k}(2);
%                     app.ParamIGDD2.Value = app.regSpecs.paramIGs{k}(2);
%                     app.ParamIGDD3.Items = app.regSpecs.paramIGs{k}(3);
%                     app.ParamIGDD3.Value = app.regSpecs.paramIGs{k}(3);
                    if strcmp(solverName,"nlinfit")
                        if app.nlinfitSpecs.DerivStep(k,1) == eps^(1/3)
                            app.DerivStepDD.Items = {'Default'};
                            app.DerivStepDD.Value = 'Default';
                        else
                            app.DerivStepDD.Items = app.regSpecs.DerivStep(k,1);
                            app.DerivStepDD.Value = app.regSpecs.DerivStep(k,1);
                        end
                    end
                end
            end
        end

        % Value changed function: DerivStepDD
        function DerivStepDDValueChanged(app, event)
            value = app.DerivStepDD.Value;
            if value == "Default"
                app.DerivStepL.Text = eps^(1/3);
            else
                app.DerivStepL.Text = value;
            end

            % app.AddNewValToDD(event);
        end

        % Value changed function: ParamIGDD1, ParamIGDD2, ParamIGDD3
        function AddNewValToDD(app, event)
            value = event.Source.Value;
            event.Source.Items = [event.Source.Items,{value}];
        end

        % Value changed function: RegSpecDD1, RegSpecDD2
        function WFDDValueChanged(app, event)
            solverName = app.RegSolverDD.Value;

            if strcmp(solverName,"nlinfit")
                wtFuncName = app.RegSpecDD1.Value;
                switch wtFuncName
                    case 'None'
                        wtFunc = '';
                        defTuneConst = [];
                        wtFuncText = "N/A";
                        app.RegSpecDD2.Value = 'Default';
                        app.RegSpecValL2.Text = "N/A";
                    case 'Andrews'
                        wtFunc = 'andrews';
                        defTuneConst = 1.339;
                        wtFuncText = "$w(r) = I[|r|<pi]*\frac{sin(r)}{r}$";
                    case 'Bisquare'
                        wtFunc = 'bisquare';
                        defTuneConst = 4.685;
                        wtFuncText = "$w(r) = I[|r|<1]*(1-r^2)^2$";
                    case 'Cauchy'
                        wtFunc = 'cauchy';
                        defTuneConst = 2.385;
                        wtFuncText = "$w(r) = \frac{1}{(1+r^2)}$";
                    case 'Fair'
                        wtFunc = 'fair';
                        defTuneConst = 1.400;
                        wtFuncText = "$w(r) = \frac{1}{(1+|r|)}$";
                    case 'Huber'
                        wtFunc = 'huber';
                        defTuneConst = 1.345;
                        wtFuncText = "$w(r) = \frac{1}{\max(1,|r|)}$";
                    case 'Logistic'
                        wtFunc = 'logistic';
                        defTuneConst = 1.205;
                        wtFuncText = "$w(r) = \frac{\tanh(r)}{r}$";
                    case 'Talwar'
                        wtFunc = 'talwar';
                        defTuneConst = 2.795;
                        wtFuncText = "$w(r) = I[|r|<1]$";
                    case 'Welsch'
                        wtFunc = 'welsch';
                        defTuneConst = 2.985;
                        wtFuncText = "$w(r) = e^{-r^2}$";
                end
    
                app.nlinfitSpecs.Weight_Function = wtFunc;
                if app.RegSpecDD2.Value == "Default"
                    app.nlinfitSpecs.Tuning_Constant = defTuneConst;
                    if defTuneConst == double.empty
                        defTuneConst = "N/A";
                    end
                    app.RegSpecValL2.Text = defTuneConst;
                else
                    app.nlinfitSpecs.Tuning_Constant = double(string(app.RegSpecDD2.Value));
                    app.RegSpecValL2.Text = double(string(app.RegSpecDD2.Value));
                end
    
                app.RegSpecL1.Text = wtFuncText;
            end
        end

        % Value changed function: RegSolverDD
        function RegSolverDDValueChanged(app, event)
            solverName = app.RegSolverDD.Value;
            switch solverName
                case "nlinfit"
                    app.RegSpecL1.Text = "Weight Function";
                    app.RegSpecL2.Text = "Tuning Constant";
                    app.RegSpecL3.Text = "Termination Tolerance on Residual Sum of Squares";
                    app.RegSpecL4.Text = "Termination Tolerance on Estimated Coefficients";
                    app.RegSpecL5.Text = "Maximum Iterations";
                   app.RegSpecCB7.Text = "Check for NaN and Inf Values";

                    for k=1:1:5
                        app.("RegSpecL"+k).Enable = true;
                        app.("RegSpecL"+k).Visible = true;
                    end
                    app.RegSpecL6.Enable = false;
                    app.RegSpecL6.Visible = false;
                    app.RegSpecDD1AB.Enable = false;
                    app.RegSpecDD1AB.Visible = false;
                    app.DerivStepDD.Enable = true;
                    app.DerivStepDD.Visible = true;

                    app.RegSpecDD1.Items = {'None','Andrews','Bisquare','Cauchy', ...
                        'Fair','Huber','Logicstic','Talwar','Welsch'};
                    app.RegSpecDD1.Value = app.nlinfitSpecs.Weight_Function;
                    app.RegSpecDD2.Items = {'Default',app.nlinfitSpecs.Tuning_Constant};
                    app.RegSpecDD2.Value = app.nlinfitSpecs.Tuning_Constant;
                    app.WFDDValueChanged();

                    app.RegSpecVF3.Value = app.nlinfitSpecs.TolFun;
                    app.RegSpecVF4.Value = app.nlinfitSpecs.TolX;

                    app.RegSpecSpin5.Value = app.nlinfitSpecs.Maximum_Iterations;
                    app.RegSpecCB7.Value = app.nlinfitSpecs.checkNanInf;
                case "lsqcurvefit"


                case "fminsearch"
                    app.RegSpecL1.Text = "Regression Progress Plotting";
                    app.RegSpecL3.Text = "Termination Tolerance on Objective Function";
                    app.RegSpecL4.Text = "Termination Tolerance on Optimization Variables";
                    app.RegSpecL5.Text = "Maximum Iterations";
                    app.RegSpecL6.Text = "Maximum Function Evaluations";
                    app.RegSpecCB7.Text = "Check for Inf and Complex Function Values";

                    for k=[1,3,4,5,6]
                        app.("RegSpecL"+k).Enable = true;
                        app.("RegSpecL"+k).Visible = true;                        
                    end
                    app.RegSpecL2.Enable = false;
                    app.RegSpecL2.Visible = false;
                    app.DerivStepDD.Enable = false;
                    app.DerivStepDD.Visible = false;

                    app.RegSpecDD1.Items = {'Optimization Variables','Objective Function Count','Objective Function Value'};
                    app.RegSpecDD1.Value = app.RegSpecDD1.Items(1);

                    app.RegSpecVF3.Value = app.fminsearchSpecs.TolFun;
                    app.RegSpecVF4.Value = app.fminsearchSpecs.TolX;
                    app.RegSpecSpin5.Value = app.fminsearchSpecs.Maximum_Iterations;
                    app.RegSpecSpin6.Value = app.fminsearchSpecs.Maximum_Function_Evaluations;

                    app.RegSpecCB7.Value = app.fminsearchSpecs.checkComplexInf;
                case "fmincon"


            end
        end

        % Button pushed function: RegSpecDD1AB
        function RegSpecDD1ABButtonPushed(app, event)
            regProgPlot = string(app.RegSpecDD1.Value);
            plots = app.fminsearchSpecs.PlotFcns;

            app.fminsearchSpecs.PlotFcns = [plots,regProgPlot];
        end

        % Button pushed function: RegSpecDD1RB
        function RegSpecDD1RBButtonPushed(app, event)
            regProgPlot = string(app.RegSpecDD1.Value);
            plots = app.fminsearchSpecs.PlotFcns;

            for k=1:1:length(plots)
                if strcmp(plots(k),regProgPlot)
                    plots(k) = [];
                end
            end
            app.fminsearchSpecs.PlotFcns = plots;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ParameterSpecificationsPanel = uipanel(app.GridLayout);
            app.ParameterSpecificationsPanel.Title = 'Parameter Specifications';
            app.GridLayout2 = uigridlayout(app.ParameterSpecificationsPanel);
            app.RegressionParameterDropDownLabel = uilabel(app.GridLayout2);
            app.RegressionParameterDropDownLabel.Text = 'Regression Parameter';
            app.RegParamDD = uidropdown(app.GridLayout2);
            app.RegParamDD.Items = {};
            app.RegParamDD.Editable = 'on';
            app.RegParamDD.ValueChangedFcn = createCallbackFcn(app, @RegParamDDValueChanged, true);
            app.RegParamDD.Value = {};
            app.ParameterInitialGuessDropDownLabel = uilabel(app.GridLayout2);
            app.ParameterInitialGuessDropDownLabel.Text = 'Parameter Initial Guess';
            app.ParamIGDD1 = uidropdown(app.GridLayout2);
            app.ParamIGDD1.Items = {};
            app.ParamIGDD1.Editable = 'on';
            app.ParamIGDD1.ValueChangedFcn = createCallbackFcn(app, @AddNewValToDD, true);
            app.ParamIGDD1.Value = {};
            app.toDropDownLabel = uilabel(app.GridLayout2);
            app.toDropDownLabel.Enable = 'off';
            app.toDropDownLabel.Visible = 'off';
            app.toDropDownLabel.Text = 'to';
            app.ParamIGDD2 = uidropdown(app.GridLayout2);
            app.ParamIGDD2.Items = {};
            app.ParamIGDD2.Editable = 'on';
            app.ParamIGDD2.ValueChangedFcn = createCallbackFcn(app, @AddNewValToDD, true);
            app.ParamIGDD2.Enable = 'off';
            app.ParamIGDD2.Visible = 'off';
            app.ParamIGDD2.Value = {};
            app.withDropDownLabel = uilabel(app.GridLayout2);
            app.withDropDownLabel.Enable = 'off';
            app.withDropDownLabel.Visible = 'off';
            app.withDropDownLabel.Text = 'with';
            app.ParamIGDD3 = uidropdown(app.GridLayout2);
            app.ParamIGDD3.Items = {};
            app.ParamIGDD3.Editable = 'on';
            app.ParamIGDD3.ValueChangedFcn = createCallbackFcn(app, @AddNewValToDD, true);
            app.ParamIGDD3.Enable = 'off';
            app.ParamIGDD3.Visible = 'off';
            app.ParamIGDD3.Value = {};
            app.ParamIGLL3 = uilabel(app.GridLayout2);
            app.ParamIGLL3.Enable = 'off';
            app.ParamIGLL3.Visible = 'off';
            app.ParamIGLL3.Text = 'points in range';
            app.DerivStepL = uilabel(app.GridLayout2);
            app.DerivStepL.Text = '';
            app.RelativeDifferenceforFiniteDifferenceGradientDropDownLabel = uilabel(app.GridLayout2);
            app.RelativeDifferenceforFiniteDifferenceGradientDropDownLabel.Text = 'Relative Difference for Finite Difference Gradient';
            app.DerivStepDD = uidropdown(app.GridLayout2);
            app.DerivStepDD.Items = {'Default'};
            app.DerivStepDD.Editable = 'on';
            app.DerivStepDD.ValueChangedFcn = createCallbackFcn(app, @DerivStepDDValueChanged, true);
            app.DerivStepDD.Value = 'Default';
            app.RegParamSpecUB = uibutton(app.GridLayout2, 'push');
            app.RegParamSpecUB.ButtonPushedFcn = createCallbackFcn(app, @RegParamSpecUBButtonPushed, true);
            app.RegParamSpecUB.Text = 'Update';
            app.RegSpecsP = uipanel(app.GridLayout);
            app.RegSpecsP.Title = 'Regression Specifications';
            app.GridLayout3 = uigridlayout(app.RegSpecsP);
            app.RegSpecValL1 = uilabel(app.GridLayout3);
            app.RegSpecValL1.Text = 'N/A';
            app.RegSpecVF3 = uieditfield(app.GridLayout3, 'numeric');
            app.RegSpecVF3.Value = 1e-08;
            app.RegSpecVF4 = uieditfield(app.GridLayout3, 'numeric');
            app.RegSpecVF4.Value = 1e-08;
            app.RegSpecDD1 = uidropdown(app.GridLayout3);
            app.RegSpecDD1.Items = {'None', 'Andrews', 'Bisquare', 'Cauchy', 'Fair', 'Huber', 'Logicstic', 'Talwar', 'Welsch'};
            app.RegSpecDD1.Editable = 'on';
            app.RegSpecDD1.ValueChangedFcn = createCallbackFcn(app, @WFDDValueChanged, true);
            app.RegSpecDD1.Value = 'None';
            app.RegSpecValL2 = uilabel(app.GridLayout3);
            app.RegSpecValL2.Text = 'N/A';
            app.RegSpecDD2 = uidropdown(app.GridLayout3);
            app.RegSpecDD2.Items = {'Default'};
            app.RegSpecDD2.Editable = 'on';
            app.RegSpecDD2.ValueChangedFcn = createCallbackFcn(app, @WFDDValueChanged, true);
            app.RegSpecDD2.Value = 'Default';
            app.RegSpecSpin5 = uispinner(app.GridLayout3);
            app.RegSpecSpin5.Value = 100;
            app.RegSpecCB7 = uicheckbox(app.GridLayout3);
            app.RegSpecCB7.Text = 'Check for NaN and Inf Values';
            app.RegSpecUB = uibutton(app.GridLayout3, 'push');
            app.RegSpecUB.ButtonPushedFcn = createCallbackFcn(app, @RegSpecUBPushed, true);
            app.RegSpecUB.Text = 'Update Regression Specifications';
            app.RegressionSolverDropDownLabel = uilabel(app.GridLayout3);
            app.RegressionSolverDropDownLabel.Text = 'Regression Solver';
            app.RegSolverDD = uidropdown(app.GridLayout3);
            app.RegSolverDD.Items = {'nlinfit', 'lsqcurvefit', 'fminsearch', 'fmincon'};
            app.RegSolverDD.ValueChangedFcn = createCallbackFcn(app, @RegSolverDDValueChanged, true);
            app.RegSolverDD.Value = 'nlinfit';
            app.RegSpecL1 = uilabel(app.GridLayout3);
            app.RegSpecL1.Text = 'Weight Function';
            app.RegSpecL2 = uilabel(app.GridLayout3);
            app.RegSpecL2.Text = 'Tuning Constant';
            app.RegSpecL3 = uilabel(app.GridLayout3);
            app.RegSpecL3.Text = 'Termination Tolerance on Residual Sum of Squares';
            app.RegSpecL4 = uilabel(app.GridLayout3);
            app.RegSpecL4.Text = 'Termination Tolerance on Estimated Coefficients';
            app.RegSpecL5 = uilabel(app.GridLayout3);
            app.RegSpecL5.Text = 'Maximum Iterations';
            app.RegSpecL6 = uilabel(app.GridLayout3);
            app.RegSpecL6.Enable = 'off';
            app.RegSpecL6.Visible = 'off';
            app.RegSpecL6.Text = 'Maximum Number of Function Evaluations';
            app.RegSpecSpin6 = uispinner(app.GridLayout3);
            app.RegSpecSpin6.Enable = 'off';
            app.RegSpecSpin6.Visible = 'off';
            app.RegSpecSpin6.Value = 100;
            app.RegSpecDD1AB = uibutton(app.GridLayout3, 'push');
            app.RegSpecDD1AB.ButtonPushedFcn = createCallbackFcn(app, @RegSpecDD1ABButtonPushed, true);
            app.RegSpecDD1AB.Text = 'Add';
            app.RegSpecDD1RB = uibutton(app.GridLayout3, 'push');
            app.RegSpecDD1RB.ButtonPushedFcn = createCallbackFcn(app, @RegSpecDD1RBButtonPushed, true);
            app.RegSpecDD1RB.Text = 'Remove';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RegSpecsWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end